classdef XIPline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HPXeMRIAnalysisApp              matlab.ui.Figure
        AboutMenu                       matlab.ui.container.Menu
        StartFreshMenu                  matlab.ui.container.Menu
        ScreenShotMenu                  matlab.ui.container.Menu
        takescreenshotMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        UserManualMenu                  matlab.ui.container.Menu
        JournalReferenceMenu            matlab.ui.container.Menu
        SourceCodeGithubMenu            matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        Version110Label                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        FieldStrengthDropDown           matlab.ui.control.DropDown
        FieldStrengthDropDownLabel      matlab.ui.control.Label
        SequenceTypeDropDown            matlab.ui.control.DropDown
        SequenceTypeDropDown_3Label     matlab.ui.control.Label
        ScannerSoftwareDropDown         matlab.ui.control.DropDown
        ScannerSoftwareDropDownLabel    matlab.ui.control.Label
        ScannerDropDown                 matlab.ui.control.DropDown
        ScannerDropDownLabel            matlab.ui.control.Label
        InstituteDropDown               matlab.ui.control.DropDown
        InstituteDropDownLabel          matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CalibrationTab                  matlab.ui.container.Tab
        CalResultsPanel                 matlab.ui.container.Panel
        SaveResultButton                matlab.ui.control.Button
        UICalTable                      matlab.ui.control.Table
        InputsandAssumptionsPanel       matlab.ui.container.Panel
        CalAssumptionsTextArea          matlab.ui.control.TextArea
        DiffusionbvaluesEditField       matlab.ui.control.NumericEditField
        DiffusionbvaluesEditFieldLabel  matlab.ui.control.Label
        DiffusionExcitationsEditField   matlab.ui.control.NumericEditField
        DiffusionExcitationsEditFieldLabel  matlab.ui.control.Label
        VentilationExcitationsEditField  matlab.ui.control.NumericEditField
        VentilationExcitationsEditFieldLabel  matlab.ui.control.Label
        ResultPlotsPanel                matlab.ui.container.Panel
        Diss_Text                       matlab.ui.control.Label
        Gas_Text                        matlab.ui.control.Label
        FA_Text                         matlab.ui.control.Label
        Gas_Axes                        matlab.ui.control.UIAxes
        Spct_Axes                       matlab.ui.control.UIAxes
        Diss_Axes                       matlab.ui.control.UIAxes
        FA_Axes                         matlab.ui.control.UIAxes
        PerformCalibrationPanel         matlab.ui.container.Panel
        SelectDataButton                matlab.ui.control.Button
        PerformCalibrationButton        matlab.ui.control.Button
        ImageAnalysisTab                matlab.ui.container.Tab
        ViewingParametersPanel          matlab.ui.container.Panel
        FlipButton                      matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ApplyChangesButton              matlab.ui.control.Button
        ImagesbeingviewedEditField      matlab.ui.control.EditField
        ImagesbeingviewedEditFieldLabel  matlab.ui.control.Label
        imSliceViewerButton             matlab.ui.control.Button
        PixelInfoButton                 matlab.ui.control.Button
        ContrastSlider                  matlab.ui.control.Slider
        ContrastSlider_2Label_2         matlab.ui.control.Label
        ResetContrast                   matlab.ui.control.Button
        D2Spinner                       matlab.ui.control.Spinner
        D2SpinnerLabel                  matlab.ui.control.Label
        D1Spinner                       matlab.ui.control.Spinner
        D1SpinnerLabel                  matlab.ui.control.Label
        swapdimensionButton             matlab.ui.control.Button
        RotateFlipdDropDown             matlab.ui.control.DropDown
        RotateFlipdDropDownLabel        matlab.ui.control.Label
        ScaleZSpinner                   matlab.ui.control.Spinner
        ScaleZSpinnerLabel              matlab.ui.control.Label
        ScaleYSpinner                   matlab.ui.control.Spinner
        ScaleYSpinnerLabel              matlab.ui.control.Label
        ScaleXSpinner                   matlab.ui.control.Spinner
        ScaleXSpinnerLabel              matlab.ui.control.Label
        CrosshairEnableSwitch           matlab.ui.control.Switch
        CrosshairEnableSwitchLabel      matlab.ui.control.Label
        ChLineWidthSpinner              matlab.ui.control.Spinner
        ChLineWidthSpinnerLabel         matlab.ui.control.Label
        ColorMapDropDown                matlab.ui.control.DropDown
        ColorMapDropDown_2Label         matlab.ui.control.Label
        ChColorDropDown                 matlab.ui.control.DropDown
        ChColorDropDownLabel            matlab.ui.control.Label
        ViewingWindowPanel              matlab.ui.container.Panel
        sliceSlider                     matlab.ui.control.Slider
        increaseSlice                   matlab.ui.control.Button
        decreaseSlice                   matlab.ui.control.Button
        UIAxes_colorbar                 matlab.ui.control.UIAxes
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        PatientAgeyearsEditField        matlab.ui.control.EditField
        PatientAgeyearsEditFieldLabel   matlab.ui.control.Label
        ImageAnalysisTabs               matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        AnalysisExecutionModeDropDown   matlab.ui.control.DropDown
        AnalysisExecutionModeLabel      matlab.ui.control.Label
        ViewImagesPanel                 matlab.ui.container.Panel
        ViewButtonLoadData              matlab.ui.control.Button
        ImagestoviewDropDown            matlab.ui.control.DropDown
        ImagestoviewDropDownLabel       matlab.ui.control.Label
        ProtonImagesPanel               matlab.ui.container.Panel
        NoProtonImagesCheckBox          matlab.ui.control.CheckBox
        SelectAnatomicalDataButton      matlab.ui.control.Button
        XenonImagesPanel                matlab.ui.container.Panel
        NofbvaluesEditField             matlab.ui.control.NumericEditField
        NofbvaluesEditField_2Label      matlab.ui.control.Label
        DenoiseSDEditField              matlab.ui.control.EditField
        DenoiseSDEditFieldLabel         matlab.ui.control.Label
        DiffAcqOrderDropDown            matlab.ui.control.DropDown
        DiffAcqOrderDropDownLabel       matlab.ui.control.Label
        DenoiseXeImageCheckBox          matlab.ui.control.CheckBox
        SelectGasDataButton             matlab.ui.control.Button
        LoadReadDataButton              matlab.ui.control.Button
        RegistrationTab                 matlab.ui.container.Tab
        SliceSelectionCheckBox          matlab.ui.control.CheckBox
        SliceSeclectionPanel            matlab.ui.container.Panel
        HendEditField                   matlab.ui.control.NumericEditField
        HendLabel                       matlab.ui.control.Label
        HstartEditField                 matlab.ui.control.NumericEditField
        HstartLabel                     matlab.ui.control.Label
        XeendEditField                  matlab.ui.control.NumericEditField
        XeendLabel                      matlab.ui.control.Label
        XestartEditField                matlab.ui.control.NumericEditField
        XestartLabel                    matlab.ui.control.Label
        TransformTypeButtonGroup        matlab.ui.container.ButtonGroup
        affineButton                    matlab.ui.control.ToggleButton
        similarityButton                matlab.ui.control.ToggleButton
        rigidButton                     matlab.ui.control.ToggleButton
        translationButton               matlab.ui.control.ToggleButton
        ViewImagesPanel_2               matlab.ui.container.Panel
        ViewButtonRegisteration         matlab.ui.control.Button
        ImagestoviewRegistrationDropDown  matlab.ui.control.DropDown
        ImagestoviewDropDown_2Label     matlab.ui.control.Label
        PerformRegistrationButton       matlab.ui.control.Button
        ImageResolutionmmPanel          matlab.ui.container.Panel
        UseRatioCheckBox                matlab.ui.control.CheckBox
        HSliceThicnessEditField         matlab.ui.control.NumericEditField
        HSliceThicnessEditFieldLabel    matlab.ui.control.Label
        HPixel2EditField                matlab.ui.control.NumericEditField
        HPixel2EditFieldLabel           matlab.ui.control.Label
        HPixel1EditField                matlab.ui.control.NumericEditField
        HPixel1EditFieldLabel           matlab.ui.control.Label
        XeSliceThicnessEditField        matlab.ui.control.NumericEditField
        XeSliceThicnessEditFieldLabel   matlab.ui.control.Label
        XePixel2EditField               matlab.ui.control.NumericEditField
        XePixel2EditFieldLabel          matlab.ui.control.Label
        XePixel1EditField               matlab.ui.control.NumericEditField
        XePixel1EditFieldLabel          matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        ManualImageSegmentationPanel    matlab.ui.container.Panel
        MethodButtonGroup_maunal        matlab.ui.container.ButtonGroup
        AppSegmenterButton              matlab.ui.control.ToggleButton
        FreehandButton                  matlab.ui.control.ToggleButton
        SelectAnatomyButtonGroup_maunal  matlab.ui.container.ButtonGroup
        AirwayButton_manual             matlab.ui.control.ToggleButton
        ParenchymaButton_maunal         matlab.ui.control.ToggleButton
        EditMaskButton                  matlab.ui.control.Button
        ImagestosegmentDropDown         matlab.ui.control.DropDown
        ImagestosegmentDropDownLabel    matlab.ui.control.Label
        LoadSegmentedMaskButton         matlab.ui.control.Button
        LoadPerformSegmentationButton   matlab.ui.control.Button
        ThresholdImageSegmentationPanel  matlab.ui.container.Panel
        SelectAnatomyButtonGroup_threshold  matlab.ui.container.ButtonGroup
        AirwayButton                    matlab.ui.control.ToggleButton
        ParenchymaButton                matlab.ui.control.ToggleButton
        ErosionSpinner                  matlab.ui.control.Spinner
        ErosionSpinnerLabel             matlab.ui.control.Label
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        SegmentationMethodDropDown      matlab.ui.control.DropDown
        SegmentationMethodDropDownLabel  matlab.ui.control.Label
        AutoImageSegmentationAIPanel    matlab.ui.container.Panel
        SliceOrientationButtonGroup     matlab.ui.container.ButtonGroup
        sagittalButton                  matlab.ui.control.ToggleButton
        isotropicButton                 matlab.ui.control.ToggleButton
        transversalButton               matlab.ui.control.ToggleButton
        coronalButton                   matlab.ui.control.ToggleButton
        ViewMaskPanel                   matlab.ui.container.Panel
        ViewButtonSegment               matlab.ui.control.Button
        MasktoviewDropDown              matlab.ui.control.DropDown
        MasktoviewDropDownLabel         matlab.ui.control.Label
        PostProcessTab                  matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        VentilationTab                  matlab.ui.container.Tab
        DefectDistributionIndexDDIPanel  matlab.ui.container.Panel
        DefectMapDropDown               matlab.ui.control.DropDown
        DefectMapDropDownLabel          matlab.ui.control.Label
        DDI_Z                           matlab.ui.control.NumericEditField
        XLabel_3                        matlab.ui.control.Label
        DDI_Y                           matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        DDI_X                           matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        VoxelSizeLabel                  matlab.ui.control.Label
        DDI3DCheckBox                   matlab.ui.control.CheckBox
        DDI2DCheckBox                   matlab.ui.control.CheckBox
        TextureAnalysisPanel            matlab.ui.container.Panel
        TextureAnalysisCheckBox         matlab.ui.control.CheckBox
        BiasFieldCorrectionCheckBox     matlab.ui.control.CheckBox
        VentilationDefectPercentageVDPPanel  matlab.ui.container.Panel
        kmeansCheckBox                  matlab.ui.control.CheckBox
        LinearBinningMethodCheckBox_vent  matlab.ui.control.CheckBox
        ThresholdVDPCheckBox            matlab.ui.control.CheckBox
        ThresholdMethodPanel            matlab.ui.container.Panel
        IncompleteEditField             matlab.ui.control.NumericEditField
        IncompelteLabel                 matlab.ui.control.Label
        HyperEditField                  matlab.ui.control.NumericEditField
        HyperLabel                      matlab.ui.control.Label
        DefectEditField                 matlab.ui.control.NumericEditField
        DefectLabel                     matlab.ui.control.Label
        LinearBinningMethodPanel        matlab.ui.container.Panel
        MeanN4EditField                 matlab.ui.control.NumericEditField
        MeanN4EditFieldLabel            matlab.ui.control.Label
        SDN4EditField                   matlab.ui.control.NumericEditField
        SDN4Label                       matlab.ui.control.Label
        SDEditField                     matlab.ui.control.NumericEditField
        SDLabel                         matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        MeanLabel                       matlab.ui.control.Label
        DiffusionTab                    matlab.ui.container.Tab
        MorphometryAnalysisCheckBox     matlab.ui.control.CheckBox
        bvaluesPanel                    matlab.ui.container.Panel
        bvaluesscm2EditField            matlab.ui.control.EditField
        bvaluesscm2EditFieldLabel       matlab.ui.control.Label
        MorphometryAnalysisPanel        matlab.ui.container.Panel
        StretchExponentialModelSEMCheckBox  matlab.ui.control.CheckBox
        StudyTypeDropDown               matlab.ui.control.DropDown
        StudyTypeDropDownLabel          matlab.ui.control.Label
        DeltamsEditField                matlab.ui.control.NumericEditField
        DeltamsEditFieldLabel           matlab.ui.control.Label
        Docm2sEditField                 matlab.ui.control.NumericEditField
        Docm2sEditFieldLabel            matlab.ui.control.Label
        CylindricalModelCMCheckBox      matlab.ui.control.CheckBox
        ADCAnalysisPanel                matlab.ui.container.Panel
        LinearBinningMethoddiffCheckBox_diff  matlab.ui.control.CheckBox
        ADCLinearBinningMethodPanel     matlab.ui.container.Panel
        SDequationEditField             matlab.ui.control.EditField
        SDequationEditFieldLabel        matlab.ui.control.Label
        MeanequationEditField           matlab.ui.control.EditField
        MeanequationEditFieldLabel      matlab.ui.control.Label
        FittingTypeDropDown             matlab.ui.control.DropDown
        FittingTypeDropDownLabel        matlab.ui.control.Label
        GasExchangeTab                  matlab.ui.container.Tab
        HealthyReferenceDropDown        matlab.ui.control.DropDown
        HealthyReferenceDropDownLabel   matlab.ui.control.Label
        ImportHealthyDistributionsButton  matlab.ui.control.Button
        ManualHealthyReferenceDistributionsPanel  matlab.ui.container.Panel
        RBCOscSDEditField               matlab.ui.control.NumericEditField
        RBCOscSDEditFieldLabel          matlab.ui.control.Label
        RBCOscMeanEditField             matlab.ui.control.NumericEditField
        RBCOscMeanEditFieldLabel        matlab.ui.control.Label
        RBCBarrSDEditField              matlab.ui.control.NumericEditField
        RBCBarrSDEditFieldLabel         matlab.ui.control.Label
        RBCBarrMeanEditField            matlab.ui.control.NumericEditField
        RBCBarrMeanEditFieldLabel       matlab.ui.control.Label
        RBCSDEditField                  matlab.ui.control.NumericEditField
        RBCSDEditFieldLabel             matlab.ui.control.Label
        RBCMeanEditField                matlab.ui.control.NumericEditField
        RBCMeanEditFieldLabel           matlab.ui.control.Label
        MembraneSDEditField             matlab.ui.control.NumericEditField
        MembraneSDEditFieldLabel        matlab.ui.control.Label
        MembraneMeanEditField           matlab.ui.control.NumericEditField
        MembraneMeanLabel               matlab.ui.control.Label
        DissolvedSDEditField            matlab.ui.control.NumericEditField
        DissolvedSDEditFieldLabel       matlab.ui.control.Label
        DissolvedMeanEditField          matlab.ui.control.NumericEditField
        DissolvedMeanEditFieldLabel     matlab.ui.control.Label
        VentSDEditField                 matlab.ui.control.NumericEditField
        VentSDEditFieldLabel            matlab.ui.control.Label
        VentMeanEditField               matlab.ui.control.NumericEditField
        VentMeanLabel                   matlab.ui.control.Label
        PerformAnalysisButton           matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        TabGroup4_2                     matlab.ui.container.TabGroup
        VentilationTabResults           matlab.ui.container.Tab
        DDIResultsPanel                 matlab.ui.container.Panel
        meanSD_3DDDITextArea            matlab.ui.control.TextArea
        VDPTextArea_2Label_3            matlab.ui.control.Label
        DDI3DMapButton                  matlab.ui.control.Button
        meanSD_2DDDITextArea            matlab.ui.control.TextArea
        VDPTextArea_2Label_2            matlab.ui.control.Label
        DDI2DMapButton                  matlab.ui.control.Button
        KmeansResultsPanel              matlab.ui.container.Panel
        kmeansVDPTextArea               matlab.ui.control.TextArea
        VDPTextArea_2Label              matlab.ui.control.Label
        kmeansVDPMapButton              matlab.ui.control.Button
        LinearBinningMethodResultsPanel  matlab.ui.container.Panel
        HighLabel                       matlab.ui.control.Label
        DefectsLabel                    matlab.ui.control.Label
        Bin6TextArea                    matlab.ui.control.TextArea
        Bin6TextAreaLabel               matlab.ui.control.Label
        Bin5TextArea                    matlab.ui.control.TextArea
        Bin5TextAreaLabel               matlab.ui.control.Label
        Bin4TextArea                    matlab.ui.control.TextArea
        Bin4TextAreaLabel               matlab.ui.control.Label
        Bin3TextArea                    matlab.ui.control.TextArea
        Bin3TextAreaLabel               matlab.ui.control.Label
        Bin2TextArea                    matlab.ui.control.TextArea
        Bin2TextAreaLabel               matlab.ui.control.Label
        Bin1TextArea                    matlab.ui.control.TextArea
        Bin1TextAreaLabel               matlab.ui.control.Label
        LBVDPHistogramButton            matlab.ui.control.Button
        LBVDPMapButton                  matlab.ui.control.Button
        MeanTextArea                    matlab.ui.control.TextArea
        MeanTextAreaLabel               matlab.ui.control.Label
        LBVDPTextArea                   matlab.ui.control.TextArea
        LBVDPTextAreaLabel              matlab.ui.control.Label
        ThresholdMethodResultsPanel     matlab.ui.container.Panel
        VDPHistogramButton              matlab.ui.control.Button
        VDPMapButton                    matlab.ui.control.Button
        HyperTextArea                   matlab.ui.control.TextArea
        HyperTextAreaLabel              matlab.ui.control.Label
        CompleteTextArea                matlab.ui.control.TextArea
        CompleteTextAreaLabel           matlab.ui.control.Label
        IncompleteTextArea              matlab.ui.control.TextArea
        IncompleteLabel                 matlab.ui.control.Label
        NormalTextArea                  matlab.ui.control.TextArea
        NormalLabel                     matlab.ui.control.Label
        VDPTextArea                     matlab.ui.control.TextArea
        VDPLabel                        matlab.ui.control.Label
        TextureAnalysisGLRLMResultsPanel  matlab.ui.container.Panel
        LGRETextArea                    matlab.ui.control.TextArea
        LGRETextAreaLabel               matlab.ui.control.Label
        LRHGETextArea                   matlab.ui.control.TextArea
        LRHGETextAreaLabel              matlab.ui.control.Label
        LRLGETextArea                   matlab.ui.control.TextArea
        LRLGETextAreaLabel              matlab.ui.control.Label
        SRHGETextArea                   matlab.ui.control.TextArea
        SRHGETextAreaLabel              matlab.ui.control.Label
        SRLGRTextArea                   matlab.ui.control.TextArea
        SRLGRTextAreaLabel              matlab.ui.control.Label
        HGRETextArea                    matlab.ui.control.TextArea
        HGRETextAreaLabel               matlab.ui.control.Label
        MeanGLRLMMapButton              matlab.ui.control.Button
        RPTextArea                      matlab.ui.control.TextArea
        RPLabel                         matlab.ui.control.Label
        RLNTextArea                     matlab.ui.control.TextArea
        RLNLabel                        matlab.ui.control.Label
        GLNTextArea                     matlab.ui.control.TextArea
        GLNLabel                        matlab.ui.control.Label
        LRETextArea                     matlab.ui.control.TextArea
        LRELabel                        matlab.ui.control.Label
        SRETextArea                     matlab.ui.control.TextArea
        SRELabel                        matlab.ui.control.Label
        DiffusionTabResults             matlab.ui.container.Tab
        MorphometryAnalysisResultPanel  matlab.ui.container.Panel
        StretchExponentialModelSEMResultsPanel  matlab.ui.container.Panel
        SEMParametersDropDown           matlab.ui.control.DropDown
        SEMParametersDropDownLabel      matlab.ui.control.Label
        MeanTextAreaSEM                 matlab.ui.control.TextArea
        MeanTextArea_3Label             matlab.ui.control.Label
        HistogramButtonSEM              matlab.ui.control.Button
        MapButtonSEM                    matlab.ui.control.Button
        CylindricalModelCMResultsPanel  matlab.ui.container.Panel
        MapButtonCM                     matlab.ui.control.Button
        HistogramButtonCM               matlab.ui.control.Button
        CMParametersDropDown            matlab.ui.control.DropDown
        CMParametersDropDownLabel       matlab.ui.control.Label
        MeanTextAreaCM                  matlab.ui.control.TextArea
        MeanTextArea_2Label             matlab.ui.control.Label
        ADCResultsPanel                 matlab.ui.container.Panel
        MeanADCcm2sTextArea             matlab.ui.control.TextArea
        MeanADCcm2sTextAreaLabel        matlab.ui.control.Label
        ADCHistogramButton              matlab.ui.control.Button
        ADCMapButton                    matlab.ui.control.Button
        ADCLinearBinningResultsPanel    matlab.ui.container.Panel
        Bin6TextAreaLBADC               matlab.ui.control.TextArea
        Bin6TextArea_2Label             matlab.ui.control.Label
        Bin5TextAreaLBADC               matlab.ui.control.TextArea
        Bin5TextArea_2Label             matlab.ui.control.Label
        Bin4TextAreaLBADC               matlab.ui.control.TextArea
        Bin4TextArea_2Label             matlab.ui.control.Label
        Bin3TextAreaLBADC               matlab.ui.control.TextArea
        Bin3TextArea_2Label             matlab.ui.control.Label
        Bin2TextAreaLBADC               matlab.ui.control.TextArea
        Bin2TextArea_2Label             matlab.ui.control.Label
        Bin1TextAreaLBADC               matlab.ui.control.TextArea
        Bin1TextArea_2Label             matlab.ui.control.Label
        HighLabelLBADC                  matlab.ui.control.Label
        LowLabel                        matlab.ui.control.Label
        LBADCHistogramButton            matlab.ui.control.Button
        LBADCMapButton                  matlab.ui.control.Button
        GasExchangeTabResults           matlab.ui.container.Tab
        RBCOscillationSummaryPanel      matlab.ui.container.Panel
        Bin8TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin8TextArea_3Label             matlab.ui.control.Label
        Bin7TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin7TextArea_3Label             matlab.ui.control.Label
        Bin6TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin6TextArea_11Label            matlab.ui.control.Label
        Bin5TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin5TextArea_11Label            matlab.ui.control.Label
        Bin4TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin4TextArea_11Label            matlab.ui.control.Label
        Bin3TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin3TextArea_11Label            matlab.ui.control.Label
        Bin2TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin2TextArea_12Label            matlab.ui.control.Label
        HistogramButtonRBCOsc           matlab.ui.control.Button
        MapButtonRBCOsc                 matlab.ui.control.Button
        MeanTextAreaRBCOsc              matlab.ui.control.TextArea
        MeanTextArea_12Label            matlab.ui.control.Label
        Bin1TextAreaRBCOsc              matlab.ui.control.TextArea
        Bin1TextArea_11Label            matlab.ui.control.Label
        HighLabel_11                    matlab.ui.control.Label
        LowLabel_10                     matlab.ui.control.Label
        MembraneSummaryPanel            matlab.ui.container.Panel
        Bin8TextAreaBarr                matlab.ui.control.TextArea
        Bin8TextArea_2Label             matlab.ui.control.Label
        Bin7TextAreaBarr                matlab.ui.control.TextArea
        Bin7TextArea_2Label             matlab.ui.control.Label
        Bin6TextAreaBarr                matlab.ui.control.TextArea
        Bin6TextArea_10Label            matlab.ui.control.Label
        Bin5TextAreaBarr                matlab.ui.control.TextArea
        Bin5TextArea_10Label            matlab.ui.control.Label
        Bin4TextAreaBarr                matlab.ui.control.TextArea
        Bin4TextArea_10Label            matlab.ui.control.Label
        Bin3TextAreaBarr                matlab.ui.control.TextArea
        Bin3TextArea_10Label            matlab.ui.control.Label
        Bin2TextAreaBarr                matlab.ui.control.TextArea
        Bin2TextArea_11Label            matlab.ui.control.Label
        HistogramButtonBarr             matlab.ui.control.Button
        MapButtonBarr                   matlab.ui.control.Button
        MeanTextAreaBarr                matlab.ui.control.TextArea
        MeanTextArea_11Label            matlab.ui.control.Label
        Bin1TextAreaBarr                matlab.ui.control.TextArea
        Bin1TextArea_10Label            matlab.ui.control.Label
        HighLabel_10                    matlab.ui.control.Label
        LowLabel_9                      matlab.ui.control.Label
        RBCMembraneSummaryPanel         matlab.ui.container.Panel
        HistogramButtonRBCBarr          matlab.ui.control.Button
        MapButtonRBCBarr                matlab.ui.control.Button
        MeanTextAreaRBCBarr             matlab.ui.control.TextArea
        MeanTextArea_8Label             matlab.ui.control.Label
        Bin6TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin6TextArea_7Label             matlab.ui.control.Label
        Bin5TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin5TextArea_7Label             matlab.ui.control.Label
        Bin4TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin4TextArea_7Label             matlab.ui.control.Label
        Bin3TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin3TextArea_7Label             matlab.ui.control.Label
        Bin2TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin2TextArea_7Label             matlab.ui.control.Label
        Bin1TextAreaRBCBarr             matlab.ui.control.TextArea
        Bin1TextArea_7Label             matlab.ui.control.Label
        HighLabel_7                     matlab.ui.control.Label
        LowLabel_6                      matlab.ui.control.Label
        RBCSummaryPanel                 matlab.ui.container.Panel
        HistogramButtonRBC              matlab.ui.control.Button
        MapButtonRBC                    matlab.ui.control.Button
        MeanTextAreaRBC                 matlab.ui.control.TextArea
        MeanTextArea_7Label             matlab.ui.control.Label
        Bin6TextAreaRBC                 matlab.ui.control.TextArea
        Bin6TextArea_6Label             matlab.ui.control.Label
        Bin5TextAreaRBC                 matlab.ui.control.TextArea
        Bin5TextArea_6Label             matlab.ui.control.Label
        Bin4TextAreaRBC                 matlab.ui.control.TextArea
        Bin4TextArea_6Label             matlab.ui.control.Label
        Bin3TextAreaRBC                 matlab.ui.control.TextArea
        Bin3TextArea_6Label             matlab.ui.control.Label
        Bin2TextAreaRBC                 matlab.ui.control.TextArea
        Bin2TextArea_6Label             matlab.ui.control.Label
        Bin1TextAreaRBC                 matlab.ui.control.TextArea
        Bin1TextArea_6Label             matlab.ui.control.Label
        HighLabel_6                     matlab.ui.control.Label
        LowLabel_5                      matlab.ui.control.Label
        DissolvedSummaryPanel           matlab.ui.container.Panel
        HistogramButtonDissolved        matlab.ui.control.Button
        MapButtonDissolved              matlab.ui.control.Button
        MeanTextAreaDissolved           matlab.ui.control.TextArea
        MeanTextArea_5Label             matlab.ui.control.Label
        Bin6TextAreaDissolved           matlab.ui.control.TextArea
        Bin6TextArea_4Label             matlab.ui.control.Label
        Bin5TextAreaDissolved           matlab.ui.control.TextArea
        Bin5TextArea_4Label             matlab.ui.control.Label
        Bin4TextAreaDissolved           matlab.ui.control.TextArea
        Bin4TextArea_4Label             matlab.ui.control.Label
        Bin3TextAreaDissolved           matlab.ui.control.TextArea
        Bin3TextArea_4Label             matlab.ui.control.Label
        Bin2TextAreaDissolved           matlab.ui.control.TextArea
        Bin2TextArea_4Label             matlab.ui.control.Label
        Bin1TextAreaDissolved           matlab.ui.control.TextArea
        Bin1TextArea_4Label             matlab.ui.control.Label
        HighLabel_4                     matlab.ui.control.Label
        LowLabel_3                      matlab.ui.control.Label
        VentilationSummaryPanel         matlab.ui.container.Panel
        HistogramButtonVent             matlab.ui.control.Button
        MapButtonVent                   matlab.ui.control.Button
        MeanTextAreaVent                matlab.ui.control.TextArea
        MeanTextArea_4Label             matlab.ui.control.Label
        Bin6TextAreaVent                matlab.ui.control.TextArea
        Bin6TextArea_3Label             matlab.ui.control.Label
        Bin5TextAreaVent                matlab.ui.control.TextArea
        Bin5TextArea_3Label             matlab.ui.control.Label
        Bin4TextAreaVent                matlab.ui.control.TextArea
        Bin4TextArea_3Label             matlab.ui.control.Label
        Bin3TextAreaVent                matlab.ui.control.TextArea
        Bin3TextArea_3Label             matlab.ui.control.Label
        Bin2TextAreaVent                matlab.ui.control.TextArea
        Bin2TextArea_3Label             matlab.ui.control.Label
        Bin1TextAreaVent                matlab.ui.control.TextArea
        Bin1TextArea_3Label             matlab.ui.control.Label
        HighLabel_3                     matlab.ui.control.Label
        LowLabel_2                      matlab.ui.control.Label
        ReportTab                       matlab.ui.container.Tab
        ProcessingDateDatePicker        matlab.ui.control.DatePicker
        ProcessingDateDatePickerLabel   matlab.ui.control.Label
        GenerateReportButton            matlab.ui.control.Button
        DataAnalystEditField            matlab.ui.control.EditField
        DataAnalystEditFieldLabel       matlab.ui.control.Label
        MRMEditField                    matlab.ui.control.EditField
        MRMEditFieldLabel               matlab.ui.control.Label
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        SummaryofFindingsEditField      matlab.ui.control.EditField
        SummaryofFindingsEditFieldLabel  matlab.ui.control.Label
        XeDoseVolumemLEditField         matlab.ui.control.EditField
        XeDoseVolumemLEditFieldLabel    matlab.ui.control.Label
        ScanDateDatePicker              matlab.ui.control.DatePicker
        ScanDateDatePickerLabel         matlab.ui.control.Label
        StudyIDEditField                matlab.ui.control.EditField
        StudyIDEditFieldLabel           matlab.ui.control.Label
        StudyTypeEditField              matlab.ui.control.EditField
        StudyTypeEditFieldLabel         matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        WeightlbsEditField              matlab.ui.control.EditField
        WeightlbsEditFieldLabel         matlab.ui.control.Label
        HeightcmEditField               matlab.ui.control.EditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        AgeyearEditField                matlab.ui.control.EditField
        AgeyearEditFieldLabel           matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PatientNameEditField            matlab.ui.control.EditField
        PatientNameEditFieldLabel       matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % view panal
        DataLocationCalibration; 
        viewing_img; viewing_img2; colorbar_fig; orthoViewer; panelchild; viewing_img_max; ImageType; 

        % FA
        GasExResults; CalResults; PerformCalibrationStatus; FA_Scale_Factor; Reference_Voltage; Cal_result_table;
        
        % Main classes
        Ventilation;    % ventilation class that has all ventilation related data/variables/results
        Diffusion;      % diffusion class that has all diffusion related data/variables/results
        GasExchange;    % gas exchange class that has all gas exchange related data/variables/results
        Proton;         % proton class that has all ventilation proton data/variables/results
        MainInput;      % main input class that has all app related inputs
        XeDataLocation;
        HDataLocation;
        VentilationAnalysisPerformed;
        DiffusionAnalysisPerformed;
        GasExchangeAnalysisPerformed;

        image;
    end
       
    methods (Access = public)
        % Update_orthoViewer function
        function Update_orthoViewer(app,viewing_img)
                % Remove the old orthoViewer if it exists
                if isfield(app, 'orthoViewer') && isvalid(app.orthoViewer)
                    delete(app.orthoViewer);
                end
                set(app.panelchild,'visible','off');
                app.panelchild = uipanel(app.ViewingWindowPanel,'Position',[1 59 494 469],'FontSize',12,'BorderType','none');
                if sum(viewing_img(:)) <= 0
                    viewing_img(end,end,end) = 1;
                end
                    app.orthoViewer = orthosliceViewer(viewing_img,...      
                             'CrosshairColor', app.ChColorDropDown.Value,...
                         'CrosshairLineWidth', app.ChLineWidthSpinner.Value,...
                                     'Parent', app.panelchild,...
                       'CrosshairStripeColor', 'none',...
                            'CrosshairEnable', app.CrosshairEnableSwitch.Value,...
                                   'Colormap', eval(app.ColorMapDropDown.Value),...
                               'ScaleFactors', [app.ScaleXSpinner.Value app.ScaleYSpinner.Value app.ScaleZSpinner.Value],...
                    'DisplayRangeInteraction', 'off',...
                               'DisplayRange', [0  max(viewing_img(:))]);     %[0  app.ContrastSlider.Value]    
        end
        
        % function to print out App inputs - start
        function PrintOutAppInputs(app, MainInput)
            disp('============== Print Out App Inputs =============');
            disp('HP 129Xe MRI Analysis App - version 1.0');
            disp(['Institute: ', app.InstituteDropDown.Value]);
            disp(['Scanner: ', app.ScannerDropDown.Value]);
            disp(['ScannerSoftware: ', app.ScannerSoftwareDropDown.Value]);
            disp(['SequenceType: ', app.SequenceTypeDropDown.Value]);
            disp(['AnalysisType: ', app.AnalysisTypeDropDown.Value]);
            disp(['SubjectAge: ', num2str(app.PatientAgeyearsEditField.Value)]);
            disp(['XeDataLocation: ', MainInput.XeDataLocation]); % need to check

            switch app.AnalysisTypeDropDown.Value                
                case 'Ventilation'  %'==== 129Xe Ventilation Analysis Inputs ===='                 
                    disp('==== 129Xe Ventilation Analysis Inputs ====');
                    disp(['RegistrationType: ', app.TransformTypeButtonGroup.SelectedObject.Text]);
                    if app.BiasFieldCorrectionCheckBox.Value == 0
                        disp('Bias Field Correction: no');
                    else
                        disp('Bias Field Correction: yes');
                    end
                    if app.ThresholdVDPCheckBox.Value == 1
                        disp(['Incomplete Thresh (%): ', num2str(app.IncompleteEditField.Value)]);
                        disp(['Defect Thresh (%): ', num2str(app.DefectEditField.Value)]);
                        disp(['Hyper Thresh (%): ', num2str(app.HyperEditField.Value)]);
                    end
                    if app.LinearBinningMethodCheckBox_vent.Value == 1
                        disp(['Mean binning: ', num2str(app.MeanEditField.Value)]);
                        disp(['SD binning : ', num2str(app.SDEditField.Value)]);
                        if app.BiasFieldCorrectionCheckBox.Value == 1
                            disp(['N4 Mean binning  : ', num2str(app.MeanN4EditField.Value)]);
                            disp(['N4 SD binning : ', num2str(app.SDN4EditField.Value)]);
                        end
                    end
                    if app.TextureAnalysisCheckBox.Value == 0
                        disp('Texture Analysis: no');
                    else
                        disp('Texture Analysis: yes');
                    end

                
                case 'Diffusion' %'==== 129Xe Diffusion Analysis Inputs ===='
                    disp('==== 129Xe Diffusion Analysis Inputs ====');
                    disp(['bvalues (s/cm2): ', app.bvaluesscm2EditField.Value]);
                    disp(['ADC fitting type: ', app.FittingTypeDropDown.Value]);
                    if app.LinearBinningMethoddiffCheckBox_diff.Value == 1
                        disp('Linear Binning: yes');
                        disp(['Linear Binning-Mean equation: ', app.MeanequationEditField.Value]);
                        disp(['Linear Binning-SD equation: ', app.SDequationEditField.Value]);
                    else
                        disp('Linear Binning: no');
                    end
                    if app.MorphometryAnalysisCheckBox.Value == 1
                        disp('Morphometry Analysis: yes');
                        disp(['StudyTypeDropDown: ', app.StudyTypeDropDown.Value]);
                        if app.CylindricalModelCMCheckBox.Value == 1
                            disp('Cylindrical Model: yes');
                        else
                            disp('Cylindrical Model: no');
                        end
                        if app.StretchExponentialModelSEMCheckBox.Value == 1
                            disp('Stretch Exponential Model: yes');
                        else
                            disp('Stretch Exponential Model: no');
                        end    
                        disp(['Do (cm2/s): ', num2str(app.Docm2sEditField.Value)]);
                        disp(['Delta (ms): ', num2str(app.DeltamsEditField.Value)]);
                    else
                        disp('Morphometry Analysis: no');
                    end                    
                
                case 'GasExchange' %'==== 129Xe Gas Exchange Analysis Inputs ===='
                    disp('==== 129Xe Gas Exchange Analysis Inputs ====');    
                    disp(['Vent Mean : ', num2str(app.VentMeanEditField.Value)]);
                    disp(['Vent SD : ', num2str(app.VentSDEditField.Value)]);
                    disp(['Dissolved Mean  : ', num2str(app.DissolvedMeanEditField.Value)]);
                    disp(['Dissolved SD  : ', num2str(app.DissolvedSDEditField.Value)]);
                    disp(['Barrier Mean  : ', num2str(app.MembraneMeanEditField.Value)]);
                    disp(['Barrier SD  : ', num2str(app.MembraneSDEditField.Value)]);
                    disp(['RBC Mean  : ', num2str(app.RBCMeanEditField.Value)]);
                    disp(['RBC SD  : ', num2str(app.RBCSDEditField.Value)]);
                    disp(['RBC BarrMean  : ', num2str(app.RBCBarrMeanEditField.Value)]);
                    disp(['RBC BarrSD  : ', num2str(app.RBCBarrSDEditField.Value)]);
                    disp(['RBCOsc Mean  : ', num2str(app.RBCOscMeanEditField.Value)]);
                    disp(['RBCOsc SD  : ', num2str(app.RBCOscSDEditField.Value)]);
            end 
        end
         % function to print out App inputs - end

         function MainInput = app_inputs(app, MainInput)
                 MainInput.Institute = app.InstituteDropDown.Value; 
                 MainInput.Scanner = app.ScannerDropDown.Value;
                 MainInput.ScannerSoftware = app.ScannerSoftwareDropDown.Value;
                 MainInput.SequenceType = app.SequenceTypeDropDown.Value; 
                 MainInput.VentilationExcitation = app.VentilationExcitationsEditField.Value;
                 MainInput.DiffusionExcitation = app.DiffusionExcitationsEditField.Value;
                 MainInput.Diffusionbvalues = app.DiffusionbvaluesEditField.Value;
                 %MainInput.NonCartesianFAsignalsetting = app.NonCartesianFAsignalsettingButtonGroup.SelectedObject.Text;
                 MainInput.AnalysisType = app.AnalysisTypeDropDown.Value;
                 MainInput.PatientAge = app.PatientAgeyearsEditField.Value;
                 MainInput.NoProtonImages = app.NoProtonImagesCheckBox.Value;
                 MainInput.TransformType = app.TransformTypeButtonGroup.SelectedObject.Text;
                 MainInput.UseRatio = app.UseRatioCheckBox.Value;
                 MainInput.XePixel1 = app.XePixel1EditField.Value;
                 MainInput.XePixel2 = app.XePixel2EditField.Value;
                 MainInput.XeSliceThicness = app.XeSliceThicnessEditField.Value;
                 MainInput.HPixel1= app.HPixel1EditField.Value;
                 MainInput.HPixel2= app.HPixel2EditField.Value;
                 MainInput.HSliceThicness = app.HSliceThicnessEditField.Value;
                 MainInput.SliceSelection = app.SliceSelectionCheckBox.Value;
                 MainInput.Xestart = app.XestartEditField.Value;
                 MainInput.Xeend= app.XeendEditField.Value;
                 MainInput.Hstart= app.HstartEditField.Value;
                 MainInput.Hend= app.HendEditField.Value;
                 MainInput.SegmentationMethod= app.SegmentationMethodDropDown.Value;
                 MainInput.Imagestosegment = app.ImagestosegmentDropDown.Value;
                 MainInput.SelectAnatomy = app.SelectAnatomyButtonGroup_threshold.SelectedObject.Text;
                 MainInput.Threshold = app.ThresholdSpinner.Value;
                 MainInput.Erosion = app.ErosionSpinner.Value;
                 MainInput.SliceOrientation= app.SliceOrientationButtonGroup.SelectedObject.Text;
                 MainInput.BiasFieldCorrection = app.BiasFieldCorrectionCheckBox.Value;
                 MainInput.ThresholdMethod = app.ThresholdVDPCheckBox.Value;
                 MainInput.Incompelte = app.IncompleteEditField.Value;
                 MainInput.Defect = app.DefectEditField.Value;
                 MainInput.Hyper = app.HyperEditField.Value;
                 MainInput.LinearBinningMethod_vent = app.LinearBinningMethodCheckBox_vent.Value;
                 MainInput.Mean= app.MeanEditField.Value;
                 MainInput.SD = app.SDEditField.Value;
                 MainInput.MeanN4= app.MeanN4EditField.Value;
                 MainInput.TextureAnalysisCheckBox = app.TextureAnalysisCheckBox.Value;
                 MainInput.bvaluesscm2= app.bvaluesscm2EditField.Value;
                 MainInput.FittingType = app.FittingTypeDropDown.Value;
                 MainInput.LinearBinningMethoddiff= app.LinearBinningMethoddiffCheckBox_diff.Value;
                 MainInput.Meanequation = app.MeanequationEditField.Value;
                 MainInput.SDequation = app.SDequationEditField.Value;
                 MainInput.MorphometryAnalysis= app.MorphometryAnalysisCheckBox.Value;
                 MainInput.StudyType = app.StudyTypeDropDown.Value;
                 MainInput.CylindricalModelCM = app.CylindricalModelCMCheckBox.Value;
                 MainInput.StretchExponentialModelSEM = app.StretchExponentialModelSEMCheckBox.Value;
                 MainInput.Docm2s = app.Docm2sEditField.Value;
                 MainInput.Deltams = app.DeltamsEditField.Value;
                 MainInput.VentMean = app.VentMeanEditField.Value;
                 MainInput.VentSD = app.VentSDEditField.Value;
                 MainInput.DissolvedMean = app.DissolvedMeanEditField.Value;
                 MainInput.DissolvedSD = app.DissolvedSDEditField.Value;
                 MainInput.BarrierMean = app.MembraneMeanEditField.Value;
                 MainInput.BarrierSD = app.MembraneSDEditField.Value;
                 MainInput.RBCMean= app.RBCMeanEditField.Value;
                 MainInput.RBCSD = app.RBCSDEditField.Value;
                 MainInput.RBCBarrMean = app.RBCBarrMeanEditField.Value;
                 MainInput.RBCBarrSD = app.RBCBarrSDEditField.Value;
                 MainInput.RBCOscMean = app.RBCOscMeanEditField.Value;
                 MainInput.RBCOscSD = app.RBCOscSDEditField.Value;
                 MainInput.ProcessingDate = app.ProcessingDateDatePicker.Value;
         end

         function FACal_calculation(app)
            if app.PerformCalibrationStatus == 1 
                Alpha=1:0.1:90;
                SType = app.SequenceTypeDropDown.Value;
                optVentFA = atand(sqrt(2/app.VentilationExcitationsEditField.Value));
                optDiffFA = acosd((0.2)^(1/(app.DiffusionExcitationsEditField.Value*app.DiffusionbvaluesEditField.Value)));

                NonCartesianFAsignalsetting = 'Maximizing Signal'; %  'Maximizing Signal' or 'Minimizing Artifacts'
                VentRF = app.VentilationExcitationsEditField.Value;
                DiffRF = app.DiffusionExcitationsEditField.Value * app.DiffusionbvaluesEditField.Value;   
                Nb = app.DiffusionbvaluesEditField.Value; 
                if strcmp(SType, '2D GRE') == 1 || strcmp(SType, '3D GRE') == 1
                            Vent_S = sind(Alpha).*(((cosd(Alpha).^VentRF)-1)./log(cosd(Alpha)));    
                            [~,locs] = findpeaks(Vent_S);
                            optVentFA = Alpha(locs); 
                            Diff_S = sind(Alpha).*(((cosd(Alpha).^DiffRF)-1)./log(cosd(Alpha)));    
                            [~,locs] = findpeaks(Diff_S);
                            optDiffFA = Alpha(locs); 

%                     switch app.GREorderButtonGroup.SelectedObject.Text
%                         case 'Sequential' 
%                                 Vent_S = sind(Alpha).*(cosd(Alpha).^((VentRF/2)-1)).*(((cosd(Alpha).^VentRF)-1)./log(cosd(Alpha)));    
%                                 [~,locs] = findpeaks(Vent_S);
%                                 optVentFA = Alpha(locs); 
%                                 Diff_S = sind(Alpha).*(cosd(Alpha).^((DiffRF/2)-1)).*(((cosd(Alpha).^DiffRF)-1)./log(cosd(Alpha)));    
%                                 [~,locs] = findpeaks(Diff_S);
%                                 optDiffFA = Alpha(locs); 
%                         case 'Centric'
%                                 Vent_S = sind(Alpha).*(((cosd(Alpha).^VentRF)-1)./log(cosd(Alpha)));    
%                                 [~,locs] = findpeaks(Vent_S);
%                                 optVentFA = Alpha(locs); 
%                                 Diff_S = sind(Alpha).*(((cosd(Alpha).^DiffRF)-1)./log(cosd(Alpha)));    
%                                 [~,locs] = findpeaks(Diff_S);
%                                 optDiffFA = Alpha(locs); 
%                     end
                elseif strcmp(SType, '2D Radial') == 1 || strcmp(SType, '3D Radial') == 1 || strcmp(SType, '2D Spiral') == 1 || strcmp(SType, '3D Spiral (SoS)') == 1 || strcmp(SType, '3D Spiral') == 1              
                    switch NonCartesianFAsignalsetting
                        case 'Maximizing Signal'                             
                            Vent_S = 1./VentRF.*sind(Alpha).*(1-cosd(Alpha).^VentRF)./(1-cosd(Alpha));    
                            [~,locs] = findpeaks(Vent_S);
                            optVentFA = Alpha(locs); 

                            Diff_S = 1./DiffRF.*sind(Alpha).*(1-cosd(Alpha).^DiffRF)./(1-cosd(Alpha).^Nb);    
                            [~,locs] = findpeaks(Diff_S);
                            optDiffFA = Alpha(locs); 
                        case 'Minimizing Artifacts'    
                            optVentFA = acosd(sqrt((VentRF-1)/VentRF));
                            optDiffFA = acosd(sqrt((DiffRF-1)/DiffRF));
                    end  
                end
                optGasExGas = 0.5;
                optGasExDiss = 20;
                scaledVentFA = optVentFA / app.FA_Scale_Factor;
                scaledDiffFA = optDiffFA / app.FA_Scale_Factor;
                scaledGasExGas = optGasExGas / app.FA_Scale_Factor;
                scaledGasExDiss = optGasExDiss / app.FA_Scale_Factor;

                % Vent FA
                app.Cal_result_table{5,1} = {[num2str(round(optVentFA,2))]};
                app.Cal_result_table{5,2} = {[num2str(round(scaledVentFA,2))]};
                % Diff FA
                app.Cal_result_table{6,1} = {[num2str(round(optDiffFA,2))]};
                app.Cal_result_table{6,2} = {[num2str(round(scaledDiffFA,2))]};
                % Gas Exchange gas FA
                app.Cal_result_table{7,1} = {[num2str(round(optGasExGas,2))]};
                app.Cal_result_table{7,2} = {[num2str(round(scaledGasExGas,2))]};
                % Gas exchange dissolve FA
                app.Cal_result_table{8,1} = {[num2str(round(optGasExDiss,2))]};
                app.Cal_result_table{8,2} = {[num2str(round(scaledGasExDiss,2))]};
                app.UICalTable.Data = app.Cal_result_table;
            end
         end

         function DDI_message(app)
                promptMessage = sprintf([app.DefectMapDropDown.Value,' needs to be selected in the VDP analysis']);
                questdlg(promptMessage, 'DDI message', 'Okay', 'Okay');  
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                clc;             
                app.HPXeMRIAnalysisApp.Name='Hyperpolarized 129Xe MRI Analysis App';
                app.panelchild = uipanel(app.ViewingWindowPanel,'Position',[1 50 440 490],'FontSize',12,'BorderType','none');
                %clc; clear all; close all;       

                % Reset viewer 
                clear app.vieweing_img; 
                app.viewing_img = zeros(50, 50, 50);
                app.viewing_img2 = app.viewing_img;                
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];
                app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                app.ChLineWidthSpinner.Value = 1;
                app.ScaleXSpinner.Value = 3;
                app.ScaleYSpinner.Value = 3;
                app.ScaleZSpinner.Value = 1;
                app.ColorMapDropDown.Value = 'gray';
                app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                app.D1Spinner.Value = 1;
                app.D2Spinner.Value = 2; 
                app.panelchild = uipanel(app.ViewingWindowPanel,'Position',[1 50 440 490],'FontSize',12,'BorderType','none');                
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];
                Update_orthoViewer(app,app.viewing_img);
                app.VentilationAnalysisPerformed = 0;
                app.DiffusionAnalysisPerformed = 0;
                app.GasExchangeAnalysisPerformed = 0;

                % Reset classes
                clear app.Ventilation;   
                clear app.Diffusion;      
                clear app.GasExchange;   
                clear app.Proton;        
                clear app.MainInput;      
                clear app.XeDataLocation;
                clear app.HDataLocation;
                clear app.GasExResults
                clear app.CalResults

                % Reset app inputs
                app.PerformCalibrationButton.Enable = 'off';
                app.SaveResultButton.Enable = 'off'; 
                app.TransformTypeButtonGroup.Enable = 'off'; 
                app.ImageResolutionmmPanel.Enable = 'off'; 
                app.PerformRegistrationButton.Enable = 'off'; 
                app.ViewImagesPanel_2.Enable = 'off'; 
                app.SegmentationMethodDropDown.Enable = 'off'; 
                app.AutoImageSegmentationAIPanel.Enable = 'off'; 
                app.ThresholdImageSegmentationPanel.Enable = 'off'; 
                app.ManualImageSegmentationPanel.Enable = 'off';
                app.LoadSegmentedMaskButton.Enable = 'off'; 
                app.ViewMaskPanel.Enable = 'off'; 
                app.PerformAnalysisButton.Enable = 'off'; 
                app.ThresholdMethodResultsPanel.Enable = 'off'; 
                app.LinearBinningMethodResultsPanel.Enable = 'off'; 
                app.TextureAnalysisGLRLMResultsPanel.Enable = 'off';
                app.ADCResultsPanel.Enable = 'off'; 
                app.MorphometryAnalysisResultPanel.Enable = 'off'; 
                app.VentilationSummaryPanel.Enable = 'off'; 
                app.DissolvedSummaryPanel.Enable = 'off'; 
                app.RBCSummaryPanel.Enable = 'off'; 
                app.RBCMembraneSummaryPanel.Enable = 'off'; 
                app.MembraneSummaryPanel.Enable = 'off'; 
                app.RBCOscillationSummaryPanel.Enable = 'off';                
                app.SliceSelectionCheckBox.Enable = 'off'; 
                app.SliceSeclectionPanel.Enable = 'off';  
                app. GenerateReportButton.Enable = 'off';
                app. LoadPerformSegmentationButton.Enable = 'off';
                app. EditMaskButton.Enable = 'off';
                app. LoadReadDataButton.Enable = 'off';
                app. ViewImagesPanel.Enable = 'off';
                app. KmeansResultsPanel.Enable = 'off';
                app. DDIResultsPanel.Enable = 'off';
                app.AnalysisExecutionModeDropDown.Value = 'Perform each step individually';  
                app.ProcessingDateDatePicker.Value = datetime('today');

                app.UICalTable.RowName={'scaled factor'; 'R.voltage'; 'Frequency'; 'TE90'; 'Vent FA'; 'Diff FA'; 'GasEx gas FA'; 'GasEx diss FA'};
                app.UICalTable.ColumnName = {'Initial/optimal'; 'Scaled'}; 
                app.Cal_result_table = array2table(string(zeros(8,2)));
                app.Cal_result_table{1,1} = 1;
                app.UICalTable.Data = app.Cal_result_table;
                
        end

        % Button pushed function: PerformCalibrationButton
        function PerformCalibrationButtonPushed(app, event)
            
            cd(app.MainInput.XeDataLocation);
            diary Log.txt
%             try
                disp('========================= Calibration Analysis started =======================');
                app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Starting Calibration analysis, please wait...';  pause(1);
                cla(app.FA_Axes); cla(app.Gas_Axes); cla(app.Diss_Axes); cla(app.Spct_Axes);
                app.MainInput.Institute = app.InstituteDropDown.Value; 
                app.MainInput.Scanner = app.ScannerDropDown.Value; 
                app.MainInput.ScannerSoftware = app.ScannerSoftwareDropDown.Value; 
                app.MainInput.SequenceType = app.SequenceTypeDropDown.Value; 

            if  strcmp(app.SequenceTypeDropDown.Value,'Spectroscopy') == 1 
                [app.GasExResults, app.CalResults] = Calibration.StartCalibrationAnalysis(app.MainInput);                        
                app.PerformCalibrationStatus = 1;
                app.FA_Scale_Factor = app.CalResults.FlipScaleFactor;
                app.Reference_Voltage = app.CalResults.Reference_Voltage; 
                figure(app.HPXeMRIAnalysisApp) %Bring app to front if not
                % Print Results
                freq_target_PPM = app.CalResults.freq_target * 0.02830;

                AssumptionsTextArea = [...
                    sprintf('Frequncy = %3.1f Hz\n', app.CalResults.freq),...
                    sprintf('TE = %3.3f ms\n', app.CalResults.te/1000),...
                    sprintf('Dwell = %3.3f µs\n', app.CalResults.dwell_time*1e6)...
                    sprintf('FA target = %3.1f deg.\n', app.CalResults.FlipTarget),...        
                    sprintf('nDiss = %3d\n', app.CalResults.nDis),... 
                    sprintf('nCal = %3d\n', app.CalResults.nCal),... 
                    sprintf('Field strength = %3.1fT\n', 3),... 
                    ];
                app.CalAssumptionsTextArea.Value = AssumptionsTextArea;
                              
                if (app.CalResults.flip_angle < 5 || app.CalResults.flip_angle > 30)
                    app.FA_Scale_Factor = 1;
                end

                app.CalAssumptionsTextArea.Value = AssumptionsTextArea;
                % Plot FA
                fitfunct = @(coefs,xdata)coefs(1)*cos(coefs(2)).^(xdata-1) + app.CalResults.noise_est;
%                 fitfunct = @(coefs,xdata)coefs(1)*cos(coefs(2)).^(xdata-1) +coefs(3);
                hold(app.FA_Axes,'on')
                plot(app.FA_Axes,app.CalResults.Pulses,app.CalResults.GasDecay,'bo','MarkerFaceColor','b');
                plot(app.FA_Axes,app.CalResults.Pulses,fitfunct(app.CalResults.DecayFit,app.CalResults.Pulses),'-r');
                hold(app.FA_Axes,'off')
                app.FA_Text.Text = sprintf('Flip Angle = %3.3f%c%3.3f%c\n', app.CalResults.flip_angle, char(177), app.CalResults.flip_err, char(176));
                % Plot Gas
                GasFit = Calibration.NMR_Mix(app.CalResults.GasFit.area, app.CalResults.GasFit.freq, app.CalResults.GasFit.fwhm, app.CalResults.GasFit.phase);
                GasFit = GasFit.calcComponentSpectralDomainSignal(app.CalResults.GasFit.f);
                hold(app.Gas_Axes,'on')
                plot(app.Gas_Axes,app.CalResults.GasFit.f,abs(app.CalResults.GasFit.spectralDomainSignal),'bo','MarkerFaceColor','b');
                plot(app.Gas_Axes,app.CalResults.GasFit.f,abs(GasFit),'-r','LineWidth',2);
                hold(app.Gas_Axes,'off')
                app.Gas_Text.Text = sprintf('Gas Frequency = %3.3fHz\n', app.CalResults.GasFit.freq);
                % Plot Diss
    %             dwell_time = 9.8*1E-6;%us to s
                dwell_time = app.CalResults.dwell_time;%us to s            
                time = dwell_time*(0:length(GasFit)-1);
                DissFit = dwell_time*fftshift(fft(app.GasExResults.DisFit.calcComponentTimeDomainSignal(time),[],1),1);
    
                hold(app.Diss_Axes,'on')
                plot(app.Diss_Axes,app.GasExResults.DisFit.f,abs(app.GasExResults.DisFit.spectralDomainSignal),'bo','MarkerFaceColor','b');
                plot(app.Diss_Axes,app.GasExResults.DisFit.f,abs(sum(DissFit,2)),'-r','LineWidth',2);
                hold(app.Diss_Axes,'off')
                app.Diss_Text.Text = [...
                    sprintf('Phase Diff. = %3.2f%c\n', app.GasExResults.DisFit.phase(1) - app.GasExResults.DisFit.phase(2), char(176)),...
                    sprintf('Freq. Diff. = %3.1fHz\n', app.GasExResults.DisFit.freq(1) - app.GasExResults.DisFit.freq(2)),...
                    sprintf('RBC/Bar Ratio = %3.2f\n', app.GasExResults.RbcBarRatio),...
                    sprintf('Gas Contam. = %3.1f%%\n', app.GasExResults.GasDisRatio * 100),...               
                    ];
                % Plot Spct
                hold(app.Spct_Axes,'on') 
                plot(app.Spct_Axes,app.CalResults.GasFit.f,real(DissFit(:,1)),'r','Linewidth',2,'DisplayName','RBC');
                plot(app.Spct_Axes,app.CalResults.GasFit.f,real(DissFit(:,2)),'b','Linewidth',2,'DisplayName','Membrane');
                plot(app.Spct_Axes,app.CalResults.GasFit.f,real(DissFit(:,3)),'g','Linewidth',2,'DisplayName','Gas');
                legend(app.Spct_Axes);
                hold(app.Spct_Axes,'off');

                                % fill out result table
                % scale factor
                app.Cal_result_table{1,2} = {num2str(app.CalResults.FlipScaleFactor)};
                % Reference Voltage
                app.Cal_result_table{2,1} = {[num2str(app.CalResults.VRef),' v']};
                app.Cal_result_table{2,2} = {[num2str(app.CalResults.Reference_Voltage),' v']};
                % Frequncy
                app.Cal_result_table{3,1} = {[char(916),'=',num2str(app.CalResults.GasFit.freq),' Hz']};                
                app.Cal_result_table{3,2} = {[num2str(freq_target_PPM),'ppm/',num2str(app.CalResults.freq_target),'Hz']};  
                % TE90
                app.Cal_result_table{4,1} = {[num2str(round(app.CalResults.te90,2)),' ms']};
                if (app.CalResults.te90 < 0.42 || app.CalResults.te90 > 0.52)
                    app.Cal_result_table{4,2} = {'default'};                
                else
                    app.Cal_result_table{4,2} = {[num2str(round(app.CalResults.te90,2)),' ms']};               
                end  
                app.UICalTable.Data = app.Cal_result_table;
                % calculate optimal FAs
                FACal_calculation(app)
                %NonCartesianFAsignalsettingButtonGroupSelectionChanged(app, event);
                 
                app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value = 'Calibration analysis completed.';  pause(1); 
                disp('========================= Calibration Analysis completed =======================');
                app.SaveResultButton.Enable = 'on';
                Global.tts('Calibration Analysis completed');
            else
                app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value = 'Calibration analysis can only be performed on spectroscopy sequence type';  pause(.1); 
            end   
            diary off
        end

        % Value changed function: VentilationExcitationsEditField
        function VentilationExcitationsEditFieldValueChanged(app, event)
            if app.PerformCalibrationStatus == 1 
                FACal_calculation(app)
                app.StatusTextArea.Value='Optimum Flip Angles have been updated.';  pause(1); 
            end
        end

        % Value changed function: DiffusionExcitationsEditField
        function DiffusionExcitationsEditFieldValueChanged(app, event)
            if app.PerformCalibrationStatus == 1 
                FACal_calculation(app)
                app.StatusTextArea.Value='Optimum Flip Angles have been updated.';  pause(1); 
            end
        end

        % Value changed function: DiffusionbvaluesEditField
        function DiffusionbvaluesEditFieldValueChanged(app, event)
            if app.PerformCalibrationStatus == 1 
                FACal_calculation(app)
                app.StatusTextArea.Value='Optimum Flip Angles have been updated.';  pause(1); 
            end
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
                % Location of Calibration data
                try
                    app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Selecting xenon data file...';  pause(.1); 
    
                    [filename, path] = uigetfile('*.*','Select xenon data file');
                    XeFullPath = [path,filename];
                    app.XeDataLocation = path(1:end-1);
                    [~,Xe_name,ext] = fileparts(XeFullPath);
                    
                    app.MainInput.XeFullPath = XeFullPath;
                    app.MainInput.XeDataLocation = app.XeDataLocation;
                    app.MainInput.XeFileName = filename;
                    app.MainInput.Xe_name = Xe_name;
                    app.MainInput.XeDataext = ext;
                    cd(app.MainInput.XeDataLocation);
                    app.PerformCalibrationButton.Enable = 'on';
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Data has been selected.';  pause(.1); 
                    clearvars -except app.MainInput 
                catch
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Data has not been selected.';  pause(.1); 
                end
        end

        % Button pushed function: decreaseSlice
        function decreaseSlicePushed(app, event)
            app.sliceSlider.Limits = [1 size(app.viewing_img,3)];  
            if app.orthoViewer.SliceNumbers(3) > 1
                app.orthoViewer.SliceNumbers = [app.orthoViewer.SliceNumbers(1) app.orthoViewer.SliceNumbers(2) app.orthoViewer.SliceNumbers(3)- 1];
                app.sliceSlider.Value = app.orthoViewer.SliceNumbers(3);
            end
        end

        % Button pushed function: increaseSlice
        function increaseSlicePushed(app, event)
            app.sliceSlider.Limits = [1 size(app.viewing_img,3)];  
            if app.orthoViewer.SliceNumbers(3) < app.sliceSlider.Limits(2)
                app.orthoViewer.SliceNumbers = [app.orthoViewer.SliceNumbers(1) app.orthoViewer.SliceNumbers(2) app.orthoViewer.SliceNumbers(3) + 1];                
                app.sliceSlider.Value = app.orthoViewer.SliceNumbers(3);
            end 
        end

        % Button pushed function: ResetContrast
        function ResetContrastPushed(app, event)
            if app.ImageType == 0
                app.viewing_img = app.viewing_img/max (app.viewing_img(:));
                app.viewing_img = app.viewing_img.*app.viewing_img_max;   
                app.ContrastSlider.Limits = abs([min(app.viewing_img(:)) 2*app.viewing_img_max]);  app.ContrastSlider.Value = round(app.ContrastSlider.Limits(2)/2);   
                app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) app.viewing_img_max];  
                app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*app.viewing_img_max];  app.ContrastSlider.Value = round(app.ContrastSlider.Limits(2)/2);        
                colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [min(app.viewing_img(:)) max(app.viewing_img(:))]);  
                app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*max(app.viewing_img(:))];  app.ContrastSlider.Value = round(max(app.viewing_img(:))/2);
            end 
        end

        % Value changed function: CrosshairEnableSwitch
        function CrosshairEnableSwitchValueChanged(app, event)
            app.orthoViewer.CrosshairEnable = app.CrosshairEnableSwitch.Value;            
        end

        % Value changed function: ChColorDropDown
        function ChColorDropDownValueChanged(app, event)
            app.orthoViewer.CrosshairColor = app.ChColorDropDown.Value;
        end

        % Value changed function: ChLineWidthSpinner
        function ChLineWidthSpinnerValueChanged(app, event)
            app.orthoViewer.CrosshairLineWidth = app.ChLineWidthSpinner.Value;
        end

        % Value changed function: ScaleXSpinner
        function ScaleXSpinnerValueChanged(app, event)
            app.orthoViewer.ScaleFactors = [app.ScaleXSpinner.Value app.ScaleYSpinner.Value app.ScaleZSpinner.Value];
        end

        % Value changed function: ScaleYSpinner
        function ScaleYSpinnerValueChanged(app, event)
            app.orthoViewer.ScaleFactors = [app.ScaleXSpinner.Value app.ScaleYSpinner.Value app.ScaleZSpinner.Value];
        end

        % Value changed function: ScaleZSpinner
        function ScaleZSpinnerValueChanged(app, event)
            app.orthoViewer.ScaleFactors = [app.ScaleXSpinner.Value app.ScaleYSpinner.Value app.ScaleZSpinner.Value];
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
        if app.ImageType == 0
            app.orthoViewer.Colormap = eval(app.ColorMapDropDown.Value);
            colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]); 
        end 
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged4(app, event)
                if app.ImageType == 0 && app.ContrastSlider.Value < 2*app.viewing_img_max && app.ContrastSlider.Value ~= 0
                    app.orthoViewer.DisplayRange = [0 app.ContrastSlider.Value];                
                end                 
        end

        % Value changed function: sliceSlider
        function sliceSliderValueChanged2(app, event)
                if strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1 
                    app.sliceSlider.Limits = [1 size(app.viewing_img2,3)];
                else
                    app.sliceSlider.Limits = [1 size(app.viewing_img,3)];
                end
                app.orthoViewer.SliceNumbers = [app.orthoViewer.SliceNumbers(1) app.orthoViewer.SliceNumbers(2) app.sliceSlider.Value];
        end

        % Button pushed function: swapdimensionButton
        function swapdimensionButtonPushed(app, event)

                if app.D1Spinner.Value ~= app.D2Spinner.Value || strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1 
                    d1 = app.D1Spinner.Value;
                    d2 = app.D2Spinner.Value;           
                    if strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1 
                        app.viewing_img = app.viewing_img2;
                    end
                    if (length(size(app.viewing_img)) == 3) && (app.D1Spinner.Value ~= 4 || app.D2Spinner.Value ~= 4)...
                            && strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 0 
                        intilshift = 1:3;
                        shift = intilshift;
                        intilshift (shift == d1) = d2;
                        intilshift (shift == d2) = d1;
                        shiftImage = permute(app.viewing_img, intilshift);
                        app.viewing_img = shiftImage;
                    elseif length(size(app.viewing_img)) == 4 || strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1                   
                        intilshift = 1:4;
                        shift = intilshift;
                        intilshift (shift == d1) = d2;
                        intilshift (shift == d2) = d1;
                        shiftImage = permute(app.viewing_img, intilshift); 
                        if  strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1 
                            app.viewing_img2 = shiftImage;
                            app.viewing_img = squeeze(app.viewing_img2(:,:,:,1));    
                        else
                            app.viewing_img = shiftImage;  
                        end                        
                    end

                    %app.viewing_img2 = app.viewing_img;
                    app.sliceSlider.Limits = [1 size(app.viewing_img2,3)]; 
                    set(app.panelchild,'visible','off');
                    app.panelchild = uipanel(app.ViewingWindowPanel,'Position',[1 60 500 470],'FontSize',12,'BorderType','none');
                    app.orthoViewer = orthosliceViewer(app.viewing_img,...
                                 'CrosshairColor', app.ChColorDropDown.Value,...
                             'CrosshairLineWidth', app.ChLineWidthSpinner.Value,...
                                         'Parent', app.panelchild,...
                           'CrosshairStripeColor', 'none',...
                                'CrosshairEnable', app.CrosshairEnableSwitch.Value,...
                                       'Colormap', eval(app.ColorMapDropDown.Value),...
                                   'ScaleFactors', [app.ScaleXSpinner.Value app.ScaleYSpinner.Value app.ScaleZSpinner.Value],...
                        'DisplayRangeInteraction', 'off',...
                                   'DisplayRange', [0  app.ContrastSlider.Value]);  
                    if app.ImageType == 0
                        if sum(app.viewing_img(:)) > 0
                            colormap(app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]); 
                        else
                            colormap(app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); 
                        end
                    end                                                                   
                else 
                    app.StatusTextArea.Value='dimenssion of color images cannot be sawpped...';  pause(.1);                     
                end
        end

        % Button pushed function: PixelInfoButton
        function PixelInfoButtonPushed(app, event)
                if app.ImageType == 0 
                    Global.my_pixinfo_tool(squeeze(app.viewing_img(:, :, app.orthoViewer.SliceNumbers(3))));
                elseif app.ImageType == 1 
                    Global.my_pixinfo_tool(squeeze(app.viewing_img(:, :, app.orthoViewer.SliceNumbers(3),:)));
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                promptMessage = sprintf('Are you sure you want to reset settings');
                titleBarCaption = 'Yes or No';
                button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
                if strcmpi(button, 'Yes')
                    app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Resetting...';  pause(.1); 
                    app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                    app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                    app.ChLineWidthSpinner.Value = 1;
                    app.ScaleXSpinner.Value = 3;
                    app.ScaleYSpinner.Value = 3;
                    app.ScaleZSpinner.Value = 1;
                    app.ColorMapDropDown.Value = 'gray';
                    app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                    app.D1Spinner.Value = 1;
                    app.D2Spinner.Value = 2; 
                    app.panelchild = uipanel(app.ViewingWindowPanel,'Position',[1 50 440 490],'FontSize',12,'BorderType','none');
                    clear app.vieweing_img; 
                    app.viewing_img = app.viewing_img2;
                    if  strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') == 1 
                        app.viewing_img = squeeze(app.viewing_img2(:,:,:,1));    
                    end
                    app.sliceSlider.Limits = [1 size(app.viewing_img,3)];
                    ImagestoviewDropDownValueChanged(app, event);
                    Update_orthoViewer(app,app.viewing_img)
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Resetting completed.';  pause(.1); 
                else
                    delete(promptMessage);
                end            
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
                promptMessage = sprintf('Are you sure you want to apply changes');
                titleBarCaption = 'Yes or No';
                button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
                if strcmpi(button, 'Yes')     
                    app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Applying changes on images, please wait....';  pause(.1); 
                    switch app.ImagesbeingviewedEditField.Value
                        case 'Ventilation'                            
                            app.Ventilation.Image = app.viewing_img;
                        case 'Diffusion'
                            app.Diffusion.Image = app.viewing_img2;
                        case 'GasExchange: Ventilation'
                            app.GasExchange.VentImage= app.viewing_img;
                        case 'GasExchange: Gas'
                            app.GasExchange.GasImage= app.viewing_img;
                        case 'GasExchange: Dissolved'
                            app.GasExchange.DissolvedImage= app.viewing_img;
                        case 'Proton'
                            app.Proton.Image = app.viewing_img;
                        case 'Proton & Xe Registered'
                        case 'Proton Registered'
                            app.Proton.ProtonRegistered = app.viewing_img;
                        case 'Lung Mask'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'
                                   app.Ventilation.LungMask = app.viewing_img;
                                case 'Diffusion'
                                   app.Diffusion.LungMask = app.viewing_img;
                                case 'GasExchange'
                                   app.GasExchange.LungMask = app.viewing_img;   
                                   app.GasExchange.ProtonMaskRegistred = app.viewing_img;
                                   app.Proton.ProtonMaskRegistred = app.viewing_img;
                            end
                        case 'Airway Mask'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'
                                   app.Ventilation.AirwayMask = app.viewing_img;
                                case 'Diffusion'
                                   app.Diffusion.AirwayMask = app.viewing_img;
                                case 'GasExchange'
                                   app.GasExchange.AirwayMask = app.viewing_img;                           
                            end
                        case 'Lung & Airway Mask'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'
                                   app.Ventilation.Mask = app.viewing_img;
                                case 'Diffusion'
                                   app.Diffusion.Mask = app.viewing_img;
                                case 'GasExchange'
                                   app.GasExchange.Mask = app.viewing_img;                           
                            end
                    end
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Changes on images have been applied.';  pause(.1); 
                else
                    delete(promptMessage);
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Changes on images have not been applied.';  pause(.1); 
                end         

        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
        folder = fileparts(mfilename('\user_manual\'));
        winopen(fullfile(folder, 'User_Manual.pdf'));       

        % % Get the directory of the currently executing script or function
        % script_path = mfilename('fullpath');
        % 
        % % Extract the directory containing the script or function
        % app_directory = fileparts(script_path);
        % 
        % % Search for the mauna_user folder
        % user_manual_folder = dir(fullfile(app_directory, 'user_manual'));
        % 
        % % Check if the folder is found
        % if isempty(user_manual_folder)
        %     app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='user_manual_folder not found.';  pause(.1); 
        %     %error('user_manual_folder folder not found.');
        % end
        % 
        % % Create the full path to the PDF file
        % pdf_path = fullfile(app_directory, 'user_manual', 'User_Manual.pdf');
        % 
        % % Check if the PDF file exists
        % if exist(pdf_path, 'file') ~= 2
        %     app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='User_Manual.pdf not found.';  pause(.1); 
        %     %error('User_Manual.pdf not found in mauna_user folder.');
        % end
        % 
        % % Open the PDF file
        % open(pdf_path);

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
              promptMessage = sprintf('Are you sure you want to Exit?');
              titleBarCaption = 'Exit';
              button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
              if strcmpi(button, 'Yes')
                exit
              else
                delete(promptMessage);
              end            
        end

        % Button pushed function: SelectGasDataButton
        function SelectGasDataButtonPushed(app, event)
                % Location of Xenon data
                % try
                    app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Selecting data location...';  pause(.1); 
                    [filename, path] = uigetfile('*.*','Select xenon data file');
                    XeFullPath = [path,filename];
                    app.XeDataLocation = path(1:end-1);
                    [~,Xe_name,ext] = fileparts(XeFullPath);
                    
                    app.MainInput.XeFullPath = XeFullPath;
                    app.MainInput.XeDataLocation = app.XeDataLocation;
                    app.MainInput.XeFileName = filename;
                    app.MainInput.Xe_name = Xe_name;
                    app.MainInput.XeDataext = ext;
                    cd(app.MainInput.XeDataLocation)
                    if strcmp(ext, '.h5') || strcmp(ext, '.MRD')
                        try
                            app.MainInput.CalFileName = dir(fullfile(app.XeDataLocation, '*Calibration*.h5'));
                        catch
                            error('no Calibration file existed')
                        end
                    end
                    % select calibration
                    app.MainInput.Institute = app.InstituteDropDown.Value;
                    if strcmp(app.MainInput.Institute,'XeCTC') && strcmp(app.AnalysisTypeDropDown.Value,'GasExchange') && (strcmp(app.MainInput.XeDataext,'.dat') || strcmp(app.MainInput.XeDataext,'.p'))
                        [filename, path] = uigetfile('*.*','Select calibration data file');
                        CalFullPath = [path,filename];
                        CalDataLocation = path(1:end-1);
                        [~,Cal_name,Cal_ext] = fileparts(CalFullPath);
                    
                        app.MainInput.CalFullPath = CalFullPath;
                        app.MainInput.CalDataLocation = CalDataLocation;
                        app.MainInput.CalFileName = filename;
                        app.MainInput.Cal_name = Cal_name;    
                        app.MainInput.CalDataext = Cal_ext;
                    end

                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Xenon data type and location have been selected.';  pause(.1); 
                    app.LoadReadDataButton.Enable = 'on';
                % catch
                %     app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Xenon data type and location have NOT been selected.';  pause(.1); 
                % end

        end

        % Button pushed function: SelectAnatomicalDataButton
        function SelectAnatomicalDataButtonPushed(app, event)
                % Location of Proton data
                try
                    app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Selecting Proton data location...';  pause(.1); 
                    [filename, path] = uigetfile('*.*','Select proton data file');
                    HFullPath = [path,filename];
                    app.HDataLocation = path(1:end-1);
                    [~,H_name,ext] = fileparts(HFullPath);
                    
                    app.MainInput.HFullPath = HFullPath;
                    app.MainInput.HDataLocation = app.HDataLocation;
                    app.MainInput.HFileName = filename;
                    app.MainInput.H_name = H_name; 
                    app.MainInput.HDataext = ext;
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Proton data type and location have been selected.';  pause(.1);     
                catch
                    app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Proton data type and location have NOT been selected.';  pause(.1); 
                end

        end

        % Button pushed function: LoadReadDataButton
        function LoadReadDataButtonPushed(app, event)
            try
                % load data function 
                % update app progress message statement
                app.ReadyLamp.Color = 'red'; 
                app.StatusTextArea.Value=['Loading/Reconstructing ',app.AnalysisTypeDropDown.Value,' data, please wait...'];  
                pause(.1); 
                
                % create a log file
                cd(app.XeDataLocation)
                diary Log.txt 
                
                %------------------ Update main inputs-------------------------
                % select scan/institute type
                app.MainInput.Institute = app.InstituteDropDown.Value;
                app.MainInput.Scanner = app.ScannerDropDown.Value;
                app.MainInput.ScannerSoftware = app.ScannerSoftwareDropDown.Value;
                app.MainInput.SequenceType = app.SequenceTypeDropDown.Value;            
                
                % select proton data  
                if app.NoProtonImagesCheckBox.Value == 0
                    app.MainInput.NoProtonImage = 0; 
                else 
                    app.MainInput.NoProtonImage = 1;                
                end
    
                % denoise option 
                if app.DenoiseXeImageCheckBox.Value == 0
                    app.MainInput.denoiseXe = 'no'; 
                else 
                    app.MainInput.denoiseXe = 'yes';                 
                end
                app.MainInput.denoiseSD = app.DenoiseSDEditField.Value ;   
                app.MainInput.AnalysisType = app.AnalysisTypeDropDown.Value;
                app.MainInput.Nbvalues = app.NofbvaluesEditField.Value; 
                app.MainInput.DiffAcqOrder = app.DiffAcqOrderDropDown.Value; 
                cd(app.XeDataLocation)
                
                %------------------ call main load data funtion----------------------
    %             try
                    [app.Ventilation, app.Diffusion, app.GasExchange, app.Proton] = LoadData.LoadReadData(app.MainInput);       
                    app.ReadyLamp.Color = 'green'; 
                    app.StatusTextArea.Value = ['Loading ',app.AnalysisTypeDropDown.Value,' Data completed.'];  pause(.1);                                                  
    %             catch 
    %                 app.ReadyLamp.Color = 'green';  
    %                 app.StatusTextArea.Value='Loading data did not performed correctly, please check log file.';  pause(.1);                 
    %             end
                % enable segmentation
                if app.NoProtonImagesCheckBox.Value == 0 
                    app.LoadPerformSegmentationButton.Enable = 'off';
                else 
                    app.LoadPerformSegmentationButton.Enable = 'on';    
                    app.SegmentationMethodDropDown.Enable = 'on';
                    SegmentationMethodDropDownValueChanged(app, event)
                end
                app.ViewImagesPanel.Enable = 'on';
                
                if app.NoProtonImagesCheckBox.Value == 0
                    switch app.AnalysisTypeDropDown.Value
                        case 'Ventilation'
                            app.ImagestoviewDropDown.Items = {'Ventilation','Proton Images'};
                            app.TransformTypeButtonGroup.Enable = 'on';
                            app.ImageResolutionmmPanel.Enable = 'on'; 
                            app.PerformRegistrationButton.Enable = 'on'; 
                            app.SliceSelectionCheckBox.Enable = 'on';                        
                        case 'Diffusion'
                            app.ImagestoviewDropDown.Items = {'Diffusion','Proton Images'};
                            app.SegmentationMethodDropDown.Enable = 'on';
                        case 'GasExchange'
                            app.ImagestoviewDropDown.Items = {'GasExchange: Ventilation', 'GasExchange: Gas', 'GasExchange: Dissolved','Proton Images'};    
                            app.TransformTypeButtonGroup.Enable = 'on';
                            app.ImageResolutionmmPanel.Enable = 'on'; 
                            app.PerformRegistrationButton.Enable = 'on'; 
                            app.SliceSelectionCheckBox.Enable = 'on'; 
                    end
    
                else
                    switch app.AnalysisTypeDropDown.Value
                        case 'Ventilation'
                           app.ImagestoviewDropDown.Items = {'Ventilation'};
                        case 'Diffusion'
                           app.ImagestoviewDropDown.Items = {'Diffusion'};
                        case 'GasExchange'
                           app.ImagestoviewDropDown.Items = {'GasExchange: Ventilation', 'GasExchange: Gas', 'GasExchange: Dissolved'};                            
                    end 
                    app.SegmentationMethodDropDown.Enable = 'on';          
                end
                
    %           try
                app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                app.ChLineWidthSpinner.Value = 1;
                app.ScaleXSpinner.Value = 3;
                app.ScaleYSpinner.Value = 3;
                app.ScaleZSpinner.Value = 1;
                app.ColorMapDropDown.Value = 'gray';
                app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                app.D1Spinner.Value = 1;
                app.D2Spinner.Value = 2;                 
                clear app.vieweing_img; app.viewing_img = zeros(10,10,10);
                                  
                if strcmp(app.ImagestoviewDropDown.Value, 'Ventilation') == 1 && strcmp(app.AnalysisTypeDropDown.Value, 'Ventilation') == 1
                   app.viewing_img = app.Ventilation.Image;   
                   app.viewing_img2 = app.viewing_img;
                   app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  
                   app.ImagesbeingviewedEditField.Value = 'Ventilation'; pause(.1); 
                elseif strcmp(app.ImagestoviewDropDown.Value, 'Diffusion') == 1 && strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1
                    app.viewing_img = squeeze(app.Diffusion.Image(:,:,:,1)); 
                    app.viewing_img2 = squeeze(app.Diffusion.Image); 
                    app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                    app.ImagesbeingviewedEditField.Value = 'Diffusion'; pause(.1);
                elseif strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && strcmp(app.ImagestoviewDropDown.Value, 'GasExchange: Ventilation') == 1  
                    app.viewing_img = app.GasExchange.VentImage;      
                    app.viewing_img2 = app.viewing_img;
                    app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                    app.ImagesbeingviewedEditField.Value = 'GasEx: Ventilation'; pause(.1);       
                elseif app.NoProtonImagesCheckBox.Value == 0 && strcmp(app.ImagestoviewDropDown.Value, 'Proton Images') == 1                    
                    app.viewing_img = app.Proton.Image; 
                    app.viewing_img2 = app.viewing_img;
                    app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                    app.ImagesbeingviewedEditField.Value = 'Proton'; pause(.1);
                else
                    app.StatusTextArea.Value = [app.ImagestoviewDropDown.Value,' images do not exist'];  pause(.1); 
                end
                app.viewing_img = double(abs(app.viewing_img));
                app.ImageType = 0; % gray image
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3);
               
                if app.ImageType == 0
                    colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]);  
                    app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*max(app.viewing_img(:))];  
                    app.ContrastSlider.Value = app.viewing_img_max;
                end 
                app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) max(app.viewing_img(:))];
                
                Update_orthoViewer(app,app.viewing_img);  
                pause(1); 
                switch app.AnalysisExecutionModeDropDown.Value
                    case 'Perform each step individually'                  
                    case 'Perform all steps collectively'                        
                        if strcmp(app.AnalysisTypeDropDown.Value, 'Ventilation') || strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange')
                            PerformRegistrationButtonPushed(app);
                            app.PerformRegistrationButton.Enable = 'on';
                            app.TransformTypeButtonGroup.Enable = 'on'; 
                            app.ImageResolutionmmPanel.Enable = 'on'; 
                            app.SliceSelectionCheckBox.Enable = 'on'; 
                        end
                        LoadPerformSegmentationButtonPushed(app);
                        PerformAnalysisButtonPushed(app);
                        app.SegmentationMethodDropDown.Enable = 'on'; 
                        app.ManualImageSegmentationPanel.Enable = 'on';
                        app.ThresholdImageSegmentationPanel.Enable = 'off'; 
                        app.LoadPerformSegmentationButton.Enable = 'on';
                        app.EditMaskButton.Enable = 'on'; 
                        app.PerformAnalysisButton.Enable = 'on'; 
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'
                                VDPMapButtonPushed(app);
                            case 'Diffusion'
                                ADCMapButtonPushed(app);
                            case 'GasExchange'
                                MapButtonVentPushed(app);
                        end
                end
            catch
             app.StatusTextArea.Value = 'something went wrong, please check log file.';  
             pause(.1);                     
            end            
        end

        % Value changed function: NoProtonImagesCheckBox
        function NoProtonImagesCheckBoxValueChanged(app, event)
            if app.NoProtonImagesCheckBox.Value == 1
                app.SelectAnatomicalDataButton.Enable = 'off'; 
                app.TransformTypeButtonGroup.Enable = 'off'; 
                app.ImageResolutionmmPanel.Enable = 'off'; 
                app.PerformRegistrationButton.Enable = 'off'; 
            elseif app.NoProtonImagesCheckBox.Value == 0
                app.SelectAnatomicalDataButton.Enable = 'on';      
                app.TransformTypeButtonGroup.Enable = 'on'; 
                app.ImageResolutionmmPanel.Enable = 'on'; 
                app.PerformRegistrationButton.Enable = 'on';                               
            end
        end

        % Button pushed function: imSliceViewerButton
        function imSliceViewerButtonPushed(app, event)
               if strcmp(app.AnalysisTypeDropDown.Value,'Diffusion') 
                    figure; Global.imslice(double(abs(app.viewing_img2)));
               else
                    figure; Global.imslice(double(abs(app.viewing_img)));
               end
        end

        % Value changed function: ImagestoviewDropDown
        function ImagestoviewDropDownValueChanged(app, event)
%                 try
                    app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                    app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                    app.ChLineWidthSpinner.Value = 1;
                    app.ScaleXSpinner.Value = 3;
                    app.ScaleYSpinner.Value = 3;
                    app.ScaleZSpinner.Value = 1;
                    app.ColorMapDropDown.Value = 'gray';
                    app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                    app.D1Spinner.Value = 1;
                    app.D2Spinner.Value = 2;                  
                    clear app.vieweing_img; app.viewing_img = zeros(10,10,10);                                      
                    
                    if strcmp(app.ImagestoviewDropDown.Value, 'Ventilation') == 1 && strcmp(app.AnalysisTypeDropDown.Value, 'Ventilation') == 1
                       app.viewing_img = app.Ventilation.Image;   
                       app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  
                       app.ImagesbeingviewedEditField.Value = 'Ventilation'; pause(.1); 
                    elseif strcmp(app.ImagestoviewDropDown.Value, 'Diffusion') == 1 && strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1
                        app.viewing_img = squeeze(app.Diffusion.Image(:,:,:,1)); 
                        app.viewing_img2 = squeeze(app.Diffusion.Image); 
                        app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                        app.ImagesbeingviewedEditField.Value = 'Diffusion'; pause(.1);
                    elseif strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && strcmp(app.ImagestoviewDropDown.Value, 'GasExchange: Ventilation') == 1  
                        app.viewing_img = app.GasExchange.VentImage;                       
                        app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                        app.ImagesbeingviewedEditField.Value = 'GasEx: Ventilation'; pause(.1);
                    elseif strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && strcmp(app.ImagestoviewDropDown.Value, 'GasExchange: Gas') == 1
                        app.viewing_img = app.GasExchange.GasImage;       
                        app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                        app.ImagesbeingviewedEditField.Value = 'GasEx: Gas'; pause(.1);
                    elseif strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && strcmp(app.ImagestoviewDropDown.Value, 'GasExchange: Dissolved') == 1
                        app.viewing_img = app.GasExchange.DissolvedImage;    
                        app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1);                         
                        app.ImagesbeingviewedEditField.Value = 'GasEx: Dissolved'; pause(.1);
                    elseif app.NoProtonImagesCheckBox.Value == 0 && strcmp(app.ImagestoviewDropDown.Value, 'Proton Images') == 1                    
                        app.viewing_img = app.Proton.Image; 
                        app.StatusTextArea.Value = ['Viewing ' ,app.ImagestoviewDropDown.Value,' Images...'];  pause(.1); 
                        app.ImagesbeingviewedEditField.Value = 'Proton'; pause(.1);
                    else
                        app.StatusTextArea.Value = [app.ImagestoviewDropDown.Value,' images do not exist'];  pause(.1); 
                    end
                    app.viewing_img = double(abs(app.viewing_img));
                    app.ImageType = 0; % gray image
                    app.viewing_img_max = max(app.viewing_img(:));
                    app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                    app.colorbar_fig = app.UIAxes_colorbar;                                       
                    app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                    %app.viewing_img2 = app.viewing_img;
                    Update_orthoViewer(app,app.viewing_img);
                    if app.ImageType == 0
                        colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]);  
                        app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*app.viewing_img_max];  
                        app.ContrastSlider.Value = app.viewing_img_max;
                    end 
                    app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) app.viewing_img_max];
%                 catch
%                     app.StatusTextArea.Value = 'something went wrong, please check log file.';  
%                     pause(.1);                     
%                 end          
        end

        % Value changed function: UseRatioCheckBox
        function UseRatioCheckBoxValueChanged(app, event)
            if app.UseRatioCheckBox.Value == 1
                app.XePixel1EditField.Enable = 'off'; 
                app.XePixel2EditField.Enable = 'off'; 
                app.XeSliceThicnessEditField.Enable = 'off'; 
                app.HPixel1EditField.Enable = 'off'; 
                app.HPixel2EditField.Enable = 'off'; 
                app.HSliceThicnessEditField.Enable = 'off'; 
            elseif app.UseRatioCheckBox.Value == 0
                app.XePixel1EditField.Enable = 'on'; 
                app.XePixel2EditField.Enable = 'on'; 
                app.XeSliceThicnessEditField.Enable = 'on'; 
                app.HPixel1EditField.Enable = 'on'; 
                app.HPixel2EditField.Enable = 'on'; 
                app.HSliceThicnessEditField.Enable = 'on';            
            end            
        end

        % Button pushed function: PerformRegistrationButton
        function PerformRegistrationButtonPushed(app, event)
            % update app progress message statement
            app.ReadyLamp.Color = 'red'; 
            app.StatusTextArea.Value='performing registration, please wait...';  
            pause(.1);
            
            % find size of xenon image 
            switch app.AnalysisTypeDropDown.Value
                case 'Ventilation'
                   Xesize = size(app.Ventilation.Image);
                case 'Diffusion'
                   Xesize = size(squeeze(app.Diffusion.Image(:,:,:,1)));
                case 'GasExchange'
                   Xesize = size(app.GasExchange.VentImage);
                   app.PerformAnalysisButton.Enable = 'on';
            end
            app.MainInput.AnalysisType = app.AnalysisTypeDropDown.Value;
            % find the type of registeration
            app.MainInput.RegistrationType = app.TransformTypeButtonGroup.SelectedObject.Text;
            % switch app.TransformTypeButtonGroup.SelectedObject.Text
            %     case 'translation' 
            %         app.MainInput.RegistrationType = 'translation';
            %     case 'rigid'
            %         app.MainInput.RegistrationType = 'rigid'; 
            %     case 'similarity' 
            %         app.MainInput.RegistrationType = 'similarity'; 
            %     case 'affine'
            %         app.MainInput.RegistrationType = 'affine'; 
            % end
            
            % find pixel size 
            if app.UseRatioCheckBox.Value == 1
                Hsize = size(app.Proton.Image);
                sizeRatio = Hsize./Xesize;
                app.MainInput.XeVoxelInfo.PixelSize1 = sizeRatio(1);
                app.MainInput.XeVoxelInfo.PixelSize2 = sizeRatio(2);
                app.MainInput.XeVoxelInfo.SliceThickness = sizeRatio(3);
                app.MainInput.ProtonVoxelInfo.PixelSize1 = 1;
                app.MainInput.ProtonVoxelInfo.PixelSize2 = 1;
                app.MainInput.ProtonVoxelInfo.SliceThickness = 1; 
                app.HSliceThicnessEditField.Enable = 'off'; 
            elseif app.UseRatioCheckBox.Value == 0
                app.MainInput.XeVoxelInfo.PixelSize1 = app.XePixel1EditField.Value;
                app.MainInput.XeVoxelInfo.PixelSize2 = app.XePixel2EditField.Value;
                app.MainInput.XeVoxelInfo.SliceThickness = app.XeSliceThicnessEditField.Value;
                app.MainInput.ProtonVoxelInfo.PixelSize1 = app.HPixel1EditField.Value;
                app.MainInput.ProtonVoxelInfo.PixelSize2 = app.HPixel2EditField.Value;
                app.MainInput.ProtonVoxelInfo.SliceThickness = app.HSliceThicnessEditField.Value; 
            
            end 
            
            if app.SliceSelectionCheckBox.Value == 1
                app.MainInput.SliceSelection = 1;
                app.MainInput.Xestart = app.XestartEditField.Value;
                app.MainInput.Xeend = app.XeendEditField.Value;
                app.MainInput.Hstart = app.HstartEditField.Value;
                app.MainInput.Hend = app.HendEditField.Value;
            else
                app.MainInput.SliceSelection = 0;
            end
            % perform registeration 
            [app.Proton,app.GasExchange,app.MainInput] = Registration.PerformRegistration(app.Proton,app.Ventilation,app.GasExchange,app.MainInput);
            app.ViewImagesPanel_2.Enable = 'on';  
            if app.NoProtonImagesCheckBox.Value == 0
               app.ImagestoviewRegistrationDropDown.Items = {'Proton & Xe Registered','Proton Registered'};
            end
            
            % View images
            app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
            app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
            app.ChLineWidthSpinner.Value = 1;
            app.ScaleXSpinner.Value = 3;
            app.ScaleYSpinner.Value = 3;
            app.ScaleZSpinner.Value = 1;
            app.ColorMapDropDown.Value = 'gray';
            app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
            app.D1Spinner.Value = 1;
            app.D2Spinner.Value = 2;                   
            clear app.vieweing_img; app.viewing_img = zeros(10,10,10);
                              
            app.viewing_img = app.Proton.ProtonRegisteredColored; 
            app.ImageType = 1; % 0 for gray image 1 for colored 
            app.viewing_img_max = max(app.viewing_img(:));
            app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
            app.colorbar_fig = app.UIAxes_colorbar;                                       
            app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
            app.viewing_img2 = app.viewing_img;
            
            % view images 
            Update_orthoViewer(app,app.viewing_img);     
            % if app.NoProtonImagesCheckBox.Value == 0 
            %     app.LoadPerformSegmentationButton.Enable = 'off';
            % else 
            %     app.LoadPerformSegmentationButton.Enable = 'on';             
            % end
            % update segmentation options
            if app.NoProtonImagesCheckBox.Value == 0
                app.SegmentationMethodDropDown.Enable = 'on';
                SegmentationMethodDropDownValueChanged(app, event);
                app.LoadPerformSegmentationButton.Enable = 'on';  
                app.ImagestosegmentDropDown.Items = {'Xe & Proton Registered', 'Xenon', 'Registered Proton'};   
                if (strcmp(app.MainInput.Institute,'CCHMC') == 1 || strcmp(app.MainInput.Institute,'XeCTC') == 1)&& strcmp(app.MainInput.SequenceType, '3D Radial') == 1  &&...
                   strcmp(app.MainInput.XeDataext,'.data') == 1  && strcmp(app.MainInput.Scanner, 'Philips') == 1         
                    app.ImagestosegmentDropDown.Items = {'Xe & Proton Registered', 'Xenon', 'Registered Proton', 'Lung Mask'};               
                end
            end
                        
            % update app progress message statement
            app.StatusTextArea.Value = 'Registration completed.';  pause(.1);            
            app.StatusTextArea.Value = 'Viewing proton and xenon registered images...';  pause(.1); 
            app.ImagesbeingviewedEditField.Value = 'Xe & Proton Registered'; pause(.1);  
            app.ReadyLamp.Color = 'green';            
                        
        end

        % Value changed function: ImagestoviewRegistrationDropDown
        function ImagestoviewRegistrationDropDownValueChanged(app, event)
%                 try
                app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                app.ChLineWidthSpinner.Value = 1;
                app.ScaleXSpinner.Value = 3;
                app.ScaleYSpinner.Value = 3;
                app.ScaleZSpinner.Value = 1;
                app.ColorMapDropDown.Value = 'gray';
                app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                app.D1Spinner.Value = 1;
                app.D2Spinner.Value = 2;                 
                clear app.vieweing_img; app.viewing_img = zeros(10,10,10);                                  
                
                if strcmp(app.ImagestoviewRegistrationDropDown.Value, 'Proton & Xe Registered') == 1 
                   app.viewing_img = app.Proton.ProtonRegisteredColored;   
                   app.StatusTextArea.Value = 'Viewing Proton & Xe Registered Images...';  
                   app.ImagesbeingviewedEditField.Value = 'Proton & Xe Registered'; pause(.1); 
                    app.ImageType = 1; % colored image                   
                elseif strcmp(app.ImagestoviewRegistrationDropDown.Value, 'Proton Registered') == 1 
                    app.viewing_img = double(app.Proton.ProtonRegistered); 
                    app.StatusTextArea.Value = 'Viewing Proton Registered Images...';  pause(.1); 
                    app.ImagesbeingviewedEditField.Value = 'Proton Registered'; pause(.1);
                    app.ImageType = 0; % gray image  
                else
                    app.StatusTextArea.Value = [app.ImagestoviewRegistrationDropDown.Value,' images do not exist'];  pause(.1); 
                end

                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) app.viewing_img_max];
                Update_orthoViewer(app,app.viewing_img);
                if app.ImageType == 0
                    colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]);  
                    app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*max(app.viewing_img(:))];  
                    app.ContrastSlider.Value = app.viewing_img_max;
                end 
%                 catch
%                     app.StatusTextArea.Value = 'something went wrong, please check log file.';  
%                     pause(.1);                     
%                 end            
        end

        % Button pushed function: LoadPerformSegmentationButton
        function LoadPerformSegmentationButtonPushed(app, event)
                if strcmp(app.SegmentationMethodDropDown.Value, 'Load Existing Mask') == 1
                    % Location of Mask data
                    try
                        app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Selecting mask location...';  pause(.1); 
                        [filename, path] = uigetfile('*.*','Select mask file');
                        MaskFullPath = [path,filename];
                        MaskDataLocation = path(1:end-1);
                        [~,~,ext] = fileparts(MaskFullPath);
                        
                        if strcmp(ext, '.mat') == 1  || strcmp(ext, '.nii') == 1  || strcmp(ext, '.gz') == 1
                            app.MainInput.MaskFullPath = MaskFullPath;
                            app.MainInput.MaskDataLocation = MaskDataLocation;
                            app.MainInput.MaskFileName = filename;
                            app.MainInput.MaskDataext = ext;
        
                            if strcmp(ext, '.mat') == 1
                                labels = load(MaskFullPath);
                                Mask = double(labels);
                                mask_existing = 1;
                            elseif strcmp(ext, '.nii') == 1  || strcmp(ext, '.gz') == 1 
                                A1 = LoadData.load_nii(MaskFullPath); % Original
                                A = double(A1.img);
                                A = double(squeeze(A));
                                A = imrotate(A,90);
                                A = flip(A,2); % Original                   
                                Mask = A; 
                                mask_existing = 1;
                            end
        
                            if mask_existing == 1
                                % assign lung and airway mask
                                Mask(isinf(Mask)|isnan(Mask)) = 0;
                                lungmask = zeros(size(Mask));
                                lungmask(Mask == 1) = 1;
                                airwaymask = zeros(size(Mask));
                                airwaymask(Mask == 2)= 1;  
                                
                                % store mask
                                switch app.AnalysisTypeDropDown.Value
                                    case 'Ventilation'
                                       app.Ventilation.Mask = Mask;
                                       app.Ventilation.LungMask = lungmask;
                                       app.Ventilation.AirwayMask = airwaymask;                       
                                    case 'Diffusion'
                                       app.Diffusion.Mask = Mask;
                                       app.Diffusion.LungMask = lungmask;
                                       app.Diffusion.AirwayMask = airwaymask;
                                    case 'GasExchange'
                                       app.GasExchange.Mask = Mask;
                                       app.GasExchange.LungMask = lungmask;
                                       app.GasExchange.AirwayMask = airwaymask;   
                                       app.GasExchange.ProtonMaskRegistred = lungmask;
                                       app.Proton.ProtonMaskRegistred = lungmask;
                                end    
                                
                                % Save images and maskes
                                mkdir([app.MainInput.XeDataLocation, '\Mask']);
                                % Mask 
                                niftiwrite(abs(fliplr(rot90(Mask,-1))),[app.MainInput.XeDataLocation,'\Mask\Mask.nii'],'Compressed',true);
                                info = niftiinfo([app.MainInput.XeDataLocation,'\Mask\Mask.nii.gz']);
                                info.Description = strcat('Package Version: ', 'Version1');
                                niftiwrite(abs(fliplr(rot90(Mask,-1))),[app.MainInput.XeDataLocation,'\Mask.nii'],info,'Compressed',true);
                                % lung mask
                                niftiwrite(abs(fliplr(rot90(lungmask,-1))),[app.MainInput.XeDataLocation,'\Mask\LungMask.nii'],'Compressed',true);
                                info = niftiinfo([app.MainInput.XeDataLocation,'\Mask\LungMask.nii.gz']);
                                info.Description = strcat('Package Version: ', 'Version1');
                                niftiwrite(abs(fliplr(rot90(lungmask,-1))),[app.MainInput.XeDataLocation,'\Mask\LungMask.nii'],info,'Compressed',true);
                                % airway mask
                                niftiwrite(abs(fliplr(rot90(airwaymask,-1))),[app.MainInput.XeDataLocation,'\Mask\AirwayMask.nii'],'Compressed',true);
                                info = niftiinfo([app.MainInput.XeDataLocation,'\Mask\AirwayMask.nii.gz']);
                                info.Description = strcat('Package Version: ', 'Version1');
                                niftiwrite(abs(fliplr(rot90(airwaymask,-1))),[app.MainInput.XeDataLocation,'\Mask\AirwayMask.nii'],info,'Compressed',true);
                                                                        
                                % update app progress message statement
                                app.StatusTextArea.Value = 'Lung Mask Segmentation completed.';  pause(.1);                        
                                
                                % view images 
                                MasktoviewDropDownValueChanged(app, event);
                                %Update_orthoViewer(app, lungmask);  
                                
                                % enable viewing mask options
                                app.ViewMaskPanel.Enable = 'on'; 
                                app.PerformAnalysisButton.Enable = 'on';
                                
                                % update app progress message statement           
                                app.StatusTextArea.Value = 'Viewing Lung Mask...';  pause(.1); 
                                app.ImagesbeingviewedEditField.Value = 'Lung Mask'; pause(.1);  
                                app.ReadyLamp.Color = 'green'; 
                                app.ViewMaskPanel.Enable = 'on'; 
                                app.EditMaskButton.Enable = 'on';
                                %LoadSegmentedMaskButtonPushed(app, Mask)
                                %MasktoviewDropDownValueChanged(app, event);
                                app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Mask has been loaded.';  pause(.1);            
                              
                            else
                                % update app progress message statement           
                                app.StatusTextArea.Value = 'Lung Mask does not exist...';  pause(.1); 
                                app.ReadyLamp.Color = 'green';                                                         
                            end
                        else
                            app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Mask data has to be .mat or .nii.';  pause(.1);            
                        end
                    catch
                            app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Mask file (.mat or .nii) has not been selected.';  pause(.1);            
                    end
                else % perform segmentation
                    % update app progress message statement           
                    app.StatusTextArea.Value = 'perform segmentation...please wait';  pause(.1); 
                    app.ReadyLamp.Color = 'red'; 
                    app.MainInput.NoProtonImage = app.NoProtonImagesCheckBox.Value;
                    cd(app.MainInput.XeDataLocation)
                    app.MainInput.SegmentationMethod = app.SegmentationMethodDropDown.Value; % 'Threshold' || 'Manual' || 'Auto'                
                    app.MainInput.Imagestosegment = app.ImagestosegmentDropDown.Value;  % 'Xe & Proton Registered' | 'Xenon' | 'Registered Proton'     

                    app.MainInput.SegmentManual = 'Freehand' ; %'AppSegmenter' || 'Freehand'
                    app.MainInput.SegmentAnatomy = 'Parenchyma';
                    switch app.SegmentationMethodDropDown.Value
                        case 'Manual'
                              switch app.SelectAnatomyButtonGroup_maunal.SelectedObject.Text
                                  case 'Parenchyma'
                                        app.MainInput.SegmentAnatomy = 'Parenchyma';
                                  case 'Airway'
                                        app.MainInput.SegmentAnatomy = 'Airway';
                              end   
                              switch app.MethodButtonGroup_maunal.SelectedObject.Text
                                  case 'Freehand'
                                        app.MainInput.SegmentManual = 'Freehand' ; %'AppSegmenter' || 'Freehand'
                                  case 'AppSegmenter'
                                        app.MainInput.SegmentManual = 'AppSegmenter' ; %'AppSegmenter' || 'Freehand'
                              end                               
                        case 'Threshold'
                              switch app.SelectAnatomyButtonGroup_threshold.SelectedObject.Text
                                  case 'Parenchyma'
                                        app.MainInput.SegmentAnatomy = 'Parenchyma';
                                  case 'Airway'
                                        app.MainInput.SegmentAnatomy = 'Airway';
                              end
                        case 'Auto'
    
                    end 
    
                    app. MainInput.thresholdlevel = app.ThresholdSpinner.Value; % 'threshold' 
                    app.MainInput.SE = app.ErosionSpinner.Value; % 'threshold' 
                    
                    app.MainInput.SliceOrientation = app.SliceOrientationButtonGroup.SelectedObject.Text; % 'transversal' || 'isotropic'
                    
                    [app.Proton,app.Ventilation,app.Diffusion,app.GasExchange] = Segmentation.PerformSegmentation(app.Proton,app.Ventilation,app.Diffusion,app.GasExchange,app.MainInput);
                    switch app.AnalysisTypeDropDown.Value
                        case 'Ventilation'  
                            if ~exist('app.Ventilation.AirwayMask','var')
                                app.Ventilation.AirwayMask = zeros(size(app.Ventilation.LungMask));
                            end
                        case 'Diffusion'
                            if ~exist('app.Diffusion.AirwayMask','var')
                                app.Diffusion.AirwayMask = zeros(size(app.Diffusion.LungMask));
                            end
                        case 'GasExchange'
                            if ~exist('app.GasExchange.AirwayMask','var')
                                app.GasExchange.AirwayMask = zeros(size(app.GasExchange.LungMask));
                            end
                    end    
                    cd(app.MainInput.XeDataLocation)
                    switch app.SegmentationMethodDropDown.Value
                        case 'Manual'
                        case 'Threshold'
                            MasktoviewDropDownValueChanged(app);                    
                        case 'Auto'
                            %LoadSegmentedMaskButtonPushed(app)
                            MasktoviewDropDownValueChanged(app); 
                    end 
    
                    % enable read mask button                
                    app.ViewMaskPanel.Enable = 'on';
                    app.PerformAnalysisButton.Enable = 'on';
                    switch app.SegmentationMethodDropDown.Value
                        case 'Manual'
                            app.LoadSegmentedMaskButton.Enable = 'on';
                            app.EditMaskButton.Enable = 'on';
                        case 'Threshold'
                            app.LoadSegmentedMaskButton.Enable = 'off';  
                            app.EditMaskButton.Enable = 'on';
                        case 'Auto'
                            app.LoadSegmentedMaskButton.Enable = 'off';
                            app.EditMaskButton.Enable = 'on';
                        case 'Load Existing Mask'
                    end 
    
                    %update status text
                    app.StatusTextArea.Value = [app.AnalysisTypeDropDown.Value,' Mask segmentation completed, please load mask to proceed'];  pause(.1); 
                    app.ReadyLamp.Color = 'green';
    
                   switch app.SliceOrientationButtonGroup.SelectedObject.Text
                        case 'coronal' 
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'  
                                case 'Diffusion'
                                    app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1); 
                                case 'GasExchange'
                            end
                        case 'transversal'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'  
                                case 'Diffusion'
                                    app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1); 
                                case 'GasExchange'
                            end
                        case 'sagittal'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'  
                                    app.StatusTextArea.Value = 'Auto segmentation for Ventilation images with transversal orientation is not supported yet';  pause(.1); 
                                case 'Diffusion'
                                    app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1); 
                                case 'GasExchange'
                            end
                        case 'isotropic'
                            switch app.AnalysisTypeDropDown.Value
                                case 'Ventilation'  
                                   app.StatusTextArea.Value = 'isotropic Auto segmentation for Ventilation analysis is not supported yet';  pause(.1); 
                                case 'Diffusion'
                                    app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1);                                 
                                case 'GasExchange'
                            end
                    end 
                end
                try 
                    switch app.AnalysisTypeDropDown.Value
                        case 'Ventilation'  
                            if sum(app.Ventilation.AirwayMask) == 0
                                app.Ventilation.AirwayMask(end,end,end) = 1;
                            end
                        case 'Diffusion'
                            if sum(app.Diffusion.AirwayMask) == 0
                                app.Diffusion.AirwayMask(end,end,end) = 1;
                            end
                        case 'GasExchange'
                            if sum(app.GasExchange.AirwayMask) == 0
                                app.GasExchange.AirwayMask(end,end,end) = 1;
                            end
                    end
                catch
                    % skip 
                end
        end

        % Button pushed function: LoadSegmentedMaskButton
        function LoadSegmentedMaskButtonPushed(app, event)
                app.MainInput = app_inputs(app, app.MainInput);
                % update app progress message statement           
                app.StatusTextArea.Value = 'Load Segmentated Mask... please wait';  pause(.1); 
                app.ReadyLamp.Color = 'red'; 
                cd(app.MainInput.XeDataLocation);
                [app.Proton,app.Ventilation,app.Diffusion, app.GasExchange] = Segmentation.loadSegmentedMask(app.Proton,app.Ventilation,app.Diffusion,app.GasExchange,app.MainInput);
                % update app progress message statement
                app.StatusTextArea.Value = 'Load Segmentated Mask completed.';  pause(.1);                            

                MasktoviewDropDownValueChanged(app, event);
                % enable viewing mask options
                app.ViewMaskPanel.Enable = 'on'; 
                app.EditMaskButton.Enable = 'on'; 
                % update app progress message statement           
                app.StatusTextArea.Value = 'Viewing Lung Mask...';  pause(.1); 
                app.ImagesbeingviewedEditField.Value = 'Lung Mask'; pause(.1);  
                app.ReadyLamp.Color = 'green'; 
        end

        % Value changed function: MasktoviewDropDown
        function MasktoviewDropDownValueChanged(app, event)

                app.CrosshairEnableSwitch.Value = app.CrosshairEnableSwitch.Items(1);
                app.ChColorDropDown.Value  = app.ChColorDropDown.Items(1);
                app.ChLineWidthSpinner.Value = 1;
                app.ScaleXSpinner.Value = 3;
                app.ScaleYSpinner.Value = 3;
                app.ScaleZSpinner.Value = 1;
                app.ColorMapDropDown.Value = 'gray';
                app.RotateFlipdDropDown.Value = app.RotateFlipdDropDown.Items(1);
                app.D1Spinner.Value = 1;
                app.D2Spinner.Value = 2;                  
                clear app.vieweing_img; app.viewing_img = zeros(10,10,10);

                % choose mask to view
                switch app.MasktoviewDropDown.Value
                    case 'Lung Mask'
                        app.ImagesbeingviewedEditField.Value = 'Lung Mask'; pause(.1);
                        app.StatusTextArea.Value = 'Viewing Lung Mask...';  pause(.1);
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'
                               app.viewing_img = app.Ventilation.LungMask;                      
                            case 'Diffusion'
                               app.viewing_img = app.Diffusion.LungMask;
                            case 'GasExchange'
                               app.viewing_img = app.GasExchange.LungMask;                          
                        end                         
                    case 'Airway Mask'
                        app.ImagesbeingviewedEditField.Value = 'Airway Mask'; pause(.1);
                        app.StatusTextArea.Value = 'Airway Mask...';  pause(.1);
                        
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'
                                try
                                    app.viewing_img = app.Ventilation.AirwayMask;      
                                catch
                                    app.viewing_img = zeros(size(app.Ventilation.LungMask)); 
                                end
                            case 'Diffusion'
                                try
                                    app.viewing_img = app.Diffusion.AirwayMask;      
                                catch
                                    app.viewing_img = zeros(size(app.Diffusion.LungMask)); 
                                end
                            case 'GasExchange'
                                try
                                    app.viewing_img = app.GasExchange.AirwayMask;      
                                catch
                                    app.viewing_img = zeros(size(app.GasExchange.LungMask)); 
                                end                        
                        end 
                    case 'Lung & Airway Mask'
                        app.ImagesbeingviewedEditField.Value = 'Lung & AIrway Mask'; pause(.1);
                        app.StatusTextArea.Value = 'Viewing Lung & Airway Mask...';  pause(.1);
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'
                                try
                                   app.Ventilation.Mask = app.Ventilation.LungMask + app.Ventilation.AirwayMask*2;
                                   app.viewing_img = app.Ventilation.Mask;     
                                catch
                                    app.Ventilation.AirwayMask = zeros(size(app.Ventilation.LungMask));
                                   app.Ventilation.Mask = app.Ventilation.LungMask + app.Ventilation.AirwayMask*2;
                                   app.viewing_img = app.Ventilation.Mask; 
                                end
                            case 'Diffusion'
                                try
                                   app.Diffusion.Mask = app.Diffusion.LungMask + app.Diffusion.AirwayMask*2;
                                   app.viewing_img = app.Diffusion.Mask;
                                catch
                                    app.Diffusion.AirwayMask = zeros(size(app.Diffusion.LungMask));
                                    app.Diffusion.Mask = app.Diffusion.LungMask + app.Diffusion.AirwayMask*2;
                                    app.viewing_img = app.Diffusion.Mask;
                                end
                            case 'GasExchange'
                                try
                                    app.GasExchange.Mask = app.GasExchange.LungMask + app.GasExchange.AirwayMask*2;
                                    app.viewing_img = app.GasExchange.Mask; 
                                catch
                                    app.GasExchange.AirwayMask = zeros(size(app.GasExchange.LungMask));
                                    app.GasExchange.Mask = app.GasExchange.LungMask + app.GasExchange.AirwayMask*2;
                                    app.viewing_img = app.GasExchange.Mask; 
                                end                                
                         
                        end                           
                end                                

                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                if app.ImageType == 0 && max(app.viewing_img(:)) ~= 0
                    colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 max(app.viewing_img(:))]);  
                    app.ContrastSlider.Limits = [min(app.viewing_img(:)) 2*max(app.viewing_img(:))];  
                    app.ContrastSlider.Value = app.viewing_img_max;
                    app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) app.viewing_img_max];
                else
                    colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 1]);  
                    app.ContrastSlider.Limits = [0 2];  
                    app.ContrastSlider.Value = 1;        
                    app.orthoViewer.DisplayRange = [min(app.viewing_img(:)) 1];
                end 
                Update_orthoViewer(app,app.viewing_img);   
                
        end

        % Value changed function: SegmentationMethodDropDown
        function SegmentationMethodDropDownValueChanged(app, event)
                switch app.SegmentationMethodDropDown.Value
                    case 'Manual'
                       app.ManualImageSegmentationPanel.Enable = 'on'; 
                       app.AutoImageSegmentationAIPanel.Enable = 'off';  
                       app.ThresholdImageSegmentationPanel.Enable = 'off'; 
                       if app.NoProtonImagesCheckBox.Value == 1
                            app.ImagestosegmentDropDown.Items = {'Xenon'}; 
                       else
                            app.ImagestosegmentDropDown.Items = {'Proton & Xe Registered','Xenon', 'Proton Registered'}; 
                       end
                    case 'Threshold'
                       app.ManualImageSegmentationPanel.Enable = 'off'; 
                       app.AutoImageSegmentationAIPanel.Enable = 'off';  
                       app.ThresholdImageSegmentationPanel.Enable = 'on'; 
                       app.LoadSegmentedMaskButton.Enable = 'off';  
                       app.ImagestosegmentDropDown.Items = {'Xenon'};  
                    case 'Auto'
                       app.ManualImageSegmentationPanel.Enable = 'off'; 
                       app.AutoImageSegmentationAIPanel.Enable = 'on';  
                       app.ThresholdImageSegmentationPanel.Enable = 'off'; 
                       if app.NoProtonImagesCheckBox.Value == 1
                            app.ImagestosegmentDropDown.Items = {'Xenon'}; 
                       else
                            app.ImagestosegmentDropDown.Items = {'Proton & Xe Registered','Xenon', 'Proton Registered'}; 
                       end
                    case 'Load Existing Mask' 
                       app.ManualImageSegmentationPanel.Enable = 'off'; 
                       app.AutoImageSegmentationAIPanel.Enable = 'off';  
                       %app.LoadSegmentedMaskButton.Enable = 'on';  
                       app.LoadSegmentedMaskButton.Enable = 'off'; 
                       app.ThresholdImageSegmentationPanel.Enable = 'off';  
                end 
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            % update app progress message statement
            app.ReadyLamp.Color = 'red'; 
            app.MainInput.SubjectAge = app.PatientAgeyearsEditField.Value;            

            switch app.AnalysisTypeDropDown.Value
                case 'Ventilation'
                    app.StatusTextArea.Value='performing ventilation analysis, please wait...';  
                    pause(.1);                  
                    if app.BiasFieldCorrectionCheckBox.Value == 0
                        app.Ventilation.N4Analysis = 0;
                    else
                        app.Ventilation.N4Analysis = 1;
                    end
                    
                    app.Ventilation.IncompleteThresh = app.IncompleteEditField.Value;
                    app.Ventilation.CompleteThresh = app.DefectEditField.Value;
                    app.Ventilation.HyperventilatedThresh = app.HyperEditField.Value;
                    
                    if app.ThresholdVDPCheckBox.Value == 0
                        app.Ventilation.ThreshAnalysis = 'no'; 
                    else
                        app.Ventilation.ThreshAnalysis = 'yes'; 
                    end
                    
                    if app.LinearBinningMethodCheckBox_vent.Value == 0
                        app.Ventilation.LB_Analysis = 'no'; 
                    else
                        app.Ventilation.LB_Analysis = 'yes'; 
                    end          

                    if app.kmeansCheckBox.Value == 0
                        app.Ventilation.Kmeans = 'no'; 
                    else
                        app.Ventilation.Kmeans = 'yes'; 
                    end  
                    if app.DDI2DCheckBox.Value == 0
                        app.Ventilation.DDI2D = 'no'; 
                    else
                        app.Ventilation.DDI2D = 'yes'; 
                        app.Ventilation.DDIDefectMap = app.DefectMapDropDown.Value;
                    end 
                    if app.DDI3DCheckBox.Value == 0
                        app.Ventilation.DDI3D = 'no'; 
                    else
                        app.Ventilation.DDI3D = 'yes'; 
                        app.Ventilation.DDIDefectMap = app.DefectMapDropDown.Value;
                        app.Ventilation.DDI3Dx = app.DDI_X.Value;
                        app.Ventilation.DDI3Dy = app.DDI_Y.Value;
                        app.Ventilation.DDI3Dz = app.DDI_Z.Value;
                    end 
                    if app.TextureAnalysisCheckBox.Value == 0
                        app. Ventilation.GLRLM_Analysis = 'no'; 
                    else
                        app. Ventilation.GLRLM_Analysis = 'yes'; 
                    end                      
                    
                    if app.Ventilation.N4Analysis == 1
                        app.Ventilation.LB_RefMean = app.MeanN4EditField.Value; 
                        app.Ventilation.LB_RefSD =  app.SDN4EditField.Value; 
                    elseif app.Ventilation.N4Analysis == 0
                        app.Ventilation.LB_RefMean = app.MeanEditField.Value; 
                        app.Ventilation.LB_RefSD = app.SDEditField.Value;
                    end
                    % ----------- Ventilation Analysis -----------------------------
                    if app.ThresholdVDPCheckBox.Value == 1 || app.LinearBinningMethodCheckBox_vent.Value == 1 || app.kmeansCheckBox.Value == 1 || app.TextureAnalysisCheckBox.Value == 1
                        [app.Ventilation] = VentilationFunctions.Ventilation_Analysis(app.Ventilation, app.Proton, app.MainInput);
                    end

                    if app.ThresholdVDPCheckBox.Value == 1
                        app.VDPTextArea.Value = [num2str(app.Ventilation.VDP), '%'];
                        app.NormalTextArea.Value = app.Ventilation.legend1;
                        app.IncompleteTextArea.Value = app.Ventilation.legend2;
                        app.CompleteTextArea.Value = app.Ventilation.legend3;
                        app.HyperTextArea.Value = app.Ventilation.legend4;
                        app.ThresholdMethodResultsPanel.Enable = 'on';  
                    else
                        app.ThresholdMethodResultsPanel.Enable = 'off'; 
                    end         
                    
                    if app.LinearBinningMethodCheckBox_vent.Value == 1 
                        app.LBVDPTextArea.Value = [num2str(app.Ventilation.LB_VDP), '%'];
                        app.MeanTextArea.Value = [num2str(app.Ventilation.LB_mean), '±' ,num2str(app.Ventilation.LB_std)];
                        app.Bin1TextArea.Value = num2str(app.Ventilation.VentLow1Percent);
                        app.Bin2TextArea.Value = num2str(app.Ventilation.VentLow2Percent);
                        app.Bin3TextArea.Value = num2str(app.Ventilation.VentNormal1Percent);
                        app.Bin4TextArea.Value = num2str(app.Ventilation.VentNormal2Percent);
                        app.Bin5TextArea.Value = num2str(app.Ventilation.VentHigh1Percent);
                        app.Bin6TextArea.Value = num2str(app.Ventilation.VentHigh2Percent);
                        app.LinearBinningMethodResultsPanel.Enable = 'on'; 
                    else
                        app.LinearBinningMethodResultsPanel.Enable = 'off'; 
                    end                      
                           
                    if app.kmeansCheckBox.Value == 1 
                        app.kmeansVDPTextArea.Value = [num2str(app.Ventilation.KmeansVDP), '%'];
                        app.KmeansResultsPanel.Enable = 'on'; 
                    else
                        app.KmeansResultsPanel.Enable = 'off'; 
                    end  

                    if app.DDI2DCheckBox.Value == 1 || app.DDI3DCheckBox.Value == 1 
                        app.DDIResultsPanel.Enable = 'on'; 
                    else
                        app.DDIResultsPanel.Enable = 'off'; 
                    end

                    if app.DDI2DCheckBox.Value == 1 
                        app.meanSD_2DDDITextArea.Value = [num2str(round(app.Ventilation.DDI2D_mean,2)),'±', num2str(round(app.Ventilation.DDI2D_std,2))];
                        app.meanSD_2DDDITextArea.Enable = 'on';
                        app.DDI2DMapButton.Enable = 'on';
                    else
                        app.meanSD_2DDDITextArea.Enable = 'off';
                        app.DDI2DMapButton.Enable = 'off';
                    end  

                    if app.DDI3DCheckBox.Value == 1 
                        app.meanSD_3DDDITextArea.Value = [num2str(round(app.Ventilation.DDI3D_mean,2)),'±', num2str(round(app.Ventilation.DDI3D_std,2))];
                        app.meanSD_3DDDITextArea.Enable = 'on';
                        app.DDI3DMapButton.Enable = 'on';
                    else
                        app.meanSD_3DDDITextArea.Enable = 'off';
                        app.DDI3DMapButton.Enable = 'off';
                    end           

                    if app.TextureAnalysisCheckBox.Value == 1
                        app.SRETextArea.Value = num2str(app.Ventilation.SRE);
                        app.LRETextArea.Value = num2str(app.Ventilation.LRE);
                        app.GLNTextArea.Value = num2str(app.Ventilation.GLN);
                        app.RLNTextArea.Value = num2str(app.Ventilation.RLN);
                        app.RPTextArea.Value = num2str(app.Ventilation.RP);
                        app.LGRETextArea.Value = num2str(app.Ventilation.LGRE);
                        app.HGRETextArea.Value = num2str(app.Ventilation.HGRE);
                        app.SRLGRTextArea.Value = num2str(app.Ventilation.SRLGR);
                        app.SRHGETextArea.Value = num2str(app.Ventilation.SRHGE);
                        app.LRLGETextArea.Value = num2str(app.Ventilation.LRLGE);
                        app.LRHGETextArea.Value = num2str(app.Ventilation.LRHGE);
                        app.TextureAnalysisGLRLMResultsPanel.Enable = 'on';
                    else
                         app.TextureAnalysisGLRLMResultsPanel.Enable = 'off';
                    end          
                    Global.tts('Ventilation Analysis completed');
                    app.VentilationAnalysisPerformed= 1;
                % ----------- Diffusion Analysis  -----------------------------
                case 'Diffusion'
                    app.StatusTextArea.Value='performing diffusion analysis, please wait...';  
                    pause(.1);                     
                    
                    app.MainInput.PatientAge = app.PatientAgeyearsEditField.Value;
                    app.Diffusion.ADCFittingType = app.FittingTypeDropDown.Value; % 'Log Weighted Linear' | 'Log Linear' | 'Non-Linear' | 'Bayesian'
                    app.Diffusion.MorphometryAnalysisType = app.StudyTypeDropDown.Value; % human | animals;  % human | animals
                    app.Diffusion.WinBUGSPath = 'F:\WinBUGS14';  
                    app.Diffusion.bvalues = app.bvaluesscm2EditField.Value;
                    
                    if app.LinearBinningMethoddiffCheckBox_diff.Value == 0
                        app.Diffusion.ADCLB_Analysis = 'no';
                    else
                        app.Diffusion.ADCLB_Analysis = 'yes';
                        app.Diffusion.ADCLB_RefMean = app.MeanequationEditField.Value; 
                        app.Diffusion.ADCLB_RefSD = app.SDequationEditField.Value;                         
                    end                      
                    
                    if app.MorphometryAnalysisCheckBox.Value == 0
                        app.Diffusion.MorphometryAnalysis = 'no'; 
                    else
                        app.Diffusion.MorphometryAnalysis = 'yes'; 
                        app.Diffusion.MorphometryAnalysisType = app.StudyTypeDropDown.Value; % human | animals
                        app.Diffusion.MA_WinBUGSPath = 'F:\WinBUGS14';

                        if app.CylindricalModelCMCheckBox.Value == 0
                            app.Diffusion.CMMorphometry = 'no';
                        else
                            app.Diffusion.CMMorphometry = 'yes';                         
                        end 

                        if app.StretchExponentialModelSEMCheckBox.Value == 0
                            app.Diffusion.SEMMorphometry = 'no';
                        else
                            app.Diffusion.SEMMorphometry = 'yes';                        
                        end                         
                        
                        app.Diffusion.Do = app.Docm2sEditField.Value; % cm2/s
                        app.Diffusion.Delta = app.DeltamsEditField.Value; % ms                        
                    end                     
                
                    [app.Diffusion] = DiffusionFunctions.Diffusion_Analysis(app.Diffusion,app.MainInput);                                        
                    app.MeanADCcm2sTextArea.Value = [num2str(app.Diffusion.meanADC), '±' ,num2str(app.Diffusion.stdADC)];
                    app.ADCResultsPanel.Enable = 'on'; 
                    
                    if app.LinearBinningMethoddiffCheckBox_diff.Value == 1
                        app.Bin1TextAreaLBADC.Value = num2str(app.Diffusion.DiffLow1Percent);
                        app.Bin2TextAreaLBADC.Value = num2str(app.Diffusion.DiffLow2Percent);
                        app.Bin3TextAreaLBADC.Value = num2str(app.Diffusion.DiffNormal1Percent);
                        app.Bin4TextAreaLBADC.Value = num2str(app.Diffusion.DiffNormal2Percent);
                        app.Bin5TextAreaLBADC.Value = num2str(app.Diffusion.DiffHigh1Percent);
                        app.Bin6TextAreaLBADC.Value = num2str(app.Diffusion.DiffHigh2Percent);
                        app.ADCLinearBinningResultsPanel.Enable = 'on';
                    else
                        app.ADCLinearBinningResultsPanel.Enable = 'off';
                    end  


                    if app.MorphometryAnalysisCheckBox.Value == 1
                        app.MorphometryAnalysisResultPanel.Enable = 'on'; 
                        if app.CylindricalModelCMCheckBox.Value == 1
                             app.CylindricalModelCMResultsPanel.Enable = 'on';
                             app.CMParametersDropDown.Value = 'R: External Acinar duct radius';
                             app.MeanTextAreaCM.Value = [num2str(app.Diffusion.R_mean),'±',num2str(app.Diffusion.R_std)];
                              CMParametersDropDownValueChanged(app);
%                             if strcmp(app.CMParametersDropDown.Value,'DT: Transverse diffusion coefficient') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.DT_mean),'±',num2str(app.Diffusion.DT_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'DL: Longitudinal diffusion coefficient') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.DL_mean),'±',num2str(app.Diffusion.DL_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'Dan: Anisotropic diffusion coefficient') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Dan_mean),'±',num2str(app.Diffusion.Dan_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'Dmean: Mean diffusion coefficient') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Dmean_mean),'±',num2str(app.Diffusion.Dmean_std)];
%                             if strcmp(app.CMParametersDropDown.Value,'R: External Acinar duct radius') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.R_mean),'±',num2str(app.Diffusion.R_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'r: Internal Acinar duct radius') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.r_mean),'±',num2str(app.Diffusion.r_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'h: Alveolar sleeve depth') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.h_mean),'±',num2str(app.Diffusion.h_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'Lm: Mean linear intercept') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Lm_mean),'±',num2str(app.Diffusion.Lm_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'SVR: Surface to volume ratio') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.SVR_mean),'±',num2str(app.Diffusion.SVR_std)];
%                             elseif strcmp(app.CMParametersDropDown.Value,'Na: Alveolar density') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Na_mean),'±',num2str(app.Diffusion.Na_std)];
%                             end
                        else
                            app.CylindricalModelCMResultsPanel.Enable = 'off';
                        end
                        
                        if app.StretchExponentialModelSEMCheckBox.Value == 1
                            app.StretchExponentialModelSEMResultsPanel.Enable = 'on';

                            app.SEMParametersDropDown.Value = 'LmD: Mean diffusive length scale';
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.LmD_mean),'±',num2str(app.Diffusion.LmD_std)];                            
                            SEMParametersDropDownValueChanged(app);
%                             if strcmp(app.SEMParametersDropDown.Value,'Alpha: Heterogeneity index') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.alpha_mean),'±',num2str(app.Diffusion.alpha_std)];
%                             elseif strcmp(app.SEMParametersDropDown.Value,'DCC: Distributed diffusivity coefficient ') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.DDC_mean),'±',num2str(app.Diffusion.DDC_std)];
%                             elseif strcmp(app.SEMParametersDropDown.Value,'LmD: Mean diffusive length scale') == 1 
%                                 app.MeanTextAreaCM.Value = [num2str(app.Diffusion.LmD_mean),'±',num2str(app.Diffusion.LmD_std)];
%                             end
                        else
                            app.StretchExponentialModelSEMResultsPanel.Enable = 'off';
                        end    
                    else
                        app.MorphometryAnalysisResultPanel.Enable = 'off'; 
                    end 
                    app.DiffusionAnalysisPerformed = 1;
                    Global.tts('Diffusion Analysis completed');
                % ----------- Gas Exchange Analysis  -----------------------------
                case 'GasExchange'
                    app.StatusTextArea.Value='performing gas exchange analysis, please wait...';  
                    pause(.1);                     
                    
                    app.GasExchange.VentHealthyMean = app.VentMeanEditField.Value; 
                    app.GasExchange.VentHealthyStd = app.VentSDEditField.Value; 
                    app.GasExchange.DissolvedHealthyMean = app.DissolvedMeanEditField.Value;  
                    app.GasExchange.DissolvedHealthyStd = app.DissolvedSDEditField.Value; 
                    app.GasExchange.MembraneHealthyMean = app.MembraneMeanEditField.Value; 
                    app.GasExchange.MembraneHealthyStd = app.MembraneSDEditField.Value; 
                    app.GasExchange.RBCHealthyMean = app.RBCMeanEditField.Value; 
                    app.GasExchange.RBCtHealthyStd = app.RBCSDEditField.Value; 
                    app.GasExchange.RBCBarrHealthyMean = app.RBCBarrMeanEditField.Value; 
                    app.GasExchange.RBCBarrHealthyStd = app.RBCBarrSDEditField.Value; 
                    app.GasExchange.RBCOscHealthyMean = app.RBCOscMeanEditField.Value; 
                    app.GasExchange.RBCOscHealthyStd = app.RBCOscSDEditField.Value; 
                    app.MainInput.HealthyReferenceType = app.HealthyReferenceDropDown.Value;
                                        
                    [app.GasExchange] = GasExchangeFunctions.GasExchange_Analysis(app.GasExchange,app.Proton,app.MainInput);                                        

                    % Vent summary
                    app.MeanTextAreaVent.Value = [num2str(round(app.GasExchange.VentMean,3)), '±' ,num2str(round(app.GasExchange.VentStd,3))];                   
                    app.Bin1TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(1),2));
                    app.Bin2TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(2),2));
                    app.Bin3TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(3),2));
                    app.Bin4TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(4),2));
                    app.Bin5TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(5),2));
                    app.Bin6TextAreaVent.Value = num2str(round(app.GasExchange.VentBinPercents(6),2));
                    
                    % Dissolved summary
                    app.MeanTextAreaDissolved.Value = [num2str(round(app.GasExchange.DissolvedMean,5)), '±' ,num2str(round(app.GasExchange.DissolvedStd,5))];                   
                    app.Bin1TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(1),2));
                    app.Bin2TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(2),2));
                    app.Bin3TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(3),2));
                    app.Bin4TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(4),2));
                    app.Bin5TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(5),2));
                    app.Bin6TextAreaDissolved.Value = num2str(round(app.GasExchange.DissolvedBinPercents(6),2));
                    
                    % Membrane summary
                    app.MeanTextAreaBarr.Value = [num2str(round(app.GasExchange.BarrierUptakeMean,5)), '±' ,num2str(round(app.GasExchange.BarrierUptakeStd,5))];                   
                    app.Bin1TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(1),2));     
                    app.Bin2TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(2),2)); 
                    app.Bin3TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(3),2)); 
                    app.Bin4TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(4),2)); 
                    app.Bin5TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(5),2)); 
                    app.Bin6TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(6),2)); 
                    app.Bin7TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(7),2)); 
                    app.Bin8TextAreaBarr.Value = num2str(round(app.GasExchange.BarrierUptakeBinPercents(8),2)); 
                    
                    % RBC summary
                    app.MeanTextAreaRBC.Value = [num2str(round(app.GasExchange.RBCTransferMean,5)), '±' ,num2str(round(app.GasExchange.RBCTransferStd,5))];                   
                    app.Bin1TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(1),2));   
                    app.Bin2TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(2),2)); 
                    app.Bin3TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(3),2)); 
                    app.Bin4TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(4),2)); 
                    app.Bin5TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(5),2)); 
                    app.Bin6TextAreaRBC.Value = num2str(round(app.GasExchange.RBCTransferBinPercents(6),2)); 
                    
                    % RBC summary
                    app.MeanTextAreaRBCBarr.Value = [num2str(round(app.GasExchange.RBCBarrierMean,5)), '±' ,num2str(round(app.GasExchange.RBCBarrierStd,5))];                   
                    app.Bin1TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(1),2));  
                    app.Bin2TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(2),2)); 
                    app.Bin3TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(3),2)); 
                    app.Bin4TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(4),2)); 
                    app.Bin5TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(5),2)); 
                    app.Bin6TextAreaRBCBarr.Value = num2str(round(app.GasExchange.RBCBarrierBinPercents(6),2)); 
                    
                    % RBC summary
                    app.MeanTextAreaRBCOsc.Value = [num2str(round(app.GasExchange.RBCOscMean,5)), '±' ,num2str(round(app.GasExchange.RBCOscStd,5))];                   
                    app.Bin1TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(1),2));  
                    app.Bin2TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(2),2)); 
                    app.Bin3TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(3),2)); 
                    app.Bin4TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(4),2)); 
                    app.Bin5TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(5),2)); 
                    app.Bin6TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(6),2)); 
                    app.Bin7TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(7),2)); 
                    app.Bin8TextAreaRBCOsc.Value = num2str(round(app.GasExchange.RBCOscBinPercents(8),2)); 
                    
                    % Enable Results summary
                    app.VentilationSummaryPanel.Enable = 'on'; 
                    app.DissolvedSummaryPanel.Enable = 'on'; 
                    app.MembraneSummaryPanel.Enable = 'on'; 
                    app.RBCSummaryPanel.Enable = 'on'; 
                    app.RBCMembraneSummaryPanel.Enable = 'on'; 
                    app.RBCOscillationSummaryPanel.Enable = 'on'; 
                    app.GasExchangeAnalysisPerformed = 1;
                    Global.tts('Gas Exchange Analysis completed');
            end              
            % print out App inputs
            PrintOutAppInputs(app, app.MainInput);
            app.GenerateReportButton.Enable = 'on';
            % update app progress message statement           
            app.StatusTextArea.Value = [app.AnalysisTypeDropDown.Value,' analysis completed'];  pause(.1); 
            app.ReadyLamp.Color = 'green';             

        end

        % Value changed function: ThresholdVDPCheckBox
        function ThresholdVDPCheckBoxValueChanged(app, event)
            if app.ThresholdVDPCheckBox.Value == 1 
                app.ThresholdMethodPanel.Enable = 'on'; 
                %app.ThresholdMethodResultsPanel.Enable = 'on'; 
            else
                app.ThresholdMethodPanel.Enable = 'off';
                %app.ThresholdMethodResultsPanel.Enable = 'off';
            end            
        end

        % Value changed function: LinearBinningMethodCheckBox_vent
        function LinearBinningMethodCheckBox_ventValueChanged(app, event)
            if app.LinearBinningMethodCheckBox_vent.Value == 1 
                app.LinearBinningMethodPanel.Enable = 'on';
                %app.LinearBinningMethodResultsPanel.Enable = 'on'; 
            else
                app.LinearBinningMethodPanel.Enable = 'off';
                %app.LinearBinningMethodResultsPanel.Enable = 'off';
            end             
        end

        % Value changed function: BiasFieldCorrectionCheckBox
        function BiasFieldCorrectionCheckBoxValueChanged(app, event)
            if app.BiasFieldCorrectionCheckBox.Value == 0
                app.MeanEditField.Enable = 'on'; 
                app.SDEditField.Enable = 'on'; 
                app.MeanN4EditField.Enable = 'off'; 
                app.SDN4EditField.Enable = 'off'; 
            else
                app.MeanEditField.Enable = 'off'; 
                app.SDEditField.Enable = 'off'; 
                app.MeanN4EditField.Enable = 'on'; 
                app.SDN4EditField.Enable = 'on';
            end              
        end

        % Value changed function: LinearBinningMethoddiffCheckBox_diff
        function LinearBinningMethoddiffCheckBox_diffValueChanged(app, event)
            if app.LinearBinningMethoddiffCheckBox_diff.Value == 0
                app.ADCLinearBinningMethodPanel.Enable = 'off'; 
                app.ADCLinearBinningResultsPanel.Enable = 'off';
                app.ADCLinearBinningResultsPanel.Enable = 'off';
            else
                app.ADCLinearBinningMethodPanel.Enable = 'on';
                app.ADCLinearBinningResultsPanel.Enable = 'on';
                app.ADCLinearBinningResultsPanel.Enable = 'on';
            end             
        end

        % Value changed function: MorphometryAnalysisCheckBox
        function MorphometryAnalysisCheckBoxValueChanged(app, event)
            if app.MorphometryAnalysisCheckBox.Value == 0
                app.MorphometryAnalysisPanel.Enable = 'off'; 
                app.MorphometryAnalysisResultPanel.Enable = 'off';
            else
                app.MorphometryAnalysisPanel.Enable = 'on';
                app.MorphometryAnalysisResultPanel.Enable = 'on';
            end              
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Ventilation' 
                    % ventilation
                    app.VentilationDefectPercentageVDPPanel.Enable = 'on'; 
                    app.BiasFieldCorrectionCheckBox.Enable = 'on'; 
                    app.TextureAnalysisPanel.Enable = 'on'; 
                    app.ProtonImagesPanel.Enable = 'on'; 
                    app.DefectDistributionIndexDDIPanel.Enable = 'on'; 
                    if app.VentilationAnalysisPerformed == 1
                        app.ThresholdMethodResultsPanel.Enable = 'on'; 
                        app.LinearBinningMethodResultsPanel.Enable = 'on'; 
                        app.TextureAnalysisGLRLMResultsPanel.Enable = 'on'; 
                    end
                    app.NoProtonImagesCheckBox.Value = 0;
                    % diffusion
                    app.NofbvaluesEditField.Enable = 'off'; 
                    app.DiffAcqOrderDropDown.Enable = 'off';
                    app.bvaluesPanel.Enable = 'off'; 
                    app.ADCAnalysisPanel.Enable = 'off'; 
                    app.MorphometryAnalysisPanel.Enable = 'off';  
                    app.MorphometryAnalysisCheckBox.Enable = 'off'; 
                    %app.ADCResultsPanel.Enable = 'off'; 
                    %app.MorphometryAnalysisResultPanel.Enable = 'off'; 
                    % Gas exchange
                    app.ManualHealthyReferenceDistributionsPanel.Enable = 'off'; 
                    app.VentilationSummaryPanel.Enable = 'off'; 
                    app.DissolvedSummaryPanel.Enable = 'off'; 
                    app.MembraneSummaryPanel.Enable = 'off'; 
                    app.RBCSummaryPanel.Enable = 'off'; 
                    app.RBCMembraneSummaryPanel.Enable = 'off'; 
                    app.RBCOscillationSummaryPanel.Enable = 'off'; 
                    app.HealthyReferenceDropDown.Enable = 'off'; 
                    app.ImportHealthyDistributionsButton.Enable = 'off'; 
                    
                case 'Diffusion'
                    % ventilation
                    app.VentilationDefectPercentageVDPPanel.Enable = 'off'; 
                    app.BiasFieldCorrectionCheckBox.Enable = 'off'; 
                    app.TextureAnalysisPanel.Enable = 'off'; 
                    app.ThresholdMethodResultsPanel.Enable = 'off'; 
                    app.LinearBinningMethodResultsPanel.Enable = 'off'; 
                    app.TextureAnalysisGLRLMResultsPanel.Enable = 'off'; 
                    app.DefectDistributionIndexDDIPanel.Enable = 'off'; 
                    % diffusion
                    app.NofbvaluesEditField.Enable = 'on'; 
                    app.DiffAcqOrderDropDown.Enable = 'on';
                    app.bvaluesPanel.Enable = 'on'; 
                    app.ADCAnalysisPanel.Enable = 'on'; 
                    app.MorphometryAnalysisPanel.Enable = 'off';  
                    app.MorphometryAnalysisCheckBox.Enable = 'on';
                    if app.DiffusionAnalysisPerformed == 1
                        app.ADCResultsPanel.Enable = 'on'; 
                        app.MorphometryAnalysisResultPanel.Enable = 'on'; 
                    end
                    % Gas exchange
                    app.ManualHealthyReferenceDistributionsPanel.Enable = 'off';  
                    app.VentilationSummaryPanel.Enable = 'off'; 
                    app.DissolvedSummaryPanel.Enable = 'off'; 
                    app.MembraneSummaryPanel.Enable = 'off'; 
                    app.RBCSummaryPanel.Enable = 'off'; 
                    app.RBCMembraneSummaryPanel.Enable = 'off'; 
                    app.RBCOscillationSummaryPanel.Enable = 'off'; 
                    app.HealthyReferenceDropDown.Enable = 'off'; 
                    app.ImportHealthyDistributionsButton.Enable = 'off'; 
                    % regisrtation
                    app.NoProtonImagesCheckBox.Value = 1;
                    app.ProtonImagesPanel.Enable = 'off'; 
                    app.TransformTypeButtonGroup.Enable = 'off'; 
                    app.ImageResolutionmmPanel.Enable = 'off'; 
                    app.PerformRegistrationButton.Enable = 'off'; 

                case 'GasExchange'
                    % ventilation
                    app.VentilationDefectPercentageVDPPanel.Enable = 'off'; 
                    app.BiasFieldCorrectionCheckBox.Enable = 'off'; 
                    app.TextureAnalysisPanel.Enable = 'off'; 
                    app.ThresholdMethodResultsPanel.Enable = 'off'; 
                    app.LinearBinningMethodResultsPanel.Enable = 'off'; 
                    app.TextureAnalysisGLRLMResultsPanel.Enable = 'off'; 
                    app.DefectDistributionIndexDDIPanel.Enable = 'off'; 
                    % diffusion
                    app.NofbvaluesEditField.Enable = 'off'; 
                    app.DiffAcqOrderDropDown.Enable = 'off';
                    app.bvaluesPanel.Enable = 'off'; 
                    app.ADCAnalysisPanel.Enable = 'off'; 
                    app.MorphometryAnalysisPanel.Enable = 'off';  
                    app.MorphometryAnalysisCheckBox.Enable = 'off';
                    app.ADCResultsPanel.Enable = 'off'; 
                    app.MorphometryAnalysisResultPanel.Enable = 'off'; 
                    % Gas exchange
                    app.HealthyReferenceDropDown.Enable = 'on';  
                    app.ProtonImagesPanel.Enable = 'on'; 
                    app.NoProtonImagesCheckBox.Value = 0;
                    if app.GasExchangeAnalysisPerformed == 1
                        app.VentilationSummaryPanel.Enable = 'on'; 
                        app.DissolvedSummaryPanel.Enable = 'on'; 
                        app.MembraneSummaryPanel.Enable = 'on'; 
                        app.RBCSummaryPanel.Enable = 'on'; 
                        app.RBCMembraneSummaryPanel.Enable = 'on'; 
                        app.RBCOscillationSummaryPanel.Enable = 'on'; 
                    end
            end             
            SegmentationMethodDropDownValueChanged(app);
        end

        % Callback function
        function VentilationResultsDropDownValueChanged(app, event)
%             try
                P = app.MainInput.XeDataLocation;
                cd([P,'\VDP Analysis'])   
                switch app.VentilationResultsDropDown.Value
                    case 'VDP Threshold'
                        Img = imread('Ventilation_Histogram.png'); 
                        app.viewing_img = app.Ventilation.VentDefectmap;                                             
                    case 'VDP Linear Binning'           
                        app.viewing_img = app.Ventilation.Ventcolormap;
                        Img = imread('LB_Ventilation_Histogram.png');                                                   
                    case 'Texture Analysis'
                        app.viewing_img = zeros(50,50,10);
                        Img = imread('GLRLM_AverageAll.png');    
                end    
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                Update_orthoViewer(app,app.viewing_img);          
                Img = imresize(Img, 0.7);
                imagesc(app.HistogramAxes,Img);
%                 imshow(app.HistogramAxes,Img);
                app.StatusTextArea.Value = ['Viewing ',app.AnalysisTypeDropDown.Value,' images'];  pause(.1); 
                app.ImagesbeingviewedEditField.Value = app.AnalysisTypeDropDown.Value ; pause(.1);               
%             catch
%                 app.StatusTextArea.Value = [app.AnalysisTypeDropDown.Value,' does not exsit'];  pause(.1); 
%             end
            
        end

        % Value changed function: CylindricalModelCMCheckBox
        function CylindricalModelCMCheckBoxValueChanged(app, event)
%             if app.CylindricalModelCMCheckBox.Value == 0
%                 app.CylindricalModelCMResultsPanel.Enable = 'off'; 
%             else
%                 app.CylindricalModelCMResultsPanel.Enable = 'on';
%             end              
        end

        % Value changed function: StretchExponentialModelSEMCheckBox
        function StretchExponentialModelSEMCheckBoxValueChanged(app, event)
%             if app.StretchExponentialModelSEMCheckBox.Value == 0
%                 app.StretchExponentialModelSEMResultsPanel.Enable = 'off'; 
%             else
%                 app.StretchExponentialModelSEMResultsPanel.Enable = 'on';
%             end              
        end

        % Value changed function: TextureAnalysisCheckBox
        function TextureAnalysisCheckBoxValueChanged(app, event)
            if app.TextureAnalysisCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.TextureAnalysisGLRLMResultsPanel.Enable = 'on'; 
            else
                app.TextureAnalysisGLRLMResultsPanel.Enable = 'off';
            end             
        end

        % Button pushed function: VDPMapButton
        function VDPMapButtonPushed(app, event)
            if app.ThresholdVDPCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.viewing_img = app.Ventilation.VentDefectmap; 
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Thersh VDP Map'; pause(.1);
            end             
        end

        % Button pushed function: VDPHistogramButton
        function VDPHistogramButtonPushed(app, event)
            if app.ThresholdVDPCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Ventilation Analysis\VDP Analysis']) 
                Img = imread('Ventilation_Histogram.png');
                figure; imshow(Img);
            end             
        end

        % Button pushed function: LBVDPMapButton
        function LBVDPMapButtonPushed(app, event)
            if app.LinearBinningMethodCheckBox_vent.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.viewing_img = app.Ventilation.Ventcolormap; 
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 0 0.57 0.71; 0 0 1]; %Used for Vent and RBC
                colormap(app.UIAxes_colorbar, SixBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]); 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'LB VDP Map'; pause(.1);
            end             
        end

        % Button pushed function: LBVDPHistogramButton
        function LBVDPHistogramButtonPushed(app, event)
            if app.LinearBinningMethodCheckBox_vent.Value == 1 && app.VentilationAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Ventilation Analysis\VDP Analysis']) 
                Img = imread('LB_Ventilation_Histogram.png');
                figure; imshow(Img);
                
            end              
        end

        % Button pushed function: MeanGLRLMMapButton
        function MeanGLRLMMapButtonPushed(app, event)
            if app.TextureAnalysisCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Ventilation Analysis\GLRLM Analysis']) 
                Img = imread('GLRLM_AverageAll.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Texture Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: ADCMapButton
        function ADCMapButtonPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis'])
                app.viewing_img = app.Diffusion.ADCmap; % ADCmap | ADCcoloredmap
                %app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 0; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                app.ColorMapDropDown.Value = 'jet';
                
                % view images 
                colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [min(app.viewing_img(:)) max(app.viewing_img(:))]); 
                Update_orthoViewer(app,app.viewing_img);  
                app.ImagesbeingviewedEditField.Value = 'ADC Map'; pause(.1);
            else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: ADCHistogramButton
        function ADCHistogramButtonPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 
                Img = imread('ADC_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: LBADCMapButton
        function LBADCMapButtonPushed(app, event)
            if app.LinearBinningMethoddiffCheckBox_diff.Value == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 
                app.viewing_img = app.Diffusion.LB_colorDiffmap;                 
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
               
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 0 0.57 0.71; 0 0 1]; %Used for Vent and RBC
                colormap(app.UIAxes_colorbar, SixBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]); 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'LB ADC Map'; pause(.1);
            else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end             
        end

        % Button pushed function: LBADCHistogramButton
        function LBADCHistogramButtonPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.LinearBinningMethoddiffCheckBox_diff.Value == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 
                Img = imread('LB_ADC_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end                
        end

        % Button pushed function: HistogramButtonVent
        function HistogramButtonVentPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('Ventilation_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end                
        end

        % Button pushed function: HistogramButtonDissolved
        function HistogramButtonDissolvedPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('Dissolved_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end               
        end

        % Button pushed function: HistogramButtonBarr
        function HistogramButtonBarrPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('Barrier_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: HistogramButtonRBC
        function HistogramButtonRBCPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('RBC_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end               
        end

        % Button pushed function: HistogramButtonRBCBarr
        function HistogramButtonRBCBarrPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('RBC_Barrier_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: HistogramButtonRBCOsc
        function HistogramButtonRBCOscPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                Img = imread('RBC_Oscillation_Histogram.png');
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
            end              
        end

        % Button pushed function: MapButtonVent
        function MapButtonVentPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedVentmap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 0 0.57 0.71; 0 0 1]; %Used for Vent and RBC
                colormap(app.UIAxes_colorbar, SixBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]); 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: Ventilation'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end             
        end

        % Button pushed function: MapButtonDissolved
        function MapButtonDissolvedPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedDissolvedmap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 0 0.57 0.71; 0 0 1]; %Used for Vent and RBC
                colormap(app.UIAxes_colorbar, SixBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]);                 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: Dissolved'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end              
        end

        % Button pushed function: MapButtonBarr
        function MapButtonBarrPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedBarrierUptakemap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                EightBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 184/255 226/255 145/255; 243/255 205/255 213/255; 225/255 129/255 162/255; 197/255 27/255 125/255]; %Used for barrier
                colormap(app.UIAxes_colorbar, EightBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]);                 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: Barrier'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end              
        end

        % Button pushed function: MapButtonRBC
        function MapButtonRBCPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1 && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedRBCTransfermap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 0 0.57 0.71; 0 0 1]; %Used for Vent and RBC
                colormap(app.UIAxes_colorbar, SixBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]);                 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: RBC'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end          
        end

        % Button pushed function: MapButtonRBCBarr
        function MapButtonRBCBarrPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1  && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedRBCBarriermap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                SixBinRBCBarMap = [197/255 27/255 125/255; 225/255 129/255 162/255; 0.4 0.7 0.4; 0 1 0; 1 0.7143 0; 1 0 0]; %Used for RBC/Barrier ratio
                colormap(app.UIAxes_colorbar, SixBinRBCBarMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]);                  
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: RBC:Barrier'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end             
        end

        % Button pushed function: MapButtonRBCOsc
        function MapButtonRBCOscPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'GasExchange') == 1  && app.GasExchangeAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Gas Exchange Analysis']) 
                app.viewing_img = app.GasExchange.BinnedRBCOscillationmap;                 
                app.ImageType = 1; 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                                                    
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                EightBinMap = [1 0 0; 1 0.7143 0; 0.4 0.7 0.4; 0 1 0; 184/255 226/255 145/255; 243/255 205/255 213/255; 225/255 129/255 162/255; 197/255 27/255 125/255]; %Used for barrier
                colormap(app.UIAxes_colorbar, EightBinMap); colorbar(app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [0 6]);                  
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'Gas: RBC Oscillation'; pause(.1);
             else
                app.StatusTextArea.Value = 'Gas Exchange Analysis has not been performed.';  pause(.1); 
             end                  
        end

        % Value changed function: CMParametersDropDown
        function CMParametersDropDownValueChanged(app, event)
                 if app.MorphometryAnalysisCheckBox.Value == 1 && size(app.Diffusion.Image,4) > 3
                    if app.CylindricalModelCMCheckBox.Value == 1 && app.DiffusionAnalysisPerformed == 1
    %                     if strcmp(app.CMParametersDropDown.Value,'DT: Transverse diffusion coefficient') == 1 
    %                         app.MeanTextAreaCM.Value = [num2str(app.Diffusion.DT_mean),'±',num2str(app.Diffusion.DT_std)];
    %                     elseif strcmp(app.CMParametersDropDown.Value,'DL: Longitudinal diffusion coefficient') == 1 
    %                         app.MeanTextAreaCM.Value = [num2str(app.Diffusion.DL_mean),'±',num2str(app.Diffusion.DL_std)];
    %                     elseif strcmp(app.CMParametersDropDown.Value,'Dan: Anisotropic diffusion coefficient') == 1 
    %                         app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Dan_mean),'±',num2str(app.Diffusion.Dan_std)];
    %                     elseif strcmp(app.CMParametersDropDown.Value,'Dmean: Mean diffusion coefficient') == 1 
    %                         app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Dmean_mean),'±',num2str(app.Diffusion.Dmean_std)];
                        if strcmp(app.CMParametersDropDown.Value,'R: External Acinar duct radius') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.R_mean),'±',num2str(app.Diffusion.R_std)];
                        elseif strcmp(app.CMParametersDropDown.Value,'r: Internal Acinar duct radius') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.r_mean),'±',num2str(app.Diffusion.r_std)];
                        elseif strcmp(app.CMParametersDropDown.Value,'h: Alveolar sleeve depth') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.h_mean),'±',num2str(app.Diffusion.h_std)];
                        elseif strcmp(app.CMParametersDropDown.Value,'Lm: Mean linear intercept') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Lm_mean),'±',num2str(app.Diffusion.Lm_std)];
                        elseif strcmp(app.CMParametersDropDown.Value,'SVR: Surface to volume ratio') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.SVR_mean),'±',num2str(app.Diffusion.SVR_std)];
                        elseif strcmp(app.CMParametersDropDown.Value,'Na: Alveolar density') == 1 
                            app.MeanTextAreaCM.Value = [num2str(app.Diffusion.Na_mean),'±',num2str(app.Diffusion.Na_std)];
                        end
                    end  
                 else
                     app.StatusTextArea.Value = 'CM Morphometry Analysis has not been performed.';  pause(.1); 
                end
        end

        % Value changed function: SEMParametersDropDown
        function SEMParametersDropDownValueChanged(app, event)
            if app.MorphometryAnalysisCheckBox.Value == 1 && size(app.Diffusion.Image,4) > 3
                if app.StretchExponentialModelSEMCheckBox.Value == 1 && app.DiffusionAnalysisPerformed == 1
                    if strcmp(app.SEMParametersDropDown.Value,'Alpha: Heterogeneity index') == 1 
                        app.MeanTextAreaSEM.Value = [num2str(app.Diffusion.alpha_mean),'±',num2str(app.Diffusion.alpha_std)];
                    elseif strcmp(app.SEMParametersDropDown.Value,'DCC: Distributed diffusivity coefficient') == 1 
                        app.MeanTextAreaSEM.Value = [num2str(app.Diffusion.DDC_mean),'±',num2str(app.Diffusion.DDC_std)];
                    elseif strcmp(app.SEMParametersDropDown.Value,'LmD: Mean diffusive length scale') == 1 
                        app.MeanTextAreaSEM.Value = [num2str(app.Diffusion.LmD_mean),'±',num2str(app.Diffusion.LmD_std)];
                    end
                end    
            else
                 app.StatusTextArea.Value = 'SEM Morphometry Analysis has not been performed.';  pause(.1); 
            end
        end

        % Button pushed function: MapButtonCM
        function MapButtonCMPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 

                if app.CylindricalModelCMCheckBox.Value == 1
%                     if strcmp(app.CMParametersDropDown.Value,'DT: Transverse diffusion coefficient') == 1 
%                         app.viewing_img = app.Diffusion.DT_map;
%                         app.ImagesbeingviewedEditField.Value = 'DT map'; pause(.1);
%                     elseif strcmp(app.CMParametersDropDown.Value,'DL: Longitudinal diffusion coefficient') == 1 
%                         app.viewing_img = app.Diffusion.Dan_map;
%                         app.ImagesbeingviewedEditField.Value = 'Dan map'; pause(.1);
%                     elseif strcmp(app.CMParametersDropDown.Value,'Dan: Anisotropic diffusion coefficient') == 1 
%                         app.viewing_img = app.Diffusion.DL_map;
%                         app.ImagesbeingviewedEditField.Value = 'DL map'; pause(.1);
%                     elseif strcmp(app.CMParametersDropDown.Value,'Dmean: Mean diffusion coefficient') == 1 
%                         app.viewing_img = app.Diffusion.Dmean_map;
%                         app.ImagesbeingviewedEditField.Value = 'Dmean map'; pause(.1);
                    if strcmp(app.CMParametersDropDown.Value,'R: External Acinar duct radius') == 1 
                        app.viewing_img = app.Diffusion.R_map;
                        app.ImagesbeingviewedEditField.Value = 'R map'; pause(.1);
                    elseif strcmp(app.CMParametersDropDown.Value,'r: Internal Acinar duct radius') == 1 
                        app.viewing_img = app.Diffusion.h_map;
                        app.ImagesbeingviewedEditField.Value = 'r map'; pause(.1);
                    elseif strcmp(app.CMParametersDropDown.Value,'h: Alveolar sleeve depth') == 1 
                        app.viewing_img = app.Diffusion.r_map;
                        app.ImagesbeingviewedEditField.Value = 'h map'; pause(.1);
                    elseif strcmp(app.CMParametersDropDown.Value,'Lm: Mean linear intercept') == 1 
                        app.viewing_img = app.Diffusion.Lm_map;
                        app.ImagesbeingviewedEditField.Value = 'Lm map'; pause(.1);
                    elseif strcmp(app.CMParametersDropDown.Value,'SVR: Surface to volume ratio') == 1 
                        app.viewing_img = app.Diffusion.SVR_map;
                        app.ImagesbeingviewedEditField.Value = 'SVR map'; pause(.1);
                    elseif strcmp(app.CMParametersDropDown.Value,'Na: Alveolar density') == 1 
                        app.viewing_img = app.Diffusion.Na_map;
                        app.ImagesbeingviewedEditField.Value = 'Na map'; pause(.1);
                    end
                end  

                app.ImageType = 0; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                app.ColorMapDropDown.Value = 'jet';

                % view images 
                colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [min(app.viewing_img(:)) max(app.viewing_img(:))]); 
                Update_orthoViewer(app,app.viewing_img);  
                app.ResetContrastPushed(app);
            else
                 app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end
        end

        % Button pushed function: MapButtonSEM
        function MapButtonSEMPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 

                if app.StretchExponentialModelSEMCheckBox.Value == 1 
                    if strcmp(app.SEMParametersDropDown.Value,'Alpha: Heterogeneity index') == 1 
                        app.viewing_img = app.Diffusion.alpha_map;
                        app.ImagesbeingviewedEditField.Value = 'alpha map'; pause(.1);
                    elseif strcmp(app.SEMParametersDropDown.Value,'DCC: Distributed diffusivity coefficient') == 1 
                        app.viewing_img = app.Diffusion.DDC_map;
                        app.ImagesbeingviewedEditField.Value = 'DDC map'; pause(.1);
                    elseif strcmp(app.SEMParametersDropDown.Value,'LmD: Mean diffusive length scale') == 1 
                        app.viewing_img = app.Diffusion.LmD_map;
                        app.ImagesbeingviewedEditField.Value = 'LmD map'; pause(.1);
                    end
                end      

                app.ImageType = 0; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];                
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); 
                app.viewing_img2 = app.viewing_img;
                app.ColorMapDropDown.Value = 'jet';

                % view images 
                colormap (app.UIAxes_colorbar, eval(app.ColorMapDropDown.Value)); colorbar (app.UIAxes_colorbar,"north"); clim(app.UIAxes_colorbar, [min(app.viewing_img(:)) max(app.viewing_img(:))]); 
                Update_orthoViewer(app,app.viewing_img);  
                app.ResetContrastPushed(app);
            else
                 app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end
        end

        % Button pushed function: HistogramButtonCM
        function HistogramButtonCMPushed(app, event)
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 
                if app.CylindricalModelCMCheckBox.Value == 1 
%                     if strcmp(app.CMParametersDropDown.Value,'DT: Transverse diffusion coefficient') == 1 
%                         Img = imread('DT_histFig.png');
%                     elseif strcmp(app.CMParametersDropDown.Value,'DL: Longitudinal diffusion coefficient') == 1 
%                         Img = imread('DL_histFig.png');
%                     elseif strcmp(app.CMParametersDropDown.Value,'Dan: Anisotropic diffusion coefficient') == 1 
%                         Img = imread('Dan_histFig.png');
%                     elseif strcmp(app.CMParametersDropDown.Value,'Dmean: Mean diffusion coefficient') == 1 
%                         Img = imread('Dmean_histFig.png');
                    if strcmp(app.CMParametersDropDown.Value,'R: External Acinar duct radius') == 1 
                        Img = imread('R_histFig.png');
                    elseif strcmp(app.CMParametersDropDown.Value,'r: Internal Acinar duct radius') == 1 
                        Img = imread('r_histFig2.png');
                    elseif strcmp(app.CMParametersDropDown.Value,'h: Alveolar sleeve depth') == 1 
                        Img = imread('h_histFig.png');
                    elseif strcmp(app.CMParametersDropDown.Value,'Lm: Mean linear intercept') == 1 
                        Img = imread('Lm_histFig.png');
                    elseif strcmp(app.CMParametersDropDown.Value,'SVR: Surface to volume ratio') == 1 
                        Img = imread('SVR_histFig.png');
                    elseif strcmp(app.CMParametersDropDown.Value,'Na: Alveolar density') == 1 
                        Img = imread('Na_histFig.png');
                    end
                end 
                figure; imshow(Img);
            else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
            end 
        end

        % Button pushed function: HistogramButtonSEM
        function HistogramButtonSEMPushed(app, event)
             if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1 && app.DiffusionAnalysisPerformed == 1
                P = app.MainInput.XeDataLocation;
                cd([P,'\Diffusion Analysis']) 
                if app.StretchExponentialModelSEMCheckBox.Value == 1 
                    if strcmp(app.SEMParametersDropDown.Value,'Alpha: Heterogeneity index') == 1 
                        Img = imread('alpha_histFig.png'); 
                    elseif strcmp(app.SEMParametersDropDown.Value,'DCC: Distributed diffusivity coefficient') == 1 
                        Img = imread('DDC_histFig.png'); 
                    elseif strcmp(app.SEMParametersDropDown.Value,'LmD: Mean diffusive length scale') == 1 
                        Img = imread('LmD_histFig.png');
                    end
                end 
                figure; imshow(Img);
             else
                app.StatusTextArea.Value = 'Diffusion Analysis has not been performed.';  pause(.1); 
             end 
        end

        % Button pushed function: EditMaskButton
        function EditMaskButtonPushed(app, event)
            app.StatusTextArea.Value = 'Launching volumeSegmenter for editing mask, please wait...';  pause(.1); 
            app.ReadyLamp.Color = 'red'; 
            switch app.AnalysisTypeDropDown.Value
                case 'Ventilation'
                    A = double(app.Ventilation.LungMask);
                    B = double(app.Ventilation.AirwayMask);
                    C = double(app.Ventilation.LungMask) + double(app.Ventilation.AirwayMask)*2;
                    XeImage = app.Ventilation.Image;
                case 'Diffusion'
                    A = double(app.Diffusion.LungMask);
                    B = double(app.Diffusion.AirwayMask);               
                    C = double(app.Diffusion.LungMask + app.Diffusion.AirwayMask*2);
                    XeImage = app.Diffusion.Image;
                case 'GasExchange'
                    A = double(app.GasExchange.LungMask);
                    B = double(app.GasExchange.AirwayMask);
                    C = double(app.GasExchange.LungMask + app.GasExchange.AirwayMask*2);
                    XeImage = app.GasExchange.Image;
            end 

            if exist('A','var') && exist('B','var') && exist('C','var') 
                A(isinf(A)|isnan(A)) = 0;
                B(isinf(B)|isnan(B)) = 0;
                C(isinf(C)|isnan(C)) = 0;
                if strcmp(app.SelectAnatomyButtonGroup_maunal.SelectedObject.Text, 'Parenchyma') || strcmp(app.SelectAnatomyButtonGroup_threshold.SelectedObject.Text, 'Parenchyma')
                      switch app.MethodButtonGroup_maunal.SelectedObject.Text
                          case 'Freehand'
                                A = Segmentation.FreehandSegment(XeImage,A); 
                                switch app.AnalysisTypeDropDown.Value
                                    case 'Ventilation'
                                        app.Ventilation.LungMask = double(A);
                                    case 'Diffusion'
                                        app.Diffusion.LungMask = double(A);
                                    case 'GasExchange'
                                        app.GasExchange.LungMask = double(A);
                                end          
                                MasktoviewDropDownValueChanged(app, event);
                          case 'AppSegmenter'
                                % use volumeSegmenter
                                valueset = 1:2;
                                catnames = {'lung' 'airway'};                                
                                C = categorical(C,valueset,catnames,'Ordinal',true);  
                                volumeSegmenter(XeImage,C)
                      end 
                elseif strcmp(app.SelectAnatomyButtonGroup_maunal.SelectedObject.Text, 'Airway') || strcmp(app.SelectAnatomyButtonGroup_threshold.SelectedObject.Text, 'Airway')
                      switch app.MethodButtonGroup_maunal.SelectedObject.Text
                          case 'Freehand'
                                B = Segmentation.FreehandSegment(XeImage,B); 
                                switch app.AnalysisTypeDropDown.Value
                                    case 'Ventilation'
                                        app.Ventilation.AirwayMask = double(B);
                                    case 'Diffusion'
                                        app.Diffusion.AirwayMask = double(B);
                                    case 'GasExchange'
                                        app.GasExchange.AirwayMask = double(B);
                                end                  
                                MasktoviewDropDownValueChanged(app, event);
                          case 'AppSegmenter'
                                % use volumeSegmenter
                                valueset = 1:2;
                                catnames = {'lung' 'airway'};                                
                                C = categorical(C,valueset,catnames,'Ordinal',true);  
                                volumeSegmenter(XeImage,C)
                      end 
                end 
            end 
            app.LoadSegmentedMaskButton.Enable = 'on';
            app.StatusTextArea.Value = [app.AnalysisTypeDropDown.Value,' editing mask...'];  pause(.1); 
            app.ReadyLamp.Color = 'red';             
        end

        % Button pushed function: ViewButtonLoadData
        function ViewButtonLoadDataPushed(app, event)
            ImagestoviewDropDownValueChanged(app, event);
        end

        % Button pushed function: ViewButtonSegment
        function ViewButtonSegmentPushed(app, event)
            MasktoviewDropDownValueChanged(app, event);
        end

        % Button pushed function: ViewButtonRegisteration
        function ViewButtonRegisterationPushed(app, event)
            ImagestoviewRegistrationDropDownValueChanged(app, event);
        end

        % Menu selected function: StartFreshMenu
        function StartFreshMenuSelected(app, event)
            promptMessage = sprintf('Are you sure you want to reset settings');
            titleBarCaption = 'Yes or No';
            button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
            if strcmpi(button, 'Yes')
                app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Resetting...';  pause(.1); 
                startupFcn(app);
                app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Resetting completed.';  pause(.1); 
            else
                delete(promptMessage);
            end            
        end

        % Callback function
        function NonCartesianFAsignalsettingButtonGroupSelectionChanged(app, event)
        if app.PerformCalibrationStatus == 1 
            SType = app.SequenceTypeDropDown.Value;
            VentFA = atand(sqrt(2/app.VentilationExcitationsEditField.Value)) / app.FA_Scale_Factor;
            DiffFA = acosd((0.2)^(1/(app.DiffusionExcitationsEditField.Value*app.DiffusionbvaluesEditField.Value))) / app.FA_Scale_Factor;

            if strcmp(SType, '2D Radial') == 1 || strcmp(SType, '3D Radial') == 1 || strcmp(SType, '2D Spiral') == 1 || strcmp(SType, '3D Spiral (SoS)') == 1 || strcmp(SType, '3D Spiral') == 1 
                VentRF = app.VentilationExcitationsEditField.Value;
                Total_DiffRF = app.DiffusionExcitationsEditField.Value * app.DiffusionbvaluesEditField.Value;                
                switch app.NonCartesianFAsignalsettingButtonGroup.SelectedObject.Text
                    case 'Maximizing Signal' 
                        Alpha=1:0.1:90;
                        Vent_S = 1./VentRF.*sind(Alpha).*(1-cosd(Alpha).^VentRF)./(1-cosd(Alpha));    
                        [~,locs] = findpeaks(Vent_S);
                        VentFA = Alpha(locs)./app.FA_Scale_Factor; 
                        
                        Diff_S = 1./Total_DiffRF.*sind(Alpha).*(1-cosd(Alpha).^Total_DiffRF)./(1-cosd(Alpha));    
                        [~,locs] = findpeaks(Diff_S);
                        DiffFA = Alpha(locs)./app.FA_Scale_Factor; 
                    case 'Minimizing Artifacts'

                        VentFA = acosd(sqrt((VentRF-1)/VentRF))./app.FA_Scale_Factor;
                        DiffFA = acosd(sqrt((Total_DiffRF-1)/Total_DiffRF))./app.FA_Scale_Factor;
                end  
            end
            disp(['scale factor =',num2str(app.FA_Scale_Factor)]);
            GasExGas = 0.5 / app.FA_Scale_Factor;
            GasExDiss = 20 / app.FA_Scale_Factor;
            FATxt = [...
                sprintf(['For %d/%d/%d/',app.SequenceTypeDropDown.Value,':\n'], app.VentilationExcitationsEditField.Value,app.DiffusionExcitationsEditField.Value,app.DiffusionbvaluesEditField.Value),...
                sprintf('Vent. Flip Angle = %3.2f\n', VentFA),...
                sprintf('Diff. Flip Angle = %3.2f\n', DiffFA),...
                sprintf('GasEx Gas Flip Angle = %3.2f\n', GasExGas),...
                sprintf('GasEx Diss. Flip Angle = %3.2f\n', GasExDiss),...
                ];
            app.OptimumFlipAnglesTextArea.Value = FATxt;
        end
        end

        % Value changed function: SequenceTypeDropDown
        function SequenceTypeDropDownValueChanged(app, event)
            if app.PerformCalibrationStatus == 1 
                FACal_calculation(app)
                app.StatusTextArea.Value='Optimum Flip Angles have been updated.';  pause(1); 
            end
        end

        % Value changed function: SliceSelectionCheckBox
        function SliceSelectionCheckBoxValueChanged(app, event)
            if app.NoProtonImagesCheckBox.Value == 0
                if app.SliceSelectionCheckBox.Value == 1
                    app.SliceSeclectionPanel.Enable = 'on'; 
                elseif app.SliceSelectionCheckBox.Value == 0
                    app.SliceSeclectionPanel.Enable = 'off';            
                end           
    
                switch app.AnalysisTypeDropDown.Value
                    case 'Ventilation' 
                        Images = app.Ventilation.Image;   
                    case 'Diffusion'                        
                    case 'GasExchange'
                        Images = app.GasExchange.VentImage;    
                end   
                app.XestartEditField.Value = 1;
                app.XeendEditField.Value = size(Images, 3);
                app.HstartEditField.Value = 1;
                app.HendEditField.Value = size(app.Proton.Image, 3);
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            app.StatusTextArea.Value = 'Generating Report...please wait...';  pause(.1); 
            app.ReadyLamp.Color = 'red';  
            app.MainInput.studyID = app.StudyIDEditField.Value;
            app.MainInput.studyType = app.StudyTypeEditField.Value;
            app.MainInput.patientID = app.PatientIDEditField.Value;
            app.MainInput.scanDate = string(app.ScanDateDatePicker.Value);
            app.MainInput.xeDoseVolume = app.XeDoseVolumemLEditField.Value;
            app.MainInput.PatientName = app.PatientNameEditField.Value;
            app.MainInput.MRMnumber = app.MRMEditField.Value;
            app.MainInput.sex = app.SexDropDown.Value;
            app.MainInput.age = app.AgeyearEditField.Value;
            app.MainInput.height = app.HeightcmEditField.Value;
            app.MainInput.weight = app.WeightlbsEditField.Value;
            app.MainInput.summaryofFindings = app.SummaryofFindingsEditField.Value;
            app.MainInput.notes = app.NotesEditField.Value;
            app.MainInput.dataAnalyst = app.DataAnalystEditField.Value;
            app.MainInput.processingDate = string(app.ProcessingDateDatePicker.Value);
            
            Global.PatientReport(app.MainInput)
            app.StatusTextArea.Value = 'Generating Report completed';  pause(.1); 
            app.ReadyLamp.Color = 'green'; 
        end

        % Callback function
        function MaunalSegmentationMethodButtonGroupSelectionChanged(app, event)
            switch app.MaunalSegmentationMethodButtonGroup.SelectedObject.Text
                case 'Freehand'
                    app.SelectAnatomyButtonGroup_maunal.Enable = 'on';
                    app.LoadSegmentedMaskButton.Enable = 'off';
                case 'AppSegmenter'
                    app.SelectAnatomyButtonGroup_maunal.Enable = 'off';
            end
        end

        % Menu selected function: ScreenShotMenu
        function ScreenShotMenuSelected(app, event)
%             exportapp(app.HPXeMRIAnalysisApp, fullfile(app.DataLocationCalibration,'HPXeMRIAnalysisApp_screenshot.png'));
%             pause(.1); 
%             app.StatusTextArea.Value = join(['screenshot was saved in ',string(cd)]);  pause(.1); 
%             app.ReadyLamp.Color = 'green'; 
        end

        % Menu selected function: takescreenshotMenu
        function takescreenshotMenuSelected(app, event)
            exportapp(app.HPXeMRIAnalysisApp, fullfile(app.DataLocationCalibration,'HPXeMRIAnalysisApp_screenshot.png'));
            pause(.1); 
            app.StatusTextArea.Value = join(['screenshot was saved in ',string(cd)]);  pause(.1); 
            app.ReadyLamp.Color = 'green'; 
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            Global.about_this_app(12);     
        end

        % Selection changed function: SliceOrientationButtonGroup
        function SliceOrientationButtonGroupSelectionChanged(app, event)
               switch app.SliceOrientationButtonGroup.SelectedObject.Text
                    case 'coronal' 
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'  
                            case 'Diffusion'
                                app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1); 
                            case 'GasExchange'
                        end
                    case 'transversal'
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'  
                            case 'Diffusion'
                            case 'GasExchange'
                        end
                    case 'sagittal'
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'  
                                app.StatusTextArea.Value = 'Auto segmentation for Ventilation images with sagittal orientation is not supported yet';  pause(.1); 
                            case 'Diffusion'
                                app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1); 
                            case 'GasExchange'
                        end
                    case 'isotropic'
                        switch app.AnalysisTypeDropDown.Value
                            case 'Ventilation'  
                               app.StatusTextArea.Value = 'first dimension needs to be in the coronal view';  pause(.1); 
                            case 'Diffusion'
                                app.StatusTextArea.Value = 'Auto segmentation for Diffusion analysis is not supported yet';  pause(.1);                                 
                            case 'GasExchange'
                        end

                end 
        end

        % Menu selected function: JournalReferenceMenu
        function JournalReferenceMenuSelected(app, event)
            %web('https://www.example.com');
        end

        % Menu selected function: SourceCodeGithubMenu
        function SourceCodeGithubMenuSelected(app, event)
            web('https://github.com/aboodbdaiwi/HP129Xe_Analysis_App');
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed2(app, event)
            app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Saving calibration results, please wait...';  pause(.1); 
            exportapp(app.HPXeMRIAnalysisApp, fullfile(app.DataLocationCalibration,'CalibrationFigure.png'));
            exportapp(app.HPXeMRIAnalysisApp, fullfile(app.DataLocationCalibration,'CalibrationFigure.pdf')); 
            app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Calibration results have been saved.';  pause(.1);
        end

        % Button pushed function: ImportHealthyDistributionsButton
        function ImportHealthyDistributionsButtonPushed(app, event)
            if strcmp(app.HealthyReferenceDropDown.Value , 'Import') 
                app.ReadyLamp.Color = 'red'; app.StatusTextArea.Value='Importing Healthy Cohort, please wait...';  pause(.1); 
                [filename, path] = uigetfile('*.mat*','Import Healthy Cohort');
                app.GasExchange.HealthyCohortFullPath = [path,filename];
                app.GasExchange.HealthyCohortDataLocation = path(1:end-1);
                [~,~,HealthyCohort_ext] = fileparts(app.GasExchange.HealthyCohortFullPath);
                app.GasExchange.HealthyCohortFileName = filename;
                app.GasExchange.HealthyCohort_ext = HealthyCohort_ext;
                app.ReadyLamp.Color = 'green'; app.StatusTextArea.Value='Healthy Cohort has been imported.';  pause(.1);
            end
        end

        % Callback function
        function GREorderButtonGroupSelectionChanged(app, event)
            if app.PerformCalibrationStatus == 1 
                FACal_calculation(app)
                app.StatusTextArea.Value='Optimum Flip Angles have been updated.';  pause(1); 
            end
        end

        % Value changed function: DenoiseXeImageCheckBox
        function DenoiseXeImageCheckBoxValueChanged(app, event)
            value = app.DenoiseXeImageCheckBox.Value;
            if value == 1
                app.DenoiseSDEditField.Enable = 'on'; 
            elseif value == 0
                app.DenoiseSDEditField.Enable = 'off'; 
            end
        end

        % Value changed function: HealthyReferenceDropDown
        function HealthyReferenceDropDownValueChanged(app, event)
            switch app.HealthyReferenceDropDown.Value
                case 'Default' 
                    app.MainInput.ImportHealthyCohort = 0;
                    app.ManualHealthyReferenceDistributionsPanel.Enable = 'off';  
                    app.ImportHealthyDistributionsButton.Enable = 'off';
                case 'Import' 
                    app.MainInput.ImportHealthyCohort = 1;
                    app.ManualHealthyReferenceDistributionsPanel.Enable = 'off'; 
                    app.ImportHealthyDistributionsButton.Enable = 'on';
                case 'Manual' 
                    app.MainInput.ImportHealthyCohort = 2;
                    app.ManualHealthyReferenceDistributionsPanel.Enable = 'on'; 
                    app.ImportHealthyDistributionsButton.Enable = 'off';
            end
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
             % Rotate images               
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1
                app.viewing_img2 = imrotate(app.viewing_img2,str2double(app.RotateFlipdDropDown.Value)*90);
                app.viewing_img = squeeze(app.viewing_img2(:,:,:,1));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
            else 
                app.viewing_img = imrotate(app.viewing_img,str2double(app.RotateFlipdDropDown.Value)*90);
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];  
                %app.viewing_img2 = app.viewing_img; 
            end              
            Update_orthoViewer(app,app.viewing_img)            
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
             % flip images 
            if strcmp(app.AnalysisTypeDropDown.Value, 'Diffusion') == 1
                app.viewing_img2 = flip(app.viewing_img2,str2double(app.RotateFlipdDropDown.Value));
                app.viewing_img = squeeze(app.viewing_img2(:,:,:,1));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
            else 
                app.viewing_img = flip(app.viewing_img,str2double(app.RotateFlipdDropDown.Value));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)];  
                %app.viewing_img2 = app.viewing_img; 
            end
            Update_orthoViewer(app,app.viewing_img)          
        end

        % Button pushed function: kmeansVDPMapButton
        function kmeansVDPMapButtonPushed(app, event)
            if app.kmeansCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.viewing_img = app.Ventilation.kmeansDefectmap; 
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'k-means VDP Map'; pause(.1);
            end 
        end

        % Value changed function: kmeansCheckBox
        function kmeansCheckBoxValueChanged(app, event)
            if app.kmeansCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.KmeansResultsPanel.Enable = 'on'; 
            else
                app.KmeansResultsPanel.Enable = 'off';
            end   
        end

        % Value changed function: DDI3DCheckBox
        function DDI3DCheckBoxValueChanged(app, event)
            if app.DDI3DCheckBox.Value == 1 
                app.DDI_X.Enable = 'on'; 
                app.DDI_Y.Enable = 'on'; 
                app.DDI_Z.Enable = 'on';  
                app.VoxelSizeLabel.Enable = 'on';  
                app.DefectMapDropDown.Enable = 'on';  
                DefectMapDropDownValueChanged(app)
                DDI2DCheckBoxValueChanged(app)
            else
                app.DDI_X.Enable = 'off'; 
                app.DDI_Y.Enable = 'off'; 
                app.DDI_Z.Enable = 'off'; 
                app.VoxelSizeLabel.Enable = 'off';
                app.DefectMapDropDown.Enable = 'off';
                DDI2DCheckBoxValueChanged(app)
            end               
        end

        % Value changed function: DDI2DCheckBox
        function DDI2DCheckBoxValueChanged(app, event)
            if app.DDI2DCheckBox.Value == 1 
                app.DefectMapDropDown.Enable = 'on';  
                DefectMapDropDownValueChanged(app)
            elseif app.DDI3DCheckBox.Value == 1 
                app.DefectMapDropDown.Enable = 'on';
            else
                app.DefectMapDropDown.Enable = 'off';                
            end 
        end

        % Value changed function: DefectMapDropDown
        function DefectMapDropDownValueChanged(app, event)
            if strcmp(app.DefectMapDropDown.Value,'Threshold') && app.ThresholdVDPCheckBox.Value == 0
                DDI_message(app);
                app.ThresholdVDPCheckBox.Value = 1;                
                ThresholdVDPCheckBoxValueChanged(app);
            elseif strcmp(app.DefectMapDropDown.Value,'Linear Binning') && app.LinearBinningMethodCheckBox_vent.Value == 0
                DDI_message(app);
                app.LinearBinningMethodCheckBox_vent.Value = 1;
                LinearBinningMethodCheckBox_ventValueChanged(app);
            elseif strcmp(app.DefectMapDropDown.Value,'Kmeans') && app.kmeansCheckBox.Value == 0
                DDI_message(app);
                app.kmeansCheckBox.Value = 1;
            end
        end

        % Button pushed function: DDI2DMapButton
        function DDI2DMapButtonPushed(app, event)
            if app.DDI2DCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.viewing_img = app.Ventilation.DDI2Dmap; 
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'DDI 2D map'; pause(.1);
            end 
        end

        % Button pushed function: DDI3DMapButton
        function DDI3DMapButtonPushed(app, event)
            if app.DDI3DCheckBox.Value == 1 && app.VentilationAnalysisPerformed == 1
                app.viewing_img = app.Ventilation.DDI3Dmap; 
                app.viewing_img = permute(app.viewing_img, [1 2 4 3]);
                app.ImageType = 1; % 0 for gray image 1 for colored 
                app.viewing_img_max = max(app.viewing_img(:));
                app.sliceSlider.Limits = [1 size(app.viewing_img,3)]; 
                app.colorbar_fig = app.UIAxes_colorbar;                                       
                app.sliceSlider.Value = round(size(app.viewing_img,3)/2); %app.orthoViewer.SliceNumbers(3); %size(app.viewing_img,3); 
                app.viewing_img2 = app.viewing_img;
                
                % view images 
                Update_orthoViewer(app,app.viewing_img); 
                app.ImagesbeingviewedEditField.Value = 'DDI 3D map'; pause(.1);
            end             
        end

        % Value changed function: AnalysisExecutionModeDropDown
        function AnalysisExecutionModeDropDownValueChanged(app, event)
            switch app.AnalysisExecutionModeDropDown.Value
                case 'Perform each step individually'   
                    if app.VentilationAnalysisPerformed == 0 && app.DiffusionAnalysisPerformed == 0 && app.GasExchangeAnalysisPerformed == 0
                        app.PerformRegistrationButton.Enable = 'off';
                        app.TransformTypeButtonGroup.Enable = 'off'; 
                        app.ImageResolutionmmPanel.Enable = 'off'; 
                        app.SliceSelectionCheckBox.Enable = 'off'; 
                        app.SegmentationMethodDropDown.Enable = 'off'; 
                        app.ThresholdImageSegmentationPanel.Enable = 'off';  
                        app.ManualImageSegmentationPanel.Enable = 'off';
                        app.LoadPerformSegmentationButton.Enable = 'off';
                        app.LoadSegmentedMaskButton.Enable = 'off'; 
                        app.EditMaskButton.Enable = 'off'; 
                        app.ViewImagesPanel.Enable = 'off'; 
                        app.ViewImagesPanel_2.Enable = 'off'; 
                        app.ViewMaskPanel.Enable = 'off'; 
                        app.PerformAnalysisButton.Enable = 'off'; 
                    end                    
                case 'Perform all steps collectively'
                    %app.PerformRegistrationButton.Enable = 'on';
                    app.TransformTypeButtonGroup.Enable = 'on'; 
                    app.ImageResolutionmmPanel.Enable = 'on'; 
                    app.SliceSelectionCheckBox.Enable = 'off'; 
                    app.SegmentationMethodDropDown.Enable = 'on'; 
                    SegmentationMethodDropDownValueChanged(app, event)
                    app.ManualImageSegmentationPanel.Enable = 'on';
                    app.ThresholdImageSegmentationPanel.Enable = 'off';                    
                    %app.LoadPerformSegmentationButton.Enable = 'on';
                    %app.EditMaskButton.Enable = 'on'; 
                    %app.PerformAnalysisButton.Enable = 'on'; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HPXeMRIAnalysisApp = uifigure('Visible', 'off');
            app.HPXeMRIAnalysisApp.Name = 'UI Figure';
            app.AboutMenu = uimenu(app.HPXeMRIAnalysisApp);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.StartFreshMenu = uimenu(app.HPXeMRIAnalysisApp);
            app.StartFreshMenu.MenuSelectedFcn = createCallbackFcn(app, @StartFreshMenuSelected, true);
            app.StartFreshMenu.Text = 'Start Fresh';
            app.ScreenShotMenu = uimenu(app.HPXeMRIAnalysisApp);
            app.ScreenShotMenu.MenuSelectedFcn = createCallbackFcn(app, @ScreenShotMenuSelected, true);
            app.ScreenShotMenu.Text = 'ScreenShot';
            app.takescreenshotMenu = uimenu(app.ScreenShotMenu);
            app.takescreenshotMenu.MenuSelectedFcn = createCallbackFcn(app, @takescreenshotMenuSelected, true);
            app.takescreenshotMenu.Text = 'takescreenshot';
            app.HelpMenu = uimenu(app.HPXeMRIAnalysisApp);
            app.HelpMenu.Text = 'Help';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.JournalReferenceMenu = uimenu(app.HelpMenu);
            app.JournalReferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @JournalReferenceMenuSelected, true);
            app.JournalReferenceMenu.Text = 'Journal Reference';
            app.SourceCodeGithubMenu = uimenu(app.HelpMenu);
            app.SourceCodeGithubMenu.MenuSelectedFcn = createCallbackFcn(app, @SourceCodeGithubMenuSelected, true);
            app.SourceCodeGithubMenu.Text = 'Source Code (Github)';
            app.ExitMenu = uimenu(app.HPXeMRIAnalysisApp);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ImageAxes = uiaxes(app.HPXeMRIAnalysisApp);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.TabGroup = uitabgroup(app.HPXeMRIAnalysisApp);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.PerformCalibrationPanel = uipanel(app.CalibrationTab);
            app.PerformCalibrationPanel.Title = 'Perform Calibration';
            app.PerformCalibrationButton = uibutton(app.PerformCalibrationPanel, 'push');
            app.PerformCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformCalibrationButtonPushed, true);
            app.PerformCalibrationButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.PerformCalibrationButton.Enable = 'off';
            app.PerformCalibrationButton.Text = 'Perform Calibration';
            app.SelectDataButton = uibutton(app.PerformCalibrationPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Icon = fullfile(pathToMLAPP, 'Icons', 'datalocation2.png');
            app.SelectDataButton.Text = 'Select Data';
            app.ResultPlotsPanel = uipanel(app.CalibrationTab);
            app.ResultPlotsPanel.Title = 'Result Plots';
            app.FA_Axes = uiaxes(app.ResultPlotsPanel);
            title(app.FA_Axes, 'Flip Angle Determination')
            xlabel(app.FA_Axes, 'RF Pulse Number')
            ylabel(app.FA_Axes, 'NMR Signal Intensity (a.u.)')
            app.Diss_Axes = uiaxes(app.ResultPlotsPanel);
            title(app.Diss_Axes, 'Dissolved Phase Spectrum (TE90)')
            xlabel(app.Diss_Axes, 'Frequency (Hz)')
            ylabel(app.Diss_Axes, 'NMR Signal Intensity (a.u.)')
            app.Diss_Axes.XLim = [-10000 2500];
            app.Diss_Axes.XDir = 'reverse';
            app.Spct_Axes = uiaxes(app.ResultPlotsPanel);
            title(app.Spct_Axes, 'Spectrum (Components)')
            xlabel(app.Spct_Axes, 'Frequency (Hz)')
            ylabel(app.Spct_Axes, 'NMR Signal Intensity (a.u.)')
            app.Spct_Axes.XLim = [-6000 2500];
            app.Spct_Axes.XDir = 'reverse';
            app.Gas_Axes = uiaxes(app.ResultPlotsPanel);
            title(app.Gas_Axes, 'Gas Phase Spectrum ')
            xlabel(app.Gas_Axes, 'Frequency (Hz)')
            ylabel(app.Gas_Axes, 'NMR Signal Intensity (a.u.)')
            app.Gas_Axes.XLim = [-1500 1500];
            app.Gas_Axes.XDir = 'reverse';
            app.FA_Text = uilabel(app.ResultPlotsPanel);
            app.FA_Text.Text = {''; ''};
            app.Gas_Text = uilabel(app.ResultPlotsPanel);
            app.Gas_Text.Text = {''; ''};
            app.Diss_Text = uilabel(app.ResultPlotsPanel);
            app.Diss_Text.Text = {''; ''};
            app.InputsandAssumptionsPanel = uipanel(app.CalibrationTab);
            app.InputsandAssumptionsPanel.Title = 'Inputs and Assumptions';
            app.VentilationExcitationsEditFieldLabel = uilabel(app.InputsandAssumptionsPanel);
            app.VentilationExcitationsEditFieldLabel.Text = 'Ventilation Excitations';
            app.VentilationExcitationsEditField = uieditfield(app.InputsandAssumptionsPanel, 'numeric');
            app.VentilationExcitationsEditField.Limits = [0 Inf];
            app.VentilationExcitationsEditField.ValueChangedFcn = createCallbackFcn(app, @VentilationExcitationsEditFieldValueChanged, true);
            app.VentilationExcitationsEditField.Value = 72;
            app.DiffusionExcitationsEditFieldLabel = uilabel(app.InputsandAssumptionsPanel);
            app.DiffusionExcitationsEditFieldLabel.Text = 'Diffusion Excitations';
            app.DiffusionExcitationsEditField = uieditfield(app.InputsandAssumptionsPanel, 'numeric');
            app.DiffusionExcitationsEditField.Limits = [0 Inf];
            app.DiffusionExcitationsEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusionExcitationsEditFieldValueChanged, true);
            app.DiffusionExcitationsEditField.Value = 42;
            app.DiffusionbvaluesEditFieldLabel = uilabel(app.InputsandAssumptionsPanel);
            app.DiffusionbvaluesEditFieldLabel.Text = 'Diffusion b values';
            app.DiffusionbvaluesEditField = uieditfield(app.InputsandAssumptionsPanel, 'numeric');
            app.DiffusionbvaluesEditField.Limits = [0 Inf];
            app.DiffusionbvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusionbvaluesEditFieldValueChanged, true);
            app.DiffusionbvaluesEditField.Value = 4;
            app.CalAssumptionsTextArea = uitextarea(app.InputsandAssumptionsPanel);
            app.CalAssumptionsTextArea.Editable = 'off';
            app.CalResultsPanel = uipanel(app.CalibrationTab);
            app.CalResultsPanel.Title = 'Results';
            app.UICalTable = uitable(app.CalResultsPanel);
            app.UICalTable.ColumnName = {'Initial/ optimal'; 'Scaled'};
            app.UICalTable.RowName = {'scaled factor,R.voltage,Frequncy,TE90,Vent FA,Diff FA,GasEx gas FA,GasEx diss FA'};
            app.SaveResultButton = uibutton(app.CalResultsPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed2, true);
            app.SaveResultButton.Icon = fullfile(pathToMLAPP, 'Icons', 'saveresult.png');
            app.SaveResultButton.Enable = 'off';
            app.SaveResultButton.Text = 'Save Result';
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.ImageAnalysisTabs = uitabgroup(app.ImageAnalysisTab);
            app.LoadDataTab = uitab(app.ImageAnalysisTabs);
            app.LoadDataTab.Title = 'LoadData';
            app.LoadReadDataButton = uibutton(app.LoadDataTab, 'push');
            app.LoadReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReadDataButtonPushed, true);
            app.LoadReadDataButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.LoadReadDataButton.Enable = 'off';
            app.LoadReadDataButton.Text = 'Load/Read Data';
            app.XenonImagesPanel = uipanel(app.LoadDataTab);
            app.XenonImagesPanel.Title = 'Xenon Images';
            app.SelectGasDataButton = uibutton(app.XenonImagesPanel, 'push');
            app.SelectGasDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGasDataButtonPushed, true);
            app.SelectGasDataButton.Icon = fullfile(pathToMLAPP, 'Icons', 'datalocation2.png');
            app.SelectGasDataButton.Text = 'Select Gas Data';
            app.DenoiseXeImageCheckBox = uicheckbox(app.XenonImagesPanel);
            app.DenoiseXeImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenoiseXeImageCheckBoxValueChanged, true);
            app.DenoiseXeImageCheckBox.Text = 'Denoise Xe Image';
            app.DiffAcqOrderDropDownLabel = uilabel(app.XenonImagesPanel);
            app.DiffAcqOrderDropDownLabel.Text = 'Diff Acq. Order';
            app.DiffAcqOrderDropDown = uidropdown(app.XenonImagesPanel);
            app.DiffAcqOrderDropDown.Items = {'b-value interleave', 'slice interleave'};
            app.DiffAcqOrderDropDown.Enable = 'off';
            app.DiffAcqOrderDropDown.Value = 'b-value interleave';
            app.DenoiseSDEditFieldLabel = uilabel(app.XenonImagesPanel);
            app.DenoiseSDEditFieldLabel.Text = 'Denoise SD:';
            app.DenoiseSDEditField = uieditfield(app.XenonImagesPanel, 'text');
            app.DenoiseSDEditField.Enable = 'off';
            app.DenoiseSDEditField.Value = '0.02';
            app.NofbvaluesEditField_2Label = uilabel(app.XenonImagesPanel);
            app.NofbvaluesEditField_2Label.Text = 'N. of b-values';
            app.NofbvaluesEditField = uieditfield(app.XenonImagesPanel, 'numeric');
            app.NofbvaluesEditField.Limits = [2 20];
            app.NofbvaluesEditField.Enable = 'off';
            app.NofbvaluesEditField.Value = 5;
            app.ProtonImagesPanel = uipanel(app.LoadDataTab);
            app.ProtonImagesPanel.Title = 'Proton Images';
            app.SelectAnatomicalDataButton = uibutton(app.ProtonImagesPanel, 'push');
            app.SelectAnatomicalDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAnatomicalDataButtonPushed, true);
            app.SelectAnatomicalDataButton.Icon = fullfile(pathToMLAPP, 'Icons', 'datalocation2.png');
            app.SelectAnatomicalDataButton.Text = 'Select Anatomical Data';
            app.NoProtonImagesCheckBox = uicheckbox(app.ProtonImagesPanel);
            app.NoProtonImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoProtonImagesCheckBoxValueChanged, true);
            app.NoProtonImagesCheckBox.Text = 'No Proton Images';
            app.ViewImagesPanel = uipanel(app.LoadDataTab);
            app.ViewImagesPanel.Enable = 'off';
            app.ViewImagesPanel.Title = 'View Images';
            app.ImagestoviewDropDownLabel = uilabel(app.ViewImagesPanel);
            app.ImagestoviewDropDownLabel.Text = 'Images to view';
            app.ImagestoviewDropDown = uidropdown(app.ViewImagesPanel);
            app.ImagestoviewDropDown.Items = {'Ventilation', 'Diffusion', 'GasExchange: Ventilation', 'GasExchange: Gas', 'GasExchange: Dissolved', 'Proton Images'};
            app.ImagestoviewDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagestoviewDropDownValueChanged, true);
            app.ImagestoviewDropDown.Value = 'Ventilation';
            app.ViewButtonLoadData = uibutton(app.ViewImagesPanel, 'push');
            app.ViewButtonLoadData.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonLoadDataPushed, true);
            app.ViewButtonLoadData.Icon = fullfile(pathToMLAPP, 'Icons', 'view2.png');
            app.ViewButtonLoadData.Text = 'View';
            app.AnalysisExecutionModeLabel = uilabel(app.LoadDataTab);
            app.AnalysisExecutionModeLabel.Text = 'Analysis Execution Mode:';
            app.AnalysisExecutionModeDropDown = uidropdown(app.LoadDataTab);
            app.AnalysisExecutionModeDropDown.Items = {'Perform each step individually', 'Perform all steps collectively'};
            app.AnalysisExecutionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisExecutionModeDropDownValueChanged, true);
            app.AnalysisExecutionModeDropDown.Value = 'Perform each step individually';
            app.RegistrationTab = uitab(app.ImageAnalysisTabs);
            app.RegistrationTab.Title = 'Registration';
            app.ImageResolutionmmPanel = uipanel(app.RegistrationTab);
            app.ImageResolutionmmPanel.Enable = 'off';
            app.ImageResolutionmmPanel.Title = 'Image Resolution (mm)';
            app.XePixel1EditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.XePixel1EditFieldLabel.Text = 'XePixel1';
            app.XePixel1EditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.XePixel1EditField.Limits = [0 100];
            app.XePixel1EditField.Enable = 'off';
            app.XePixel1EditField.Value = 3;
            app.XePixel2EditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.XePixel2EditFieldLabel.Text = 'XePixel2';
            app.XePixel2EditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.XePixel2EditField.Limits = [0 100];
            app.XePixel2EditField.Enable = 'off';
            app.XePixel2EditField.Value = 3;
            app.XeSliceThicnessEditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.XeSliceThicnessEditFieldLabel.Text = 'XeSliceThicness';
            app.XeSliceThicnessEditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.XeSliceThicnessEditField.Limits = [0 100];
            app.XeSliceThicnessEditField.Enable = 'off';
            app.XeSliceThicnessEditField.Value = 15;
            app.HPixel1EditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.HPixel1EditFieldLabel.Text = 'HPixel1';
            app.HPixel1EditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.HPixel1EditField.Limits = [0 100];
            app.HPixel1EditField.Enable = 'off';
            app.HPixel1EditField.Value = 3;
            app.HPixel2EditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.HPixel2EditFieldLabel.Text = 'HPixel2';
            app.HPixel2EditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.HPixel2EditField.Limits = [0 100];
            app.HPixel2EditField.Enable = 'off';
            app.HPixel2EditField.Value = 3;
            app.HSliceThicnessEditFieldLabel = uilabel(app.ImageResolutionmmPanel);
            app.HSliceThicnessEditFieldLabel.Text = 'HSliceThicness';
            app.HSliceThicnessEditField = uieditfield(app.ImageResolutionmmPanel, 'numeric');
            app.HSliceThicnessEditField.Limits = [0 100];
            app.HSliceThicnessEditField.Enable = 'off';
            app.HSliceThicnessEditField.Value = 15;
            app.UseRatioCheckBox = uicheckbox(app.ImageResolutionmmPanel);
            app.UseRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseRatioCheckBoxValueChanged, true);
            app.UseRatioCheckBox.Text = 'Use Ratio';
            app.UseRatioCheckBox.Value = true;
            app.PerformRegistrationButton = uibutton(app.RegistrationTab, 'push');
            app.PerformRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformRegistrationButtonPushed, true);
            app.PerformRegistrationButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.PerformRegistrationButton.Enable = 'off';
            app.PerformRegistrationButton.Text = 'Perform Registration';
            app.ViewImagesPanel_2 = uipanel(app.RegistrationTab);
            app.ViewImagesPanel_2.Enable = 'off';
            app.ViewImagesPanel_2.Title = 'View Images';
            app.ImagestoviewDropDown_2Label = uilabel(app.ViewImagesPanel_2);
            app.ImagestoviewDropDown_2Label.Text = 'Images to view';
            app.ImagestoviewRegistrationDropDown = uidropdown(app.ViewImagesPanel_2);
            app.ImagestoviewRegistrationDropDown.Items = {'Proton & Xe Registered', 'Proton Registered'};
            app.ImagestoviewRegistrationDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagestoviewRegistrationDropDownValueChanged, true);
            app.ImagestoviewRegistrationDropDown.Value = 'Proton & Xe Registered';
            app.ViewButtonRegisteration = uibutton(app.ViewImagesPanel_2, 'push');
            app.ViewButtonRegisteration.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonRegisterationPushed, true);
            app.ViewButtonRegisteration.Icon = fullfile(pathToMLAPP, 'Icons', 'view2.png');
            app.ViewButtonRegisteration.Text = 'View';
            app.TransformTypeButtonGroup = uibuttongroup(app.RegistrationTab);
            app.TransformTypeButtonGroup.Enable = 'off';
            app.TransformTypeButtonGroup.Title = 'Transform Type';
            app.translationButton = uitogglebutton(app.TransformTypeButtonGroup);
            app.translationButton.Text = 'translation';
            app.rigidButton = uitogglebutton(app.TransformTypeButtonGroup);
            app.rigidButton.Text = 'rigid';
            app.similarityButton = uitogglebutton(app.TransformTypeButtonGroup);
            app.similarityButton.Text = 'similarity';
            app.affineButton = uitogglebutton(app.TransformTypeButtonGroup);
            app.affineButton.Text = 'affine';
            app.affineButton.Value = true;
            app.SliceSeclectionPanel = uipanel(app.RegistrationTab);
            app.SliceSeclectionPanel.Enable = 'off';
            app.SliceSeclectionPanel.Title = 'Slice Seclection';
            app.XestartLabel = uilabel(app.SliceSeclectionPanel);
            app.XestartLabel.Text = 'Xe start';
            app.XestartEditField = uieditfield(app.SliceSeclectionPanel, 'numeric');
            app.XestartEditField.Limits = [0 100];
            app.XestartEditField.Value = 3;
            app.XeendLabel = uilabel(app.SliceSeclectionPanel);
            app.XeendLabel.Text = 'Xe end';
            app.XeendEditField = uieditfield(app.SliceSeclectionPanel, 'numeric');
            app.XeendEditField.Limits = [0 100];
            app.XeendEditField.Value = 3;
            app.HstartLabel = uilabel(app.SliceSeclectionPanel);
            app.HstartLabel.Text = 'H start';
            app.HstartEditField = uieditfield(app.SliceSeclectionPanel, 'numeric');
            app.HstartEditField.Limits = [0 100];
            app.HstartEditField.Value = 3;
            app.HendLabel = uilabel(app.SliceSeclectionPanel);
            app.HendLabel.Text = 'H end';
            app.HendEditField = uieditfield(app.SliceSeclectionPanel, 'numeric');
            app.HendEditField.Limits = [0 100];
            app.HendEditField.Value = 3;
            app.SliceSelectionCheckBox = uicheckbox(app.RegistrationTab);
            app.SliceSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SliceSelectionCheckBoxValueChanged, true);
            app.SliceSelectionCheckBox.Text = 'Slice Selection';
            app.SegmentationTab = uitab(app.ImageAnalysisTabs);
            app.SegmentationTab.Title = 'Segmentation';
            app.ViewMaskPanel = uipanel(app.SegmentationTab);
            app.ViewMaskPanel.Enable = 'off';
            app.ViewMaskPanel.Title = 'View Mask';
            app.MasktoviewDropDownLabel = uilabel(app.ViewMaskPanel);
            app.MasktoviewDropDownLabel.Text = 'Mask to view';
            app.MasktoviewDropDown = uidropdown(app.ViewMaskPanel);
            app.MasktoviewDropDown.Items = {'Lung Mask', 'Airway Mask', 'Lung & Airway Mask'};
            app.MasktoviewDropDown.ValueChangedFcn = createCallbackFcn(app, @MasktoviewDropDownValueChanged, true);
            app.MasktoviewDropDown.Value = 'Lung Mask';
            app.ViewButtonSegment = uibutton(app.ViewMaskPanel, 'push');
            app.ViewButtonSegment.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonSegmentPushed, true);
            app.ViewButtonSegment.Icon = fullfile(pathToMLAPP, 'Icons', 'view2.png');
            app.ViewButtonSegment.Text = 'View';
            app.AutoImageSegmentationAIPanel = uipanel(app.SegmentationTab);
            app.AutoImageSegmentationAIPanel.Enable = 'off';
            app.AutoImageSegmentationAIPanel.Title = 'Auto Image Segmentation (AI)';
            app.SliceOrientationButtonGroup = uibuttongroup(app.AutoImageSegmentationAIPanel);
            app.SliceOrientationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SliceOrientationButtonGroupSelectionChanged, true);
            app.SliceOrientationButtonGroup.Title = 'Slice Orientation';
            app.coronalButton = uitogglebutton(app.SliceOrientationButtonGroup);
            app.coronalButton.Text = 'coronal';
            app.coronalButton.Value = true;
            app.transversalButton = uitogglebutton(app.SliceOrientationButtonGroup);
            app.transversalButton.Text = 'transversal';
            app.isotropicButton = uitogglebutton(app.SliceOrientationButtonGroup);
            app.isotropicButton.Text = 'isotropic';
            app.sagittalButton = uitogglebutton(app.SliceOrientationButtonGroup);
            app.sagittalButton.Text = 'sagittal';
            app.SegmentationMethodDropDownLabel = uilabel(app.SegmentationTab);
            app.SegmentationMethodDropDownLabel.Text = 'Segmentation Method';
            app.SegmentationMethodDropDown = uidropdown(app.SegmentationTab);
            app.SegmentationMethodDropDown.Items = {'Manual', 'Threshold', 'Auto', 'Load Existing Mask'};
            app.SegmentationMethodDropDown.Editable = 'on';
            app.SegmentationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.SegmentationMethodDropDown.Enable = 'off';
            app.SegmentationMethodDropDown.Value = 'Manual';
            app.ThresholdImageSegmentationPanel = uipanel(app.SegmentationTab);
            app.ThresholdImageSegmentationPanel.Enable = 'off';
            app.ThresholdImageSegmentationPanel.Title = 'Threshold Image Segmentation';
            app.ThresholdSpinnerLabel = uilabel(app.ThresholdImageSegmentationPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.ThresholdImageSegmentationPanel);
            app.ThresholdSpinner.Step = 0.2;
            app.ThresholdSpinner.Limits = [0 100];
            app.ThresholdSpinner.Value = 1;
            app.ErosionSpinnerLabel = uilabel(app.ThresholdImageSegmentationPanel);
            app.ErosionSpinnerLabel.Text = 'Erosion';
            app.ErosionSpinner = uispinner(app.ThresholdImageSegmentationPanel);
            app.ErosionSpinner.Step = 0.2;
            app.ErosionSpinner.Limits = [0 100];
            app.ErosionSpinner.Value = 1;
            app.SelectAnatomyButtonGroup_threshold = uibuttongroup(app.ThresholdImageSegmentationPanel);
            app.SelectAnatomyButtonGroup_threshold.Title = 'Select Anatomy';
            app.ParenchymaButton = uitogglebutton(app.SelectAnatomyButtonGroup_threshold);
            app.ParenchymaButton.Text = 'Parenchyma';
            app.ParenchymaButton.Value = true;
            app.AirwayButton = uitogglebutton(app.SelectAnatomyButtonGroup_threshold);
            app.AirwayButton.Text = 'Airway';
            app.LoadPerformSegmentationButton = uibutton(app.SegmentationTab, 'push');
            app.LoadPerformSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPerformSegmentationButtonPushed, true);
            app.LoadPerformSegmentationButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.LoadPerformSegmentationButton.Enable = 'off';
            app.LoadPerformSegmentationButton.Text = 'Load/Perform Segmentation';
            app.LoadSegmentedMaskButton = uibutton(app.SegmentationTab, 'push');
            app.LoadSegmentedMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentedMaskButtonPushed, true);
            app.LoadSegmentedMaskButton.Enable = 'off';
            app.LoadSegmentedMaskButton.Text = 'Load Segmented Mask';
            app.ImagestosegmentDropDownLabel = uilabel(app.SegmentationTab);
            app.ImagestosegmentDropDownLabel.Text = 'Images to segment';
            app.ImagestosegmentDropDown = uidropdown(app.SegmentationTab);
            app.ImagestosegmentDropDown.Items = {'Xe & Proton Registered', 'Xenon', 'Registered Proton'};
            app.ImagestosegmentDropDown.Value = 'Xe & Proton Registered';
            app.EditMaskButton = uibutton(app.SegmentationTab, 'push');
            app.EditMaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditMaskButtonPushed, true);
            app.EditMaskButton.Icon = fullfile(pathToMLAPP, 'Icons', 'edit.png');
            app.EditMaskButton.Enable = 'off';
            app.EditMaskButton.Text = 'Edit Mask';
            app.ManualImageSegmentationPanel = uipanel(app.SegmentationTab);
            app.ManualImageSegmentationPanel.Enable = 'off';
            app.ManualImageSegmentationPanel.Title = 'Manual Image Segmentation';
            app.SelectAnatomyButtonGroup_maunal = uibuttongroup(app.ManualImageSegmentationPanel);
            app.SelectAnatomyButtonGroup_maunal.Title = 'Select Anatomy';
            app.ParenchymaButton_maunal = uitogglebutton(app.SelectAnatomyButtonGroup_maunal);
            app.ParenchymaButton_maunal.Text = 'Parenchyma';
            app.ParenchymaButton_maunal.Value = true;
            app.AirwayButton_manual = uitogglebutton(app.SelectAnatomyButtonGroup_maunal);
            app.AirwayButton_manual.Text = 'Airway';
            app.MethodButtonGroup_maunal = uibuttongroup(app.ManualImageSegmentationPanel);
            app.MethodButtonGroup_maunal.Title = 'Method';
            app.FreehandButton = uitogglebutton(app.MethodButtonGroup_maunal);
            app.FreehandButton.Text = 'Freehand';
            app.FreehandButton.Value = true;
            app.AppSegmenterButton = uitogglebutton(app.MethodButtonGroup_maunal);
            app.AppSegmenterButton.Text = 'AppSegmenter';
            app.PostProcessTab = uitab(app.ImageAnalysisTabs);
            app.PostProcessTab.Title = 'Post-Process';
            app.PerformAnalysisButton = uibutton(app.PostProcessTab, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.PerformAnalysisButton.Enable = 'off';
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.TabGroup4 = uitabgroup(app.PostProcessTab);
            app.VentilationTab = uitab(app.TabGroup4);
            app.VentilationTab.Title = 'Ventilation';
            app.VentilationDefectPercentageVDPPanel = uipanel(app.VentilationTab);
            app.VentilationDefectPercentageVDPPanel.Title = 'Ventilation Defect Percentage (VDP)';
            app.LinearBinningMethodPanel = uipanel(app.VentilationDefectPercentageVDPPanel);
            app.LinearBinningMethodPanel.Enable = 'off';
            app.LinearBinningMethodPanel.Title = 'Linear Binning Method';
            app.MeanLabel = uilabel(app.LinearBinningMethodPanel);
            app.MeanLabel.Text = 'Mean:';
            app.MeanEditField = uieditfield(app.LinearBinningMethodPanel, 'numeric');
            app.MeanEditField.Enable = 'off';
            app.MeanEditField.Value = 0.5421;
            app.SDLabel = uilabel(app.LinearBinningMethodPanel);
            app.SDLabel.Text = 'SD:';
            app.SDEditField = uieditfield(app.LinearBinningMethodPanel, 'numeric');
            app.SDEditField.Enable = 'off';
            app.SDEditField.Value = 0.15;
            app.SDN4Label = uilabel(app.LinearBinningMethodPanel);
            app.SDN4Label.Text = 'SD (N4):';
            app.SDN4EditField = uieditfield(app.LinearBinningMethodPanel, 'numeric');
            app.SDN4EditField.Value = 0.14;
            app.MeanN4EditFieldLabel = uilabel(app.LinearBinningMethodPanel);
            app.MeanN4EditFieldLabel.Text = 'Mean (N4):';
            app.MeanN4EditField = uieditfield(app.LinearBinningMethodPanel, 'numeric');
            app.MeanN4EditField.Value = 0.68;
            app.ThresholdMethodPanel = uipanel(app.VentilationDefectPercentageVDPPanel);
            app.ThresholdMethodPanel.Title = 'Threshold Method';
            app.DefectLabel = uilabel(app.ThresholdMethodPanel);
            app.DefectLabel.Text = 'Defect (%):';
            app.DefectEditField = uieditfield(app.ThresholdMethodPanel, 'numeric');
            app.DefectEditField.Value = 15;
            app.HyperLabel = uilabel(app.ThresholdMethodPanel);
            app.HyperLabel.Text = 'Hyper (%):';
            app.HyperEditField = uieditfield(app.ThresholdMethodPanel, 'numeric');
            app.HyperEditField.Value = 200;
            app.IncompelteLabel = uilabel(app.ThresholdMethodPanel);
            app.IncompelteLabel.Text = 'Incomplete (%):';
            app.IncompleteEditField = uieditfield(app.ThresholdMethodPanel, 'numeric');
            app.IncompleteEditField.Value = 60;
            app.ThresholdVDPCheckBox = uicheckbox(app.VentilationDefectPercentageVDPPanel);
            app.ThresholdVDPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdVDPCheckBoxValueChanged, true);
            app.ThresholdVDPCheckBox.Text = 'Threshold VDP';
            app.ThresholdVDPCheckBox.Value = true;
            app.LinearBinningMethodCheckBox_vent = uicheckbox(app.VentilationDefectPercentageVDPPanel);
            app.LinearBinningMethodCheckBox_vent.ValueChangedFcn = createCallbackFcn(app, @LinearBinningMethodCheckBox_ventValueChanged, true);
            app.LinearBinningMethodCheckBox_vent.Text = 'Linear Binning VDP';
            app.kmeansCheckBox = uicheckbox(app.VentilationDefectPercentageVDPPanel);
            app.kmeansCheckBox.ValueChangedFcn = createCallbackFcn(app, @kmeansCheckBoxValueChanged, true);
            app.kmeansCheckBox.Text = 'K-means VDP';
            app.BiasFieldCorrectionCheckBox = uicheckbox(app.VentilationTab);
            app.BiasFieldCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BiasFieldCorrectionCheckBoxValueChanged, true);
            app.BiasFieldCorrectionCheckBox.Text = 'Bias Field Correction';
            app.BiasFieldCorrectionCheckBox.Value = true;
            app.TextureAnalysisPanel = uipanel(app.VentilationTab);
            app.TextureAnalysisPanel.Title = 'Texture Analysis';
            app.TextureAnalysisCheckBox = uicheckbox(app.TextureAnalysisPanel);
            app.TextureAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextureAnalysisCheckBoxValueChanged, true);
            app.TextureAnalysisCheckBox.Text = 'Texture Analysis';
            app.DefectDistributionIndexDDIPanel = uipanel(app.VentilationTab);
            app.DefectDistributionIndexDDIPanel.Title = 'Defect Distribution Index (DDI)';
            app.DDI2DCheckBox = uicheckbox(app.DefectDistributionIndexDDIPanel);
            app.DDI2DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DDI2DCheckBoxValueChanged, true);
            app.DDI2DCheckBox.Text = '2D DDI';
            app.DDI3DCheckBox = uicheckbox(app.DefectDistributionIndexDDIPanel);
            app.DDI3DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DDI3DCheckBoxValueChanged, true);
            app.DDI3DCheckBox.Text = '3D DDI';
            app.VoxelSizeLabel = uilabel(app.DefectDistributionIndexDDIPanel);
            app.VoxelSizeLabel.Text = 'Voxel Size:';
            app.XLabel = uilabel(app.DefectDistributionIndexDDIPanel);
            app.XLabel.Text = 'X(mm)';
            app.DDI_X = uieditfield(app.DefectDistributionIndexDDIPanel, 'numeric');
            app.DDI_X.Enable = 'off';
            app.DDI_X.Value = 3;
            app.XLabel_2 = uilabel(app.DefectDistributionIndexDDIPanel);
            app.XLabel_2.Text = 'Y(mm)';
            app.DDI_Y = uieditfield(app.DefectDistributionIndexDDIPanel, 'numeric');
            app.DDI_Y.Enable = 'off';
            app.DDI_Y.Value = 3;
            app.XLabel_3 = uilabel(app.DefectDistributionIndexDDIPanel);
            app.XLabel_3.Text = 'Z(mm)';
            app.DDI_Z = uieditfield(app.DefectDistributionIndexDDIPanel, 'numeric');
            app.DDI_Z.Enable = 'off';
            app.DDI_Z.Value = 15;
            app.DefectMapDropDownLabel = uilabel(app.DefectDistributionIndexDDIPanel);
            app.DefectMapDropDownLabel.Text = 'Defect Map:';
            app.DefectMapDropDown = uidropdown(app.DefectDistributionIndexDDIPanel);
            app.DefectMapDropDown.Items = {'Threshold', 'Linear Binning', 'Kmeans'};
            app.DefectMapDropDown.ValueChangedFcn = createCallbackFcn(app, @DefectMapDropDownValueChanged, true);
            app.DefectMapDropDown.Enable = 'off';
            app.DefectMapDropDown.Value = 'Threshold';
            app.DiffusionTab = uitab(app.TabGroup4);
            app.DiffusionTab.Title = 'Diffusion';
            app.ADCAnalysisPanel = uipanel(app.DiffusionTab);
            app.ADCAnalysisPanel.Enable = 'off';
            app.ADCAnalysisPanel.Title = 'ADC Analysis';
            app.FittingTypeDropDownLabel = uilabel(app.ADCAnalysisPanel);
            app.FittingTypeDropDownLabel.Text = 'Fitting Type:';
            app.FittingTypeDropDown = uidropdown(app.ADCAnalysisPanel);
            app.FittingTypeDropDown.Items = {'Log Linear', 'Log Weighted Linear', 'Non-Linear', 'Bayesian'};
            app.FittingTypeDropDown.Value = 'Log Linear';
            app.ADCLinearBinningMethodPanel = uipanel(app.ADCAnalysisPanel);
            app.ADCLinearBinningMethodPanel.Enable = 'off';
            app.ADCLinearBinningMethodPanel.Title = 'ADC Linear Binning Method';
            app.MeanequationEditFieldLabel = uilabel(app.ADCLinearBinningMethodPanel);
            app.MeanequationEditFieldLabel.Text = 'Mean equation:';
            app.MeanequationEditField = uieditfield(app.ADCLinearBinningMethodPanel, 'text');
            app.MeanequationEditField.Value = '0.0002*age+0.029';
            app.SDequationEditFieldLabel = uilabel(app.ADCLinearBinningMethodPanel);
            app.SDequationEditFieldLabel.Text = 'SD equation:';
            app.SDequationEditField = uieditfield(app.ADCLinearBinningMethodPanel, 'text');
            app.SDequationEditField.Value = '5e-5*age+0.0121';
            app.LinearBinningMethoddiffCheckBox_diff = uicheckbox(app.ADCAnalysisPanel);
            app.LinearBinningMethoddiffCheckBox_diff.ValueChangedFcn = createCallbackFcn(app, @LinearBinningMethoddiffCheckBox_diffValueChanged, true);
            app.LinearBinningMethoddiffCheckBox_diff.Text = 'Linear Binning Method';
            app.MorphometryAnalysisPanel = uipanel(app.DiffusionTab);
            app.MorphometryAnalysisPanel.Enable = 'off';
            app.MorphometryAnalysisPanel.Title = 'Morphometry Analysis';
            app.CylindricalModelCMCheckBox = uicheckbox(app.MorphometryAnalysisPanel);
            app.CylindricalModelCMCheckBox.ValueChangedFcn = createCallbackFcn(app, @CylindricalModelCMCheckBoxValueChanged, true);
            app.CylindricalModelCMCheckBox.Text = 'Cylindrical Model (CM)';
            app.Docm2sEditFieldLabel = uilabel(app.MorphometryAnalysisPanel);
            app.Docm2sEditFieldLabel.Text = 'Do (cm2/s)';
            app.Docm2sEditField = uieditfield(app.MorphometryAnalysisPanel, 'numeric');
            app.Docm2sEditField.Limits = [0.001 0.14];
            app.Docm2sEditField.Value = 0.14;
            app.DeltamsEditFieldLabel = uilabel(app.MorphometryAnalysisPanel);
            app.DeltamsEditFieldLabel.Text = 'Delta (ms)';
            app.DeltamsEditField = uieditfield(app.MorphometryAnalysisPanel, 'numeric');
            app.DeltamsEditField.Limits = [1 20];
            app.DeltamsEditField.Value = 3.5;
            app.StudyTypeDropDownLabel = uilabel(app.MorphometryAnalysisPanel);
            app.StudyTypeDropDownLabel.Text = 'Study Type:';
            app.StudyTypeDropDown = uidropdown(app.MorphometryAnalysisPanel);
            app.StudyTypeDropDown.Items = {'human', 'animals'};
            app.StudyTypeDropDown.Value = 'human';
            app.StretchExponentialModelSEMCheckBox = uicheckbox(app.MorphometryAnalysisPanel);
            app.StretchExponentialModelSEMCheckBox.ValueChangedFcn = createCallbackFcn(app, @StretchExponentialModelSEMCheckBoxValueChanged, true);
            app.StretchExponentialModelSEMCheckBox.Text = 'Stretch Exponential Model (SEM)';
            app.bvaluesPanel = uipanel(app.DiffusionTab);
            app.bvaluesPanel.Enable = 'off';
            app.bvaluesPanel.Title = 'b-values';
            app.bvaluesscm2EditFieldLabel = uilabel(app.bvaluesPanel);
            app.bvaluesscm2EditFieldLabel.Text = 'b-values (s/cm2):';
            app.bvaluesscm2EditField = uieditfield(app.bvaluesPanel, 'text');
            app.bvaluesscm2EditField.Value = '[0, 6.25, 12.5, 18.75, 25]';
            app.MorphometryAnalysisCheckBox = uicheckbox(app.DiffusionTab);
            app.MorphometryAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @MorphometryAnalysisCheckBoxValueChanged, true);
            app.MorphometryAnalysisCheckBox.Enable = 'off';
            app.MorphometryAnalysisCheckBox.Text = 'Morphometry Analysis';
            app.GasExchangeTab = uitab(app.TabGroup4);
            app.GasExchangeTab.Title = 'GasExchange';
            app.ManualHealthyReferenceDistributionsPanel = uipanel(app.GasExchangeTab);
            app.ManualHealthyReferenceDistributionsPanel.Enable = 'off';
            app.ManualHealthyReferenceDistributionsPanel.Title = 'Manual Healthy Reference Distributions';
            app.VentMeanLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.VentMeanLabel.Text = 'Vent Mean:';
            app.VentMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.VentMeanEditField.Value = 0.51;
            app.VentSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.VentSDEditFieldLabel.Text = 'Vent SD:';
            app.VentSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.VentSDEditField.Value = 0.19;
            app.DissolvedMeanEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.DissolvedMeanEditFieldLabel.Text = 'Dissolved Mean:';
            app.DissolvedMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.DissolvedMeanEditField.Value = 0.0075;
            app.DissolvedSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.DissolvedSDEditFieldLabel.Text = 'Dissolved SD:';
            app.DissolvedSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.DissolvedSDEditField.Value = 0.00125;
            app.MembraneMeanLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.MembraneMeanLabel.Text = 'Membrane Mean:';
            app.MembraneMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.MembraneMeanEditField.Value = 0.0049;
            app.MembraneSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.MembraneSDEditFieldLabel.Text = 'Membrane SD:';
            app.MembraneSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.MembraneSDEditField.Value = 0.0015;
            app.RBCMeanEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCMeanEditFieldLabel.Text = 'RBC Mean:';
            app.RBCMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCMeanEditField.Value = 0.0026;
            app.RBCSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCSDEditFieldLabel.Text = 'RBC SD:';
            app.RBCSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCSDEditField.Value = 0.001;
            app.RBCBarrMeanEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCBarrMeanEditFieldLabel.Text = 'RBCBarr Mean:';
            app.RBCBarrMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCBarrMeanEditField.Value = 0.53;
            app.RBCBarrSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCBarrSDEditFieldLabel.Text = 'RBCBarr SD:';
            app.RBCBarrSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCBarrSDEditField.Value = 0.18;
            app.RBCOscMeanEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCOscMeanEditFieldLabel.Text = 'RBCOsc Mean:';
            app.RBCOscMeanEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCOscMeanEditField.Value = 8.9596;
            app.RBCOscSDEditFieldLabel = uilabel(app.ManualHealthyReferenceDistributionsPanel);
            app.RBCOscSDEditFieldLabel.Text = 'RBCOsc SD:';
            app.RBCOscSDEditField = uieditfield(app.ManualHealthyReferenceDistributionsPanel, 'numeric');
            app.RBCOscSDEditField.Value = 10.5608;
            app.ImportHealthyDistributionsButton = uibutton(app.GasExchangeTab, 'push');
            app.ImportHealthyDistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHealthyDistributionsButtonPushed, true);
            app.ImportHealthyDistributionsButton.Enable = 'off';
            app.ImportHealthyDistributionsButton.Text = 'Import Healthy Distributions';
            app.HealthyReferenceDropDownLabel = uilabel(app.GasExchangeTab);
            app.HealthyReferenceDropDownLabel.Text = 'Healthy Reference:';
            app.HealthyReferenceDropDown = uidropdown(app.GasExchangeTab);
            app.HealthyReferenceDropDown.Items = {'Default', 'Import', 'Manual'};
            app.HealthyReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @HealthyReferenceDropDownValueChanged, true);
            app.HealthyReferenceDropDown.Value = 'Default';
            app.ResultsTab = uitab(app.ImageAnalysisTabs);
            app.ResultsTab.Title = 'Results';
            app.TabGroup4_2 = uitabgroup(app.ResultsTab);
            app.VentilationTabResults = uitab(app.TabGroup4_2);
            app.VentilationTabResults.Title = 'Ventilation';
            app.TextureAnalysisGLRLMResultsPanel = uipanel(app.VentilationTabResults);
            app.TextureAnalysisGLRLMResultsPanel.Enable = 'off';
            app.TextureAnalysisGLRLMResultsPanel.Title = 'Texture Analysis (GLRLM) Results';
            app.SRELabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.SRELabel.Text = 'SRE:';
            app.SRETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.LRELabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.LRELabel.Text = 'LRE:';
            app.LRETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.GLNLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.GLNLabel.Text = 'GLN:';
            app.GLNTextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.RLNLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.RLNLabel.Text = 'RLN:';
            app.RLNTextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.RPLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.RPLabel.Text = 'RP:';
            app.RPTextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.MeanGLRLMMapButton = uibutton(app.TextureAnalysisGLRLMResultsPanel, 'push');
            app.MeanGLRLMMapButton.ButtonPushedFcn = createCallbackFcn(app, @MeanGLRLMMapButtonPushed, true);
            app.MeanGLRLMMapButton.Text = 'Mean GLRLM Map';
            app.HGRETextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.HGRETextAreaLabel.Text = 'HGRE:';
            app.HGRETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.SRLGRTextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.SRLGRTextAreaLabel.Text = 'SRLGR:';
            app.SRLGRTextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.SRHGETextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.SRHGETextAreaLabel.Text = 'SRHGE:';
            app.SRHGETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.LRLGETextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.LRLGETextAreaLabel.Text = 'LRLGE';
            app.LRLGETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.LRHGETextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.LRHGETextAreaLabel.Text = 'LRHGE';
            app.LRHGETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.LGRETextAreaLabel = uilabel(app.TextureAnalysisGLRLMResultsPanel);
            app.LGRETextAreaLabel.Text = 'LGRE:';
            app.LGRETextArea = uitextarea(app.TextureAnalysisGLRLMResultsPanel);
            app.ThresholdMethodResultsPanel = uipanel(app.VentilationTabResults);
            app.ThresholdMethodResultsPanel.Enable = 'off';
            app.ThresholdMethodResultsPanel.Title = 'Threshold Method Results';
            app.VDPLabel = uilabel(app.ThresholdMethodResultsPanel);
            app.VDPLabel.Text = 'VDP (%):';
            app.VDPTextArea = uitextarea(app.ThresholdMethodResultsPanel);
            app.NormalLabel = uilabel(app.ThresholdMethodResultsPanel);
            app.NormalLabel.Text = 'Normal (%):';
            app.NormalTextArea = uitextarea(app.ThresholdMethodResultsPanel);
            app.IncompleteLabel = uilabel(app.ThresholdMethodResultsPanel);
            app.IncompleteLabel.Text = 'Incomplete (%):';
            app.IncompleteTextArea = uitextarea(app.ThresholdMethodResultsPanel);
            app.CompleteTextAreaLabel = uilabel(app.ThresholdMethodResultsPanel);
            app.CompleteTextAreaLabel.Text = 'Complete (%)';
            app.CompleteTextArea = uitextarea(app.ThresholdMethodResultsPanel);
            app.HyperTextAreaLabel = uilabel(app.ThresholdMethodResultsPanel);
            app.HyperTextAreaLabel.Text = 'Hyper (%)';
            app.HyperTextArea = uitextarea(app.ThresholdMethodResultsPanel);
            app.VDPMapButton = uibutton(app.ThresholdMethodResultsPanel, 'push');
            app.VDPMapButton.ButtonPushedFcn = createCallbackFcn(app, @VDPMapButtonPushed, true);
            app.VDPMapButton.Text = 'VDP Map';
            app.VDPHistogramButton = uibutton(app.ThresholdMethodResultsPanel, 'push');
            app.VDPHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @VDPHistogramButtonPushed, true);
            app.VDPHistogramButton.Text = 'VDP Histogram';
            app.LinearBinningMethodResultsPanel = uipanel(app.VentilationTabResults);
            app.LinearBinningMethodResultsPanel.Enable = 'off';
            app.LinearBinningMethodResultsPanel.Title = 'Linear Binning Method Results';
            app.LBVDPTextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.LBVDPTextAreaLabel.Text = 'LB VDP (%):';
            app.LBVDPTextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.MeanTextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.MeanTextAreaLabel.Text = 'Mean:';
            app.MeanTextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.LBVDPMapButton = uibutton(app.LinearBinningMethodResultsPanel, 'push');
            app.LBVDPMapButton.ButtonPushedFcn = createCallbackFcn(app, @LBVDPMapButtonPushed, true);
            app.LBVDPMapButton.Text = 'LB VDP Map';
            app.LBVDPHistogramButton = uibutton(app.LinearBinningMethodResultsPanel, 'push');
            app.LBVDPHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @LBVDPHistogramButtonPushed, true);
            app.LBVDPHistogramButton.Text = 'LB VDP Histogram';
            app.Bin1TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin1TextAreaLabel.Text = 'Bin1';
            app.Bin1TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.Bin2TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin2TextAreaLabel.Text = 'Bin2';
            app.Bin2TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.Bin3TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin3TextAreaLabel.Text = 'Bin3';
            app.Bin3TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.Bin4TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin4TextAreaLabel.Text = 'Bin4';
            app.Bin4TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.Bin5TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin5TextAreaLabel.Text = 'Bin5';
            app.Bin5TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.Bin6TextAreaLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.Bin6TextAreaLabel.Text = 'Bin6';
            app.Bin6TextArea = uitextarea(app.LinearBinningMethodResultsPanel);
            app.DefectsLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.DefectsLabel.Text = 'Defects';
            app.HighLabel = uilabel(app.LinearBinningMethodResultsPanel);
            app.HighLabel.Text = 'High';
            app.KmeansResultsPanel = uipanel(app.VentilationTabResults);
            app.KmeansResultsPanel.Enable = 'off';
            app.KmeansResultsPanel.Title = 'K-means Results';
            app.kmeansVDPMapButton = uibutton(app.KmeansResultsPanel, 'push');
            app.kmeansVDPMapButton.ButtonPushedFcn = createCallbackFcn(app, @kmeansVDPMapButtonPushed, true);
            app.kmeansVDPMapButton.Text = 'k-means VDP Map';
            app.VDPTextArea_2Label = uilabel(app.KmeansResultsPanel);
            app.VDPTextArea_2Label.Text = 'VDP:';
            app.kmeansVDPTextArea = uitextarea(app.KmeansResultsPanel);
            app.DDIResultsPanel = uipanel(app.VentilationTabResults);
            app.DDIResultsPanel.Enable = 'off';
            app.DDIResultsPanel.Title = 'DDI Results';
            app.DDI2DMapButton = uibutton(app.DDIResultsPanel, 'push');
            app.DDI2DMapButton.ButtonPushedFcn = createCallbackFcn(app, @DDI2DMapButtonPushed, true);
            app.DDI2DMapButton.Enable = 'off';
            app.DDI2DMapButton.Text = '2D DDI Map';
            app.VDPTextArea_2Label_2 = uilabel(app.DDIResultsPanel);
            app.VDPTextArea_2Label_2.Text = '2D DDI';
            app.meanSD_2DDDITextArea = uitextarea(app.DDIResultsPanel);
            app.meanSD_2DDDITextArea.Enable = 'off';
            app.DDI3DMapButton = uibutton(app.DDIResultsPanel, 'push');
            app.DDI3DMapButton.ButtonPushedFcn = createCallbackFcn(app, @DDI3DMapButtonPushed, true);
            app.DDI3DMapButton.Enable = 'off';
            app.DDI3DMapButton.Text = '3D DDI Map';
            app.VDPTextArea_2Label_3 = uilabel(app.DDIResultsPanel);
            app.VDPTextArea_2Label_3.Text = '3D DDI';
            app.meanSD_3DDDITextArea = uitextarea(app.DDIResultsPanel);
            app.meanSD_3DDDITextArea.Enable = 'off';
            app.DiffusionTabResults = uitab(app.TabGroup4_2);
            app.DiffusionTabResults.Title = 'Diffusion';
            app.ADCResultsPanel = uipanel(app.DiffusionTabResults);
            app.ADCResultsPanel.Title = 'ADC Results';
            app.ADCLinearBinningResultsPanel = uipanel(app.ADCResultsPanel);
            app.ADCLinearBinningResultsPanel.Enable = 'off';
            app.ADCLinearBinningResultsPanel.Title = 'ADC Linear Binning Results';
            app.LBADCMapButton = uibutton(app.ADCLinearBinningResultsPanel, 'push');
            app.LBADCMapButton.ButtonPushedFcn = createCallbackFcn(app, @LBADCMapButtonPushed, true);
            app.LBADCMapButton.Text = 'LB ADC Map';
            app.LBADCHistogramButton = uibutton(app.ADCLinearBinningResultsPanel, 'push');
            app.LBADCHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @LBADCHistogramButtonPushed, true);
            app.LBADCHistogramButton.Text = 'LB ADC Histogram';
            app.LowLabel = uilabel(app.ADCLinearBinningResultsPanel);
            app.LowLabel.Text = 'Low ';
            app.HighLabelLBADC = uilabel(app.ADCLinearBinningResultsPanel);
            app.HighLabelLBADC.Text = 'High';
            app.Bin1TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin1TextArea_2Label.Text = 'Bin1';
            app.Bin1TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.Bin2TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin2TextArea_2Label.Text = 'Bin2';
            app.Bin2TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.Bin3TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin3TextArea_2Label.Text = 'Bin3';
            app.Bin3TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.Bin4TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin4TextArea_2Label.Text = 'Bin4';
            app.Bin4TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.Bin5TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin5TextArea_2Label.Text = 'Bin5';
            app.Bin5TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.Bin6TextArea_2Label = uilabel(app.ADCLinearBinningResultsPanel);
            app.Bin6TextArea_2Label.Text = 'Bin6';
            app.Bin6TextAreaLBADC = uitextarea(app.ADCLinearBinningResultsPanel);
            app.ADCMapButton = uibutton(app.ADCResultsPanel, 'push');
            app.ADCMapButton.ButtonPushedFcn = createCallbackFcn(app, @ADCMapButtonPushed, true);
            app.ADCMapButton.Text = 'ADC Map';
            app.ADCHistogramButton = uibutton(app.ADCResultsPanel, 'push');
            app.ADCHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ADCHistogramButtonPushed, true);
            app.ADCHistogramButton.Text = 'ADC Histogram';
            app.MeanADCcm2sTextAreaLabel = uilabel(app.ADCResultsPanel);
            app.MeanADCcm2sTextAreaLabel.Text = 'Mean ADC (cm2/s)';
            app.MeanADCcm2sTextArea = uitextarea(app.ADCResultsPanel);
            app.MorphometryAnalysisResultPanel = uipanel(app.DiffusionTabResults);
            app.MorphometryAnalysisResultPanel.Enable = 'off';
            app.MorphometryAnalysisResultPanel.Title = 'Morphometry Analysis';
            app.CylindricalModelCMResultsPanel = uipanel(app.MorphometryAnalysisResultPanel);
            app.CylindricalModelCMResultsPanel.Enable = 'off';
            app.CylindricalModelCMResultsPanel.Title = 'Cylindrical Model (CM) Results';
            app.MeanTextArea_2Label = uilabel(app.CylindricalModelCMResultsPanel);
            app.MeanTextArea_2Label.Text = 'Mean';
            app.MeanTextAreaCM = uitextarea(app.CylindricalModelCMResultsPanel);
            app.CMParametersDropDownLabel = uilabel(app.CylindricalModelCMResultsPanel);
            app.CMParametersDropDownLabel.Text = 'CM Parameters';
            app.CMParametersDropDown = uidropdown(app.CylindricalModelCMResultsPanel);
            app.CMParametersDropDown.Items = {'R: External Acinar duct radius', 'r: Internal Acinar duct radius', 'h: Alveolar sleeve depth', 'Lm: Mean linear intercept', 'SVR: Surface to volume ratio', 'Na: Alveolar density'};
            app.CMParametersDropDown.Editable = 'on';
            app.CMParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @CMParametersDropDownValueChanged, true);
            app.CMParametersDropDown.Value = 'R: External Acinar duct radius';
            app.HistogramButtonCM = uibutton(app.CylindricalModelCMResultsPanel, 'push');
            app.HistogramButtonCM.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonCMPushed, true);
            app.HistogramButtonCM.Text = 'Histogram';
            app.MapButtonCM = uibutton(app.CylindricalModelCMResultsPanel, 'push');
            app.MapButtonCM.ButtonPushedFcn = createCallbackFcn(app, @MapButtonCMPushed, true);
            app.MapButtonCM.Text = 'Map';
            app.StretchExponentialModelSEMResultsPanel = uipanel(app.MorphometryAnalysisResultPanel);
            app.StretchExponentialModelSEMResultsPanel.Enable = 'off';
            app.StretchExponentialModelSEMResultsPanel.Title = 'Stretch Exponential Model (SEM) Results';
            app.MapButtonSEM = uibutton(app.StretchExponentialModelSEMResultsPanel, 'push');
            app.MapButtonSEM.ButtonPushedFcn = createCallbackFcn(app, @MapButtonSEMPushed, true);
            app.MapButtonSEM.Text = 'Map';
            app.HistogramButtonSEM = uibutton(app.StretchExponentialModelSEMResultsPanel, 'push');
            app.HistogramButtonSEM.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonSEMPushed, true);
            app.HistogramButtonSEM.Text = 'Histogram';
            app.MeanTextArea_3Label = uilabel(app.StretchExponentialModelSEMResultsPanel);
            app.MeanTextArea_3Label.Text = 'Mean';
            app.MeanTextAreaSEM = uitextarea(app.StretchExponentialModelSEMResultsPanel);
            app.SEMParametersDropDownLabel = uilabel(app.StretchExponentialModelSEMResultsPanel);
            app.SEMParametersDropDownLabel.Text = 'SEM Parameters';
            app.SEMParametersDropDown = uidropdown(app.StretchExponentialModelSEMResultsPanel);
            app.SEMParametersDropDown.Items = {'Alpha: Heterogeneity index', 'DCC: Distributed diffusivity coefficient', 'LmD: Mean diffusive length scale'};
            app.SEMParametersDropDown.Editable = 'on';
            app.SEMParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @SEMParametersDropDownValueChanged, true);

            app.SEMParametersDropDown.Value = 'Alpha: Heterogeneity index';
            app.GasExchangeTabResults = uitab(app.TabGroup4_2);
            app.GasExchangeTabResults.Title = 'GasExchange';
            app.VentilationSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.VentilationSummaryPanel.Enable = 'off';
            app.VentilationSummaryPanel.Title = 'Ventilation Summary';
            app.LowLabel_2 = uilabel(app.VentilationSummaryPanel);
            app.LowLabel_2.Text = 'Low ';
            app.HighLabel_3 = uilabel(app.VentilationSummaryPanel);
            app.HighLabel_3.Text = 'High';
            app.Bin1TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin1TextArea_3Label.Text = 'Bin1';
            app.Bin1TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.Bin2TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin2TextArea_3Label.Text = 'Bin2';
            app.Bin2TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.Bin3TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin3TextArea_3Label.Text = 'Bin3';
            app.Bin3TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.Bin4TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin4TextArea_3Label.Text = 'Bin4';
            app.Bin4TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.Bin5TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin5TextArea_3Label.Text = 'Bin5';
            app.Bin5TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.Bin6TextArea_3Label = uilabel(app.VentilationSummaryPanel);
            app.Bin6TextArea_3Label.Text = 'Bin6';
            app.Bin6TextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.MeanTextArea_4Label = uilabel(app.VentilationSummaryPanel);
            app.MeanTextArea_4Label.Text = 'Mean:';
            app.MeanTextAreaVent = uitextarea(app.VentilationSummaryPanel);
            app.MapButtonVent = uibutton(app.VentilationSummaryPanel, 'push');
            app.MapButtonVent.ButtonPushedFcn = createCallbackFcn(app, @MapButtonVentPushed, true);
            app.MapButtonVent.Text = 'Map';
            app.HistogramButtonVent = uibutton(app.VentilationSummaryPanel, 'push');
            app.HistogramButtonVent.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonVentPushed, true);
            app.HistogramButtonVent.Text = 'Histogram';
            app.DissolvedSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.DissolvedSummaryPanel.Enable = 'off';
            app.DissolvedSummaryPanel.Title = 'Dissolved Summary';
            app.LowLabel_3 = uilabel(app.DissolvedSummaryPanel);
            app.LowLabel_3.Text = 'Low ';
            app.HighLabel_4 = uilabel(app.DissolvedSummaryPanel);
            app.HighLabel_4.Text = 'High';
            app.Bin1TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin1TextArea_4Label.Text = 'Bin1';
            app.Bin1TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.Bin2TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin2TextArea_4Label.Text = 'Bin2';
            app.Bin2TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.Bin3TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin3TextArea_4Label.Text = 'Bin3';
            app.Bin3TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.Bin4TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin4TextArea_4Label.Text = 'Bin4';
            app.Bin4TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.Bin5TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin5TextArea_4Label.Text = 'Bin5';
            app.Bin5TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.Bin6TextArea_4Label = uilabel(app.DissolvedSummaryPanel);
            app.Bin6TextArea_4Label.Text = 'Bin6';
            app.Bin6TextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.MeanTextArea_5Label = uilabel(app.DissolvedSummaryPanel);
            app.MeanTextArea_5Label.Text = 'Mean:';
            app.MeanTextAreaDissolved = uitextarea(app.DissolvedSummaryPanel);
            app.MapButtonDissolved = uibutton(app.DissolvedSummaryPanel, 'push');
            app.MapButtonDissolved.ButtonPushedFcn = createCallbackFcn(app, @MapButtonDissolvedPushed, true);
            app.MapButtonDissolved.Text = 'Map';
            app.HistogramButtonDissolved = uibutton(app.DissolvedSummaryPanel, 'push');
            app.HistogramButtonDissolved.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonDissolvedPushed, true);
            app.HistogramButtonDissolved.Text = 'Histogram';
            app.RBCSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.RBCSummaryPanel.Enable = 'off';
            app.RBCSummaryPanel.Title = 'RBC Summary';
            app.LowLabel_5 = uilabel(app.RBCSummaryPanel);
            app.LowLabel_5.Text = 'Low ';
            app.HighLabel_6 = uilabel(app.RBCSummaryPanel);
            app.HighLabel_6.Text = 'High';
            app.Bin1TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin1TextArea_6Label.Text = 'Bin1';
            app.Bin1TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.Bin2TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin2TextArea_6Label.Text = 'Bin2';
            app.Bin2TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.Bin3TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin3TextArea_6Label.Text = 'Bin3';
            app.Bin3TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.Bin4TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin4TextArea_6Label.Text = 'Bin4';
            app.Bin4TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.Bin5TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin5TextArea_6Label.Text = 'Bin5';
            app.Bin5TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.Bin6TextArea_6Label = uilabel(app.RBCSummaryPanel);
            app.Bin6TextArea_6Label.Text = 'Bin6';
            app.Bin6TextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.MeanTextArea_7Label = uilabel(app.RBCSummaryPanel);
            app.MeanTextArea_7Label.Text = 'Mean:';
            app.MeanTextAreaRBC = uitextarea(app.RBCSummaryPanel);
            app.MapButtonRBC = uibutton(app.RBCSummaryPanel, 'push');
            app.MapButtonRBC.ButtonPushedFcn = createCallbackFcn(app, @MapButtonRBCPushed, true);
            app.MapButtonRBC.Text = 'Map';
            app.HistogramButtonRBC = uibutton(app.RBCSummaryPanel, 'push');
            app.HistogramButtonRBC.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonRBCPushed, true);
            app.HistogramButtonRBC.Text = 'Histogram';
            app.RBCMembraneSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.RBCMembraneSummaryPanel.Enable = 'off';
            app.RBCMembraneSummaryPanel.Title = 'RBC:Membrane Summary';
            app.LowLabel_6 = uilabel(app.RBCMembraneSummaryPanel);
            app.LowLabel_6.Text = 'Low ';
            app.HighLabel_7 = uilabel(app.RBCMembraneSummaryPanel);
            app.HighLabel_7.Text = 'High';
            app.Bin1TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin1TextArea_7Label.Text = 'Bin1';
            app.Bin1TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.Bin2TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin2TextArea_7Label.Text = 'Bin2';
            app.Bin2TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.Bin3TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin3TextArea_7Label.Text = 'Bin3';
            app.Bin3TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.Bin4TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin4TextArea_7Label.Text = 'Bin4';
            app.Bin4TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.Bin5TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin5TextArea_7Label.Text = 'Bin5';
            app.Bin5TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.Bin6TextArea_7Label = uilabel(app.RBCMembraneSummaryPanel);
            app.Bin6TextArea_7Label.Text = 'Bin6';
            app.Bin6TextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.MeanTextArea_8Label = uilabel(app.RBCMembraneSummaryPanel);
            app.MeanTextArea_8Label.Text = 'Mean:';
            app.MeanTextAreaRBCBarr = uitextarea(app.RBCMembraneSummaryPanel);
            app.MapButtonRBCBarr = uibutton(app.RBCMembraneSummaryPanel, 'push');
            app.MapButtonRBCBarr.ButtonPushedFcn = createCallbackFcn(app, @MapButtonRBCBarrPushed, true);
            app.MapButtonRBCBarr.Text = 'Map';
            app.HistogramButtonRBCBarr = uibutton(app.RBCMembraneSummaryPanel, 'push');
            app.HistogramButtonRBCBarr.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonRBCBarrPushed, true);
            app.HistogramButtonRBCBarr.Text = 'Histogram';
            app.MembraneSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.MembraneSummaryPanel.Enable = 'off';
            app.MembraneSummaryPanel.Title = 'Membrane Summary';
            app.LowLabel_9 = uilabel(app.MembraneSummaryPanel);
            app.LowLabel_9.Text = 'Low ';
            app.HighLabel_10 = uilabel(app.MembraneSummaryPanel);
            app.HighLabel_10.Text = 'High';
            app.Bin1TextArea_10Label = uilabel(app.MembraneSummaryPanel);
            app.Bin1TextArea_10Label.Text = 'Bin1';
            app.Bin1TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.MeanTextArea_11Label = uilabel(app.MembraneSummaryPanel);
            app.MeanTextArea_11Label.Text = 'Mean:';
            app.MeanTextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.MapButtonBarr = uibutton(app.MembraneSummaryPanel, 'push');
            app.MapButtonBarr.ButtonPushedFcn = createCallbackFcn(app, @MapButtonBarrPushed, true);
            app.MapButtonBarr.Text = 'Map';
            app.HistogramButtonBarr = uibutton(app.MembraneSummaryPanel, 'push');
            app.HistogramButtonBarr.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonBarrPushed, true);
            app.HistogramButtonBarr.Text = 'Histogram';
            app.Bin2TextArea_11Label = uilabel(app.MembraneSummaryPanel);
            app.Bin2TextArea_11Label.Text = 'Bin2';
            app.Bin2TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin3TextArea_10Label = uilabel(app.MembraneSummaryPanel);
            app.Bin3TextArea_10Label.Text = 'Bin3';
            app.Bin3TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin4TextArea_10Label = uilabel(app.MembraneSummaryPanel);
            app.Bin4TextArea_10Label.Text = 'Bin4';
            app.Bin4TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin5TextArea_10Label = uilabel(app.MembraneSummaryPanel);
            app.Bin5TextArea_10Label.Text = 'Bin5';
            app.Bin5TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin6TextArea_10Label = uilabel(app.MembraneSummaryPanel);
            app.Bin6TextArea_10Label.Text = 'Bin6';
            app.Bin6TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin7TextArea_2Label = uilabel(app.MembraneSummaryPanel);
            app.Bin7TextArea_2Label.Text = 'Bin7';
            app.Bin7TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.Bin8TextArea_2Label = uilabel(app.MembraneSummaryPanel);
            app.Bin8TextArea_2Label.Text = 'Bin8';
            app.Bin8TextAreaBarr = uitextarea(app.MembraneSummaryPanel);
            app.RBCOscillationSummaryPanel = uipanel(app.GasExchangeTabResults);
            app.RBCOscillationSummaryPanel.Enable = 'off';
            app.RBCOscillationSummaryPanel.Title = 'RBC Oscillation Summary';
            app.LowLabel_10 = uilabel(app.RBCOscillationSummaryPanel);
            app.LowLabel_10.Text = 'Low ';
            app.HighLabel_11 = uilabel(app.RBCOscillationSummaryPanel);
            app.HighLabel_11.Text = 'High';
            app.Bin1TextArea_11Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin1TextArea_11Label.Text = 'Bin1';
            app.Bin1TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.MeanTextArea_12Label = uilabel(app.RBCOscillationSummaryPanel);
            app.MeanTextArea_12Label.Text = 'Mean:';
            app.MeanTextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.MapButtonRBCOsc = uibutton(app.RBCOscillationSummaryPanel, 'push');
            app.MapButtonRBCOsc.ButtonPushedFcn = createCallbackFcn(app, @MapButtonRBCOscPushed, true);
            app.MapButtonRBCOsc.Text = 'Map';
            app.HistogramButtonRBCOsc = uibutton(app.RBCOscillationSummaryPanel, 'push');
            app.HistogramButtonRBCOsc.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonRBCOscPushed, true);
            app.HistogramButtonRBCOsc.Text = 'Histogram';
            app.Bin2TextArea_12Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin2TextArea_12Label.Text = 'Bin2';
            app.Bin2TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin3TextArea_11Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin3TextArea_11Label.Text = 'Bin3';
            app.Bin3TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin4TextArea_11Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin4TextArea_11Label.Text = 'Bin4';
            app.Bin4TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin5TextArea_11Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin5TextArea_11Label.Text = 'Bin5';
            app.Bin5TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin6TextArea_11Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin6TextArea_11Label.Text = 'Bin6';
            app.Bin6TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin7TextArea_3Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin7TextArea_3Label.Text = 'Bin7';
            app.Bin7TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.Bin8TextArea_3Label = uilabel(app.RBCOscillationSummaryPanel);
            app.Bin8TextArea_3Label.Text = 'Bin8';
            app.Bin8TextAreaRBCOsc = uitextarea(app.RBCOscillationSummaryPanel);
            app.ReportTab = uitab(app.ImageAnalysisTabs);
            app.ReportTab.Title = 'Report';
            app.PatientNameEditFieldLabel = uilabel(app.ReportTab);
            app.PatientNameEditFieldLabel.Text = 'Patient Name:';
            app.PatientNameEditField = uieditfield(app.ReportTab, 'text');
            app.PatientIDEditFieldLabel = uilabel(app.ReportTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID:';
            app.PatientIDEditField = uieditfield(app.ReportTab, 'text');
            app.AgeyearEditFieldLabel = uilabel(app.ReportTab);
            app.AgeyearEditFieldLabel.Text = 'Age (year): ';
            app.AgeyearEditField = uieditfield(app.ReportTab, 'text');
            app.HeightcmEditFieldLabel = uilabel(app.ReportTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm):';
            app.HeightcmEditField = uieditfield(app.ReportTab, 'text');
            app.WeightlbsEditFieldLabel = uilabel(app.ReportTab);
            app.WeightlbsEditFieldLabel.Text = 'Weight (lbs):';
            app.WeightlbsEditField = uieditfield(app.ReportTab, 'text');
            app.SexDropDownLabel = uilabel(app.ReportTab);
            app.SexDropDownLabel.Text = 'Sex:';
            app.SexDropDown = uidropdown(app.ReportTab);
            app.SexDropDown.Items = {'Male', 'Female', 'Other'};
            app.SexDropDown.Value = 'Male';
            app.StudyTypeEditFieldLabel = uilabel(app.ReportTab);
            app.StudyTypeEditFieldLabel.Text = 'Study Type:';
            app.StudyTypeEditField = uieditfield(app.ReportTab, 'text');
            app.StudyIDEditFieldLabel = uilabel(app.ReportTab);
            app.StudyIDEditFieldLabel.Text = 'Study ID:';
            app.StudyIDEditField = uieditfield(app.ReportTab, 'text');
            app.ScanDateDatePickerLabel = uilabel(app.ReportTab);
            app.ScanDateDatePickerLabel.Text = 'Scan Date:';
            app.ScanDateDatePicker = uidatepicker(app.ReportTab);
            app.ScanDateDatePicker.Value = datetime([2021 5 29]);
            app.XeDoseVolumemLEditFieldLabel = uilabel(app.ReportTab);
            app.XeDoseVolumemLEditFieldLabel.Text = 'Xe Dose Volume (mL):';
            app.XeDoseVolumemLEditField = uieditfield(app.ReportTab, 'text');
            app.SummaryofFindingsEditFieldLabel = uilabel(app.ReportTab);
            app.SummaryofFindingsEditFieldLabel.Text = 'Summary of Findings:';
            app.SummaryofFindingsEditField = uieditfield(app.ReportTab, 'text');
            app.NotesEditFieldLabel = uilabel(app.ReportTab);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.NotesEditField = uieditfield(app.ReportTab, 'text');
            app.MRMEditFieldLabel = uilabel(app.ReportTab);
            app.MRMEditFieldLabel.Text = 'MRM:';
            app.MRMEditField = uieditfield(app.ReportTab, 'text');
            app.DataAnalystEditFieldLabel = uilabel(app.ReportTab);
            app.DataAnalystEditFieldLabel.Text = 'Data Analyst:';
            app.DataAnalystEditField = uieditfield(app.ReportTab, 'text');
            app.GenerateReportButton = uibutton(app.ReportTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Icon = fullfile(pathToMLAPP, 'Icons', 'analysis3.png');
            app.GenerateReportButton.Enable = 'off';
            app.GenerateReportButton.Text = 'Generate Report';
            app.ProcessingDateDatePickerLabel = uilabel(app.ReportTab);
            app.ProcessingDateDatePickerLabel.Text = 'Processing Date:';
            app.ProcessingDateDatePicker = uidatepicker(app.ReportTab);
            app.ProcessingDateDatePicker.Value = datetime([2021 5 29]);
            app.PatientAgeyearsEditFieldLabel = uilabel(app.ImageAnalysisTab);
            app.PatientAgeyearsEditFieldLabel.Text = 'Patient Age (years):';
            app.PatientAgeyearsEditField = uieditfield(app.ImageAnalysisTab, 'text');
            app.PatientAgeyearsEditField.CharacterLimits = [1 100];
            app.PatientAgeyearsEditField.Value = '30';
            app.AnalysisTypeDropDownLabel = uilabel(app.ImageAnalysisTab);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type:';
            app.AnalysisTypeDropDown = uidropdown(app.ImageAnalysisTab);
            app.AnalysisTypeDropDown.Items = {'Ventilation', 'Diffusion', 'GasExchange'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Ventilation';
            app.ViewingWindowPanel = uipanel(app.ImageAnalysisTab);
            app.ViewingWindowPanel.Title = 'Viewing Window';
            app.UIAxes_colorbar = uiaxes(app.ViewingWindowPanel);
            app.UIAxes_colorbar.XTick = [];
            app.UIAxes_colorbar.XTickLabel = '';
            app.UIAxes_colorbar.YTick = [];
            app.decreaseSlice = uibutton(app.ViewingWindowPanel, 'push');
            app.decreaseSlice.ButtonPushedFcn = createCallbackFcn(app, @decreaseSlicePushed, true);
            app.decreaseSlice.Icon = fullfile(pathToMLAPP, 'Icons', 'left3.png');
            app.decreaseSlice.Text = '';
            app.increaseSlice = uibutton(app.ViewingWindowPanel, 'push');
            app.increaseSlice.ButtonPushedFcn = createCallbackFcn(app, @increaseSlicePushed, true);
            app.increaseSlice.Icon = fullfile(pathToMLAPP, 'Icons', 'left4.png');
            app.increaseSlice.Text = '';
            app.sliceSlider = uislider(app.ViewingWindowPanel);
            app.sliceSlider.Limits = [1 500];
            app.sliceSlider.ValueChangedFcn = createCallbackFcn(app, @sliceSliderValueChanged2, true);
            app.sliceSlider.Value = 1;
            app.ViewingParametersPanel = uipanel(app.ImageAnalysisTab);
            app.ViewingParametersPanel.Title = 'Viewing Parameters';
            app.ChColorDropDownLabel = uilabel(app.ViewingParametersPanel);
            app.ChColorDropDownLabel.Text = 'C.h.Color';
            app.ChColorDropDown = uidropdown(app.ViewingParametersPanel);
            app.ChColorDropDown.Items = {'yellow', 'green', 'red', 'blue', 'cyan', 'magenta'};
            app.ChColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ChColorDropDownValueChanged, true);
            app.ChColorDropDown.Value = 'yellow';
            app.ColorMapDropDown_2Label = uilabel(app.ViewingParametersPanel);
            app.ColorMapDropDown_2Label.Text = 'ColorMap';
            app.ColorMapDropDown = uidropdown(app.ViewingParametersPanel);
            app.ColorMapDropDown.Items = {'gray', 'jet', 'parula', 'turbo', 'hot', 'cool', 'bone', 'copper', 'hsv'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'gray';
            app.ChLineWidthSpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.ChLineWidthSpinnerLabel.Text = 'C.h.LineWidth';
            app.ChLineWidthSpinner = uispinner(app.ViewingParametersPanel);
            app.ChLineWidthSpinner.Step = 0.5;
            app.ChLineWidthSpinner.Limits = [0.1 10];
            app.ChLineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @ChLineWidthSpinnerValueChanged, true);
            app.ChLineWidthSpinner.Value = 1;
            app.CrosshairEnableSwitchLabel = uilabel(app.ViewingParametersPanel);
            app.CrosshairEnableSwitchLabel.Text = 'CrosshairEnable';
            app.CrosshairEnableSwitch = uiswitch(app.ViewingParametersPanel, 'slider');
            app.CrosshairEnableSwitch.Items = {'On', 'Off'};
            app.CrosshairEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @CrosshairEnableSwitchValueChanged, true);
            app.CrosshairEnableSwitch.Value = 'On';
            app.ScaleXSpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.ScaleXSpinnerLabel.Text = 'Scale X';
            app.ScaleXSpinner = uispinner(app.ViewingParametersPanel);
            app.ScaleXSpinner.Step = 0.5;
            app.ScaleXSpinner.Limits = [0.5 6];
            app.ScaleXSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleXSpinnerValueChanged, true);
            app.ScaleXSpinner.Value = 3;
            app.ScaleYSpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.ScaleYSpinnerLabel.Text = 'Scale Y';
            app.ScaleYSpinner = uispinner(app.ViewingParametersPanel);
            app.ScaleYSpinner.Step = 0.5;
            app.ScaleYSpinner.Limits = [0.5 6];
            app.ScaleYSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleYSpinnerValueChanged, true);
            app.ScaleYSpinner.Value = 3;
            app.ScaleZSpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.ScaleZSpinnerLabel.Text = 'Scale Z';
            app.ScaleZSpinner = uispinner(app.ViewingParametersPanel);
            app.ScaleZSpinner.Step = 0.5;
            app.ScaleZSpinner.Limits = [0.5 6];
            app.ScaleZSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleZSpinnerValueChanged, true);
            app.ScaleZSpinner.Value = 1;
            app.RotateFlipdDropDownLabel = uilabel(app.ViewingParametersPanel);
            app.RotateFlipdDropDownLabel.Text = 'Rotate/Flip (d)';
            app.RotateFlipdDropDown = uidropdown(app.ViewingParametersPanel);
            app.RotateFlipdDropDown.Items = {'1', '2', '3', '4'};
            app.RotateFlipdDropDown.Value = '1';
            app.swapdimensionButton = uibutton(app.ViewingParametersPanel, 'push');
            app.swapdimensionButton.ButtonPushedFcn = createCallbackFcn(app, @swapdimensionButtonPushed, true);
            app.swapdimensionButton.Text = 'swap dimension';
            app.D1SpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.D1SpinnerLabel.Text = 'D1';
            app.D1Spinner = uispinner(app.ViewingParametersPanel);
            app.D1Spinner.Limits = [1 4];
            app.D1Spinner.Value = 1;
            app.D2SpinnerLabel = uilabel(app.ViewingParametersPanel);
            app.D2SpinnerLabel.Text = 'D2';
            app.D2Spinner = uispinner(app.ViewingParametersPanel);
            app.D2Spinner.Limits = [1 4];
            app.D2Spinner.Value = 2;
            app.ResetContrast = uibutton(app.ViewingParametersPanel, 'push');
            app.ResetContrast.ButtonPushedFcn = createCallbackFcn(app, @ResetContrastPushed, true);
            app.ResetContrast.Icon = fullfile(pathToMLAPP, 'Icons', 'reset.png');
            app.ResetContrast.Text = '';
            app.ContrastSlider_2Label_2 = uilabel(app.ViewingParametersPanel);
            app.ContrastSlider_2Label_2.Text = 'Contrast';
            app.ContrastSlider = uislider(app.ViewingParametersPanel);
            app.ContrastSlider.Limits = [0.01 2];
            app.ContrastSlider.MajorTicks = [0.01 2];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged4, true);
            app.ContrastSlider.Value = 1;
            app.PixelInfoButton = uibutton(app.ViewingParametersPanel, 'push');
            app.PixelInfoButton.ButtonPushedFcn = createCallbackFcn(app, @PixelInfoButtonPushed, true);
            app.PixelInfoButton.Text = 'Pixel Info';
            app.imSliceViewerButton = uibutton(app.ViewingParametersPanel, 'push');
            app.imSliceViewerButton.ButtonPushedFcn = createCallbackFcn(app, @imSliceViewerButtonPushed, true);
            app.imSliceViewerButton.Text = 'imSliceViewer';
            app.ImagesbeingviewedEditFieldLabel = uilabel(app.ViewingParametersPanel);
            app.ImagesbeingviewedEditFieldLabel.Text = 'Images being viewed';
            app.ImagesbeingviewedEditField = uieditfield(app.ViewingParametersPanel, 'text');
            app.ApplyChangesButton = uibutton(app.ViewingParametersPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ResetButton = uibutton(app.ViewingParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'Icons', 'reset.png');
            app.ResetButton.Text = 'Reset';
            app.RotateButton = uibutton(app.ViewingParametersPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.FlipButton = uibutton(app.ViewingParametersPanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.Label = uilabel(app.HPXeMRIAnalysisApp);
            app.Label.Text = 'Copyright ©2024 Center for Pulmonary Imaging Research (CPIR), Cincinnati Children’s Hospital Medical Center';
            app.ReadyLamp = uilamp(app.HPXeMRIAnalysisApp);
            app.StatusTextAreaLabel = uilabel(app.HPXeMRIAnalysisApp);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.HPXeMRIAnalysisApp);
            app.InstituteDropDownLabel = uilabel(app.HPXeMRIAnalysisApp);
            app.InstituteDropDownLabel.Text = 'Institute:';
            app.InstituteDropDown = uidropdown(app.HPXeMRIAnalysisApp);
            app.InstituteDropDown.Items = {'XeCTC', 'CCHMC', 'Duke', 'UVA'};
            app.InstituteDropDown.Value = 'XeCTC';
            app.ScannerDropDownLabel = uilabel(app.HPXeMRIAnalysisApp);
            app.ScannerDropDownLabel.Text = 'Scanner:';
            app.ScannerDropDown = uidropdown(app.HPXeMRIAnalysisApp);
            app.ScannerDropDown.Items = {'Philips', 'Siemens', 'GE'};
            app.ScannerDropDown.Value = 'Philips';
            app.ScannerSoftwareDropDownLabel = uilabel(app.HPXeMRIAnalysisApp);
            app.ScannerSoftwareDropDownLabel.Text = 'Scanner Software:';
            app.ScannerSoftwareDropDown = uidropdown(app.HPXeMRIAnalysisApp);
            app.ScannerSoftwareDropDown.Items = {'5.3.1', '5.6.0', '5.9.0'};
            app.ScannerSoftwareDropDown.Value = '5.9.0';
            app.SequenceTypeDropDown_3Label = uilabel(app.HPXeMRIAnalysisApp);
            app.SequenceTypeDropDown_3Label.Text = 'Sequence Type:';
            app.SequenceTypeDropDown = uidropdown(app.HPXeMRIAnalysisApp);
            app.SequenceTypeDropDown.Items = {'Spectroscopy', '2D GRE', '3D GRE', '2D Radial', '3D Radial', '2D Spiral', '3D Spiral (SoS)', '3D Spiral (FLORET)'};
            app.SequenceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SequenceTypeDropDownValueChanged, true);
            app.SequenceTypeDropDown.Value = 'Spectroscopy';
            app.FieldStrengthDropDownLabel = uilabel(app.HPXeMRIAnalysisApp);
            app.FieldStrengthDropDownLabel.Text = 'Field Strength:';
            app.FieldStrengthDropDown = uidropdown(app.HPXeMRIAnalysisApp);
            app.FieldStrengthDropDown.Items = {'1.5T', '3.0T'};
            app.FieldStrengthDropDown.Value = '3.0T';
            app.Image2 = uiimage(app.HPXeMRIAnalysisApp);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'XIPline_MainLogo.png');
            app.Version110Label = uilabel(app.HPXeMRIAnalysisApp);
            app.Version110Label.Text = 'Version 1.1.0';
            % Show the figure after all components are created
            app.HPXeMRIAnalysisApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XIPline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HPXeMRIAnalysisApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HPXeMRIAnalysisApp)
        end
    end
end